classdef Hypnos_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Hypnos                          matlab.ui.Figure
        InputDataPanel                  matlab.ui.container.Panel
        OpenButton                      matlab.ui.control.Button
        outText                         matlab.ui.control.Label
        cmpixLabel                      matlab.ui.control.Label
        scaleFactor                     matlab.ui.control.NumericEditField
        splitData                       matlab.ui.control.CheckBox
        remainHr                        matlab.ui.control.NumericEditField
        notesBt                         matlab.ui.control.Button
        HighspeedthresholdcmsEditFieldLabel  matlab.ui.control.Label
        highSpeedLbl                    matlab.ui.control.NumericEditField
        ArenaLabel                      matlab.ui.control.Label
        arenaBottomLeftX                matlab.ui.control.NumericEditField
        arenaBottomLeftY                matlab.ui.control.NumericEditField
        arenaTopRightX                  matlab.ui.control.NumericEditField
        arenaTopRightY                  matlab.ui.control.NumericEditField
        ParametersforanalysisandstatedefinitionsPanel  matlab.ui.container.Panel
        ThresholdspeedcmsLabel          matlab.ui.control.Label
        thrSpeed                        matlab.ui.control.NumericEditField
        DeltaALabel                     matlab.ui.control.Label
        areaThr                         matlab.ui.control.NumericEditField
        MindurationofsleepeventssLabel  matlab.ui.control.Label
        minSleep                        matlab.ui.control.NumericEditField
        LPfilterhalfWdLabel             matlab.ui.control.Label
        rollingWnd                      matlab.ui.control.NumericEditField
        MaxinerruptionofrestphasespLabel  matlab.ui.control.Label
        maxInterruption                 matlab.ui.control.NumericEditField
        autoThrCB                       matlab.ui.control.CheckBox
        SelectdeltaASwitchLabel         matlab.ui.control.Label
        selectDeltaPxSW                 matlab.ui.control.Switch
        DisplayandOutputPanel           matlab.ui.container.Panel
        logSpeedCB                      matlab.ui.control.CheckBox
        logDACB                         matlab.ui.control.CheckBox
        smoothSpeedCB                   matlab.ui.control.CheckBox
        smoothDACB                      matlab.ui.control.CheckBox
        displayStatesCB                 matlab.ui.control.CheckBox
        parseBt                         matlab.ui.control.Button
        reportBt                        matlab.ui.control.Button
        saveMdataBt                     matlab.ui.control.Button
        statesOnTrjCB                   matlab.ui.control.CheckBox
        drawArenaOnPlotsCB              matlab.ui.control.CheckBox
        widthsEditFieldLabel            matlab.ui.control.Label
        plotWidth                       matlab.ui.control.NumericEditField
        scrollLeftBtn                   matlab.ui.control.Button
        scrollRightBtn                  matlab.ui.control.Button
        resetWndBtn                     matlab.ui.control.Button
        zoomInBtn                       matlab.ui.control.Button
        autoScaleCB                     matlab.ui.control.CheckBox
        MaxspeedcmsLabel                matlab.ui.control.Label
        maxSpeed                        matlab.ui.control.NumericEditField
        MaxdeltaareapxLabel             matlab.ui.control.Label
        maxDelta                        matlab.ui.control.NumericEditField
        ExplorationLabel                matlab.ui.control.Label
        LocalactivityLabel              matlab.ui.control.Label
        RestLabel                       matlab.ui.control.Label
        speedAx                         matlab.ui.control.UIAxes
        mapAx                           matlab.ui.control.UIAxes
        deltaAx                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fileTime
        trajectory
        dataPath                % Data directory
        dataCompleteFileName 
        len
        left
        right
    end
    
    properties (Access = private)
        trjH        % handle to the trajectory object
    end
    
    methods (Access = private)
        
        function plotTrajectory(app)
            cla(app.mapAx)
            % First, find out the indexes associated to the plot window limits app.left and app.right
            ileft = ceil(seconds(app.left-app.trajectory.t1)/app.trajectory.meanDt + 1);
            if ileft < 1, ileft = 1;
            end
            iright = ceil(seconds(app.right-app.trajectory.t1)/app.trajectory.meanDt + 1);
%            iright = ceil(ileft + app.plotWidth.Value/app.trajectory.meanDt);
            if iright>app.len, iright = app.len;
            end
            % plot the trajectory limited to the plotted time window
            cla(app.mapAx)
            if app.statesOnTrjCB.Value
                % the color of each line is provided by the state color code
                %plot(app.mapAx,app.trajectory.x(ileft:iright),app.trajectory.y(ileft:iright),"Color",app.trajectory.colorRGB(ileft:iright,:))
                xx = app.trajectory.x(ileft:iright);
                yy = app.trajectory.y(ileft:iright);
                zz = zeros(size(yy));
                app.mapAx.Colormap = [1 0 0; 0 1 0; 0 0 1];
                col(1:size(yy)) = app.trajectory.colorRGB(ileft:iright,1:3) * [1;2;3];
                
                surface(app.mapAx,[xx';xx'],[yy';yy'],[zz';zz'],[col;col],'facecol','no','edgecol','flat','linew',0.5);
%                surface(app.mapAx,[x;x],[y;y],[z;z],[col;col],'facecol','no','edgecol','interp','linew',2);
            else   
                plot(app.mapAx,app.trajectory.x(ileft:iright),app.trajectory.y(ileft:iright),"Color",'b')
                iD = ileft + find(app.trajectory.environment(ileft:iright)==1) - 1;
                hold (app.mapAx, 'on');
                plot(app.mapAx,app.trajectory.x(iD),app.trajectory.y(iD),"Color",'g')
                hold (app.mapAx, 'off');
            end
            drawArena(app)
            axis(app.mapAx,[1 640 1 480])
        end
        
        function plotActivity(app)
            % this function plots speed and derivative of area as a function of experiment datetime
            cla(app.speedAx)
            % First, find out the indexes associated to the plot window limits app.left and app.right
            ileft = ceil(seconds(app.left-app.trajectory.t1)/app.trajectory.meanDt + 1);
            if ileft < 1, ileft = 1;
            end
            iright = ceil(seconds(app.right-app.trajectory.t1)/app.trajectory.meanDt + 1);
%            iright = ceil(ileft + app.plotWidth.Value/app.trajectory.meanDt);
            if iright>app.len, iright = app.len;
            end
            
            plot(app.speedAx,app.trajectory.timeArray(ileft:iright),app.trajectory.speed(ileft:iright))
            plot(app.deltaAx,app.trajectory.timeArray(ileft:iright),app.trajectory.deltaSpeed(ileft:iright))
            
            % display state color code in the speed plot
            if app.displayStatesCB.Value
               hold (app.speedAx,'on')
               for i=1:app.trajectory.resting.n
                   % This previous design is about 4 times slower. Working
                   % with datetime data inside a loop is not a good idea.
%                    t(1) = app.trajectory.resting.time1(i);
%                    t(2) = app.trajectory.resting.time2(i);
%                    tf = isbetween(t,app.left,app.right);
%                    if sum(tf) > 0
                   
                   i1 = app.trajectory.resting.leftI(i);
                   i2 = app.trajectory.resting.rightI(i);
                   if i1 >= iright, break           % break the loop as soon as the events are outside of the displaied interval
                   end
                   if i1 > ileft && i1 < iright || i2 > ileft && i2 < iright
%                       plot(app.speedAx,app.trajectory.timeArray(i1:i2),app.trajectory.speed(i1:i2),"Color",'r',"LineWidth",2)
                       x(1) = app.trajectory.timeArray(i1);
                       x(2) = app.trajectory.timeArray(i2);
                       plot(app.speedAx,x,[0 0],"Color",'g',"LineWidth",2)
                   end
               end
               for i=1:app.trajectory.localActivity.n
                   i1 = app.trajectory.localActivity.leftI(i);
                   i2 = app.trajectory.localActivity.rightI(i);
                   if i1 >= iright, break           % break the loop as soon as the events are outside of the displaied interval
                   end
                   if i1 > ileft && i1 < iright || i2 > ileft && i2 < iright
%                       plot(app.speedAx,app.trajectory.timeArray(i1:i2),app.trajectory.speed(i1:i2),"Color",'g',"LineWidth",2)
                       x(1) = app.trajectory.timeArray(i1);
                       x(2) = app.trajectory.timeArray(i2);
                       plot(app.speedAx,x,[0 0],"Color",'r',"LineWidth",2)
                   end
               end
            end
            %app.trajectory.speed =
            app.speedAx.YTickMode = 'auto';
            app.speedAx.YTickLabelMode = 'auto';
            app.speedAx.XTickLabel = [];
            app.speedAx.XLim = [app.left app.right];
            if app.autoScaleCB.Value
                app.speedAx.YLim = [0 inf];
                app.deltaAx.YLim = [0 inf];
            else
                app.speedAx.YLim = [0 app.maxSpeed.Value];
                app.deltaAx.YLim = [0 app.maxDelta.Value];
            end
            app.deltaAx.XLim = [app.left app.right];   
            if app.logSpeedCB.Value, app.speedAx.YScale = 'log';
            else
                app.speedAx.YScale = 'linear';
            end
            if app.logDACB.Value, app.deltaAx.YScale = 'log';
            else
                app.deltaAx.YScale = 'linear';
            end
            ipdA = app.deltaAx.InnerPosition;
            ipSp = app.speedAx.InnerPosition;
            dw = (ipSp(3)-ipdA(3));
            app.speedAx.Position(3) = app.speedAx.Position(3) - dw;
            app.speedAx.Position(1) = app.speedAx.Position(1) + dw;
            ipdA = app.deltaAx.InnerPosition;
            ipSp = app.speedAx.InnerPosition;
            dw = (ipSp(3)-ipdA(3));
            app.speedAx.Position(3) = app.speedAx.Position(3) - dw;
            app.speedAx.Position(1) = app.speedAx.Position(1) + dw;
            plotTrajectory(app)

        end
        
        function dataOut = detectClusters(app,indexSet,stateID)
        % This function identifies the state clusters following the code:
        % State 1: active exploratory: the mouse is moving at a speed larger than speedThr
        % State 2: active grooming or feeding: the mouse is moving less than speedThr but is changing shape as defined by 
        %          an area change larger than areaThr
        % State 3: resting. The mouse is still ad is not moving for a length of time less than the sleep threshold

            clrSt = ['b' 'r' 'g'];
            clrRGB = [0 0 1; 1 0 0; 0 1 0];
            nel = length (indexSet);

            % Compute the difference between adjacent indexes
            diffIS = diff(indexSet);
            % The begnning of each cluster is defined by the elements of
            % diffIS larger than a specific threshold.
            if stateID == 3
                % Max interruption of rest periods
                thrD = app.maxInterruption.Value;
            else
                % max interruption of exploratory and local activity
                thrD = 1;
            end
            dataOut.leftI (1) = indexSet(1);    % The first cluster starts with the first extracted index
            dataOut.time1(1) = app.trajectory.timeArray(indexSet(1));
            indClBegin = find(diffIS > thrD);
            nClusters = length(indClBegin) + 1;     % + 1 to account for the first cluster
            dataOut.n = nClusters;
            dataOut.leftI(2:nClusters) = indexSet(indClBegin+1);
            dataOut.time1(2:nClusters) = app.trajectory.timeArray(dataOut.leftI(2:nClusters));

            dataOut.rightI(1:nClusters-1) = indexSet(indClBegin);
            dataOut.rightI(nClusters) = indexSet(end);
            dataOut.time2(1:nClusters) = app.trajectory.timeArray(dataOut.rightI(1:nClusters));
            % Cluster long 1 data point only have length = to the median sampling period
            dataOut.duration = dataOut.time2 - dataOut.time1 + seconds(app.trajectory.medianDt);
            app.trajectory.state(indexSet) = stateID;
%             for cl=1:nClusters
%                 app.trajectory.state(dataOut.leftI(cl):dataOut.rightI(cl)) = stateID;
%             end
%             ncluster = 1;
%             pnt = 1;
%             % we define the first cluster
%             startI = indexSet(pnt);       % beginning of the event sequence
%             dataOut.leftI(ncluster) = startI;
%             dataOut.time1(ncluster) = app.trajectory.timeArray(startI);
%             oldPnt = startI;  
%             while pnt < nel
%                 pnt = pnt + 1;
%                 newPnt = indexSet(pnt); 
%                  % we are already inside a cluster definition. let's
%                  % check the contiguity condition
%                  if stateID == 3 && newPnt-oldPnt <= app.maxInterruption.Value
%                      % we are inside the continuation of the resting
%                      % phase. Move on
%                      oldPnt = newPnt;                    
%                  else
%                      % the previous state must be closed and we open a
%                      % new one. The end is placed 1 data point after the
%                      % last element of the cluster. In this way clusters
%                      % containing 1 point only do not have length 0.
%                      dataOut.rightI(ncluster) = oldPnt + 1;
%                      dataOut.time2(ncluster) = app.trajectory.timeArray(oldPnt + 1);
%                      dataOut.duration(ncluster) = dataOut.time2(ncluster) - dataOut.time1(ncluster);
%                      app.trajectory.state(dataOut.leftI(ncluster):dataOut.rightI(ncluster)) = stateID;
% 
%                      ncluster = ncluster + 1;
%                      dataOut.leftI(ncluster) = newPnt;
%                      dataOut.time1(ncluster) = app.trajectory.timeArray(newPnt);
%                      oldPnt = newPnt;
%                  end
%             end
%             dataOut.rightI(ncluster) = newPnt;
%             dataOut.time2(ncluster) = app.trajectory.timeArray(newPnt);
%             dataOut.duration(ncluster) = dataOut.time2(ncluster) - dataOut.time1(ncluster);
%             dataOut.n = ncluster;
%             app.trajectory.state(dataOut.leftI(ncluster):dataOut.rightI(ncluster)) = stateID;

            % s of May 1, 2020 I suppress this output as it takes time and
            % is not all that useful.
%             app.trajectory.colorSt(dataOut.leftI(1:dataOut.n):dataOut.rightI(1:dataOut.n)) = clrSt(stateID);
%             clrV(1:3) = clrRGB(stateID,1:3);
%             app.trajectory.colorRGB(dataOut.leftI(1:dataOut.n):dataOut.rightI(1:dataOut.n),1) = clrV(1);
%             app.trajectory.colorRGB(dataOut.leftI(1:dataOut.n):dataOut.rightI(1:dataOut.n),2) = clrV(2);
%             app.trajectory.colorRGB(dataOut.leftI(1:dataOut.n):dataOut.rightI(1:dataOut.n),3) = clrV(3);

            dataOut.duration.Format = 'hh:mm:ss.SSS';
        end
        
        function correctWarpSpeed(app, deltaT,distance)                           
            nasty = find (app.trajectory.speed > app.highSpeedLbl.Value);
            % The idea is that each time we perform a warp speed jump to an ectopic location
            % we must identify the way out and the way back, therefore
            % each cluster must contain TWO warp speed segment and not
            % just one
            if ~isempty(nasty)
                % nasty contains the indexes of all warp speed point.
                % One warp speed point does not mean anything since it
                % must be followed by another WSP that brings the point
                % back to where it was. Isolated WSP indicates faster
                % than usual segments.

                % Therefore we need to operate not on all the nasty
                % points but on the nasty couples. The nasty couples
                % are WSP couples closest than a given threshold.
                
                nastyD = diff(nasty);           % if nasty contains a single point... that is a problem
                % nastyD contains the distance between the nasty
                % points. nastyD = 1 means that there are two
                % CONSECUTIVE indexes in nasty and that indicates either:
                % a WS trip to the ectopic point immediately followed
                % by a return or
                % two segments of fast movement
                % A cluster of 1s most likely indicates that the mouse
                % is running scared but that is no proof of foul play.
                % The index of nastyD points to the the first element of nasty
                % that belongs to the couple. 
                
                % now let's extract the elements of nasty that satisfy
                % the condition nastyD<distance. nastyEs contains the
                % indexes of the WS points that are followed by another
                % WS point closer than the threshold
                
                distInd = find(nastyD<distance);       % this hold the indexes of the distances
                nastyEx = nasty(distInd);
                nastySize = nastyD(distInd);
                
                % we explore nastyEx to extract al the couples
                
                cntNasty = 1;
                while cntNasty < length(nastyEx)
                    % I know, this is horrible but it helps my thought processes
                    % Lets parse nastyEx to extract the couples
                    if nastyD(distInd)==1
                        % lets forget for the time being the ones
                    else
                        go = nastyEx(cntNasty);
                        back = go + nastySize(cntNasty);
                        sp1 = app.trajectory.speed(go-1:back+1);
                        leftI = go-1;
                        rightI = back+1;
                        goodX = app.trajectory.x([leftI rightI]);
                        goodY = app.trajectory.y([leftI rightI]);
                        goodT = app.trajectory.secFromStart([leftI rightI]);
                        interpTime = app.trajectory.secFromStart(leftI:rightI);
                        interpX = interp1(goodT,goodX,interpTime);
                        interpY = interp1(goodT,goodY,interpTime);
                        app.trajectory.x(leftI:rightI) = interpX;
                        app.trajectory.y(leftI:rightI) = interpY;
                    end
                    cntNasty = cntNasty + 1;
                end
                % compute again displacement and speed
                deltaX(2:app.len) = diff(app.trajectory.x);
                deltaY(2:app.len) = diff(app.trajectory.y);
                app.trajectory.d(1:app.len-1) = hypot(deltaX(2:app.len),deltaY(2:app.len));
                app.trajectory.d(app.len) = app.trajectory.d(app.len-1);    % padding of the final value
                app.trajectory.d = app.trajectory.d * app.scaleFactor.Value;
                app.trajectory.speed = app.trajectory.d ./ deltaT; 
            end
        end
        
        function drawArena(app)                        
        % Draw the arena edges
        if app.drawArenaOnPlotsCB.Value
%            hold (app.mapAx, 'on');
            % compute width and height of the rectangle
            w = app.arenaTopRightX.Value - app.arenaBottomLeftX.Value;
            h = abs(app.arenaTopRightY.Value - app.arenaBottomLeftY.Value);
            rectangle(app.mapAx,'Position',[app.arenaBottomLeftX.Value app.arenaBottomLeftY.Value w h],...
                'EdgeColor','r','LineWidth',1.5)
%            hold (app.mapAx, 'off');
            axis(app.mapAx,[1 640 1 480])
        end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.mapAx,[1 640 1 480])
            axis (app.mapAx, 'manual')
            app.outText.Visible = 0;
            app.parseBt.Enable = 0;
            app.reportBt.Enable = 0;
            app.notesBt.Enable = 0;
            app.saveMdataBt.Enable = 0;
            app.dataPath = [];
        end

        % Button pushed function: OpenButton
        function openMfile(app, event)
            fmtSt = '*.mat';
            
            [selection,path_in,~] = uigetfile ([app.dataPath fmtSt]);
            fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};    % structure for storage of file info
            if selection ~= 0
                app.displayStatesCB.Value = 0;
                app.trajectory = [];
                fileInfo = dir([path_in selection]);
                timeNum = datestr(fileInfo.datenum);
                app.fileTime = timeNum (13:20);
                % Time of the day (hr:mn:ss) when the file has been generated
                % Import the file
                app.trajectory = load('-mat', [path_in selection]);
                % dataIn is a structure with fields:
                % x, y          coordinates in pixels
            % update the current data directory
                app.dataPath = path_in;
                app.dataCompleteFileName = [path_in selection];
            end
            % filter the position data by removing all double zero entries that
            % could be present at the end of the data
            s = app.trajectory.x + app.trajectory.y;
            i = find(s);
            app.len = length(i);
            app.trajectory.len = app.len;
            app.trajectory.x = app.trajectory.x(1:app.len); 
            app.trajectory.y = app.trajectory.y(1:app.len); 
            app.trajectory.deltaA = app.trajectory.deltaA(1:app.len);
            app.trajectory.deltaPx = app.trajectory.deltaPx(1:app.len);
            app.trajectory.d = zeros(app.len,1);
            app.trajectory.distance = zeros(app.len,1);
            app.trajectory.environment = app.trajectory.environment(1:app.len);
            app.trajectory.year = app.trajectory.year(1:app.len);
            app.trajectory.month = app.trajectory.month(1:app.len);            
            app.trajectory.day = app.trajectory.day(1:app.len);
            app.trajectory.hour = app.trajectory.hour(1:app.len);
            app.trajectory.min = app.trajectory.min(1:app.len);
            app.trajectory.sec = app.trajectory.sec(1:app.len);
            app.trajectory.secFromStart = app.trajectory.secFromStart(1:app.len);
            
            % the 'environment' field contains info about external
            % environmental paraments in a bitwise fashion. At this stage
            % the only reported info is about daily light (1 light on, 0
            % light off). A RGB matrix is prepared in order to display the
            % proper color for night and day.
            
            iD = find(app.trajectory.environment==1);
            app.trajectory.NDcolor = zeros(app.len,3);      % night is blue
            app.trajectory.NDcolor(:,3) = 1; 
            app.trajectory.NDcolor(iD,2) = 1;
            app.trajectory.NDcolor(iD,3) = 0;
            
            % The following code is required to properly load field of the
            % trajectory structure that may or maynot be present if the
            % data file
            
            if ~isfield (app.trajectory, 'stateLabel')
                app.trajectory.stateLabel = zeros(app.len,1);
            end
            if ~isfield (app.trajectory, 'd')
                app.trajectory.d = zeros(app.len,1);        % array preallocation
            end
            if ~isfield (app.trajectory, 'speed')
                app.trajectory.speed = zeros(app.len,1);
            end
            if ~isfield (app.trajectory, 'state')
                app.trajectory.state = zeros(app.len,1);
            end
            if ~isfield (app.trajectory, 'deltaSpeed')
                app.trajectory.deltaSpeed = zeros(app.len,1);
            end    
            if ~isfield (app.trajectory, 'colorSt')
                app.trajectory.colorSt = zeros(app.len,1);
            end
            if ~isfield (app.trajectory, 'colorRGB')
                app.trajectory.colorRGB = zeros(app.len,3);
            end
            
            % Open the notes gui and, if the fields are present in the
            % input data fill it. Otherwise use the notes gui to fill
            % the info header for this experiments
            if isfield (app.trajectory, 'birthdate')
                % the note fields exists (post morpheus3 data), thus do not do anything.
            else
                % create the missing fields and fill them with null values.
                % Use notes to write the proper values
                app.trajectory.notes{1} = '';
                app.trajectory.birthdate = datetime;
                app.trajectory.sex = '';
                app.trajectory.geneticBackground = '';
                app.trajectory.lightCycle = '';
            end

            % Now fix some sporadic data points where x=0 and y=480. Reason for these currently unknown.
            % It might be related with timing issues. 
            % Feb 12, sadly, sometimes there are clusters of bad points. This is now dealt with
            
            i = find(app.trajectory.x==0);
            if ~isempty(i)
                if length(i) == 1
                    % only one point. It has to be treated separately since the diff array does not exist!
                    app.trajectory.x (i) = (app.trajectory.x(i-1)+app.trajectory.x(i+1))/2;
                    app.trajectory.y (i) = (app.trajectory.y(i-1)+app.trajectory.y(i+1))/2;
                    app.trajectory.deltaPx (i) = (2*app.trajectory.deltaPx (i-1) + app.trajectory.deltaPx (i+2))/3;
                    app.trajectory.deltaPx (i+1) = (app.trajectory.deltaPx (i-1) + 2*app.trajectory.deltaPx (i+2))/3;                 
                else
                    % We cannot assume that the bad points are isolated. First,
                    % identify and fix each cluster of a single bad points.
                    li = length(i);
                    difI = i(2:li)-i(1:li-1);
                    difI(2:li) = difI(1:li-1);      % make space for one at the beginning
                    if difI(2) > 1, difI(1)=100;     
                    else
                        difI(1) = 0;
                    end
                    % Now difI contains a 1 for each element that belongs to a
                    % cluster. We must zero the elements immediately preceding
                    % all 1s
                    clMulti = find(difI==1);
                    % All elements where difI = 1 are preceded by an element >1
                    % which represents the distance from the previous bad point
                    % but does not represent an isolated bad point
                    l = length(clMulti);
                    difIedited = difI;
                    difIedited(clMulti-1) = 0;
                    Isingle = find(difIedited>1);
                    Isingle = i(Isingle);
                    % Isingle contains the indexes of all single point cluster                
                    nSingle = length(Isingle);
                    % now loop on all single bad points
                    for iSingle=1:nSingle
                       pointer = Isingle(iSingle);
                       if pointer>1,
                           app.trajectory.x (pointer) = (app.trajectory.x(pointer-1)+app.trajectory.x(pointer+1))/2;
                           app.trajectory.y (pointer) = (app.trajectory.y(pointer-1)+app.trajectory.y(pointer+1))/2;
                           app.trajectory.deltaPx (pointer) = (2*app.trajectory.deltaPx (pointer-1) + app.trajectory.deltaPx (pointer+2))/3;
                           app.trajectory.deltaPx (pointer+1) = (app.trajectory.deltaPx (pointer-1) + 2*app.trajectory.deltaPx (pointer+2))/3;                    
                       end
                    end
                    % now lets search for the hopefully rare cluster of bad points!
                    ic = find(app.trajectory.x==0);
                    li = length(ic);
                    difI = ic(2:li)-ic(1:li-1);
                    cl1 = find(difI>1);         % cl1+1 points to the first element of each cluster as pointed to by ic.
                                                % The first cluster is missing and begins with the first element of ic
                    ncluster = length(cl1)+1;
                    iLeft = ic(1);                
                    for kCl = 1:ncluster
                        if kCl < ncluster
                            iRight = ic(cl1(kCl));
                        else
                            iRight = ic(end)+1;     % the last cluster has no further element in ic
                        end
                        % now interpolate all points belonging to the defective cluster. Find left value
                        if iLeft>1
                            leftX = app.trajectory.x (iLeft-1);
                            leftY = app.trajectory.y (iLeft-1);
                            leftDeltaA = app.trajectory.deltaA (iLeft-1);
                            leftDeltaPx = app.trajectory.deltaPx (iLeft-1);
                            
                            % find right values
                            rightX = app.trajectory.x (iRight+1);
                            rightY = app.trajectory.y (iRight+1);
                            rightDeltaA = app.trajectory.deltaA (iRight+1);
                            % DeltaPx must be interpolated on 2 intervals since the loss of one frame causes the loss of 
                            % 2 values of the deltaPx metric.
                            rightDeltaPx = app.trajectory.deltaPx (iRight+2);
                            
                            % now we can interpolate. First, compute the relative time of each missing data point.
                            %kCl, clStart(kCl), clEnd(kCl)
                            rTime = app.trajectory.secFromStart(iLeft-1 : iRight+1);
                            tSample = [app.trajectory.secFromStart(iLeft-1) app.trajectory.secFromStart(iRight+1)];
                            missing = interp1(tSample,[leftX rightX], rTime);
                            app.trajectory.x (iLeft-1 : iRight+1) = missing;
                            missing = interp1(tSample,[leftY rightY], rTime);
                            app.trajectory.y (iLeft-1 : iRight+1) = missing;
        
                            rTime = app.trajectory.secFromStart(iLeft-1 : iRight+2);
                            tSample = [app.trajectory.secFromStart(iLeft-1) app.trajectory.secFromStart(iRight+2)];
                            missing = interp1(tSample,[leftDeltaPx rightDeltaPx], rTime);
                            app.trajectory.deltaPx (iLeft-1 : iRight+2) = missing;
                            if kCl<ncluster, iLeft =  ic(cl1(kCl)+1);
                            end
                        end
                    end
                end
            end
            
            % Data have been properly edited. Proceed with computations
            
            % April 15, 2020
            % Now, and only now, I realized that it would be beneficial to
            % add to the datetime array the term indicationg the fraction of seconds
            % This can be obtained by exnding the default format of
            % datetime to fraction of seconds

            app.trajectory.timeArray = datetime(app.trajectory.year,app.trajectory.month,app.trajectory.day,app.trajectory.hour,...
                app.trajectory.min,app.trajectory.sec,'format','yyyy-MM-dd HH:mm:ss.SSS');
            % save separately the datetime of the beginning and end
            app.trajectory.t1 = datetime(app.trajectory.year(1),app.trajectory.month(1),app.trajectory.day(1),app.trajectory.hour(1),app.trajectory.min(1),app.trajectory.sec(1));
            
%            app.trajectory.t1.Format = 'yyyy-MM-dd HH:mm:ss.SSS';
            app.trajectory.t2 = app.trajectory.timeArray(app.len);
            app.trajectory.duration = app.trajectory.t2-app.trajectory.t1;

            if app.splitData.Value
                % check duration of the recording. If it is longer than 1
                % day + app.remainHr proceed to split the data in multiple files
                if app.trajectory.duration>days(1)+hours(app.remainHr.Value)
                    % split data in multiple files
                    nDays = floor(days(app.trajectory.duration));
                    dateTimeStart = app.trajectory.t1;
                    iStart = 1;
                    secFromS = 0;
                    for iDay=1:nDays
                        % Find the datetime and index of the end of iDay
                        dateTimeEnd = dateTimeStart + days(1);
                        iRemain = find(app.trajectory.timeArray>dateTimeEnd);
                        iEnd = iRemain(1) - 1;
                        % Create a new structure holding the data segment
                        dataOut.x = app.trajectory.x(iStart:iEnd); 
                        dataOut.y = app.trajectory.y(iStart:iEnd); 
                        dataOut.deltaA = app.trajectory.deltaA(iStart:iEnd);
                        dataOut.deltaPx = app.trajectory.deltaPx(iStart:iEnd);
                        dataOut.environment = app.trajectory.environment(iStart:iEnd);
                        dataOut.year = app.trajectory.year(iStart:iEnd);
                        dataOut.month = app.trajectory.month(iStart:iEnd);            
                        dataOut.day = app.trajectory.day(iStart:iEnd);
                        dataOut.hour = app.trajectory.hour(iStart:iEnd);
                        dataOut.min = app.trajectory.min(iStart:iEnd);
                        dataOut.sec = app.trajectory.sec(iStart:iEnd);
                        dataOut.secFromStart = app.trajectory.secFromStart(iStart:iEnd) - secFromS;
            
                        % Create the file name and save the data
                        dt = datestr(datetime(dataOut.year(1),dataOut.month(1),dataOut.day(1)));         % Datetime at beginning
                        fileName = [app.dataPath 'hypnos ' dt ' split.mat']; 
                        save(fileName,'-struct','dataOut');

                        iStart = iRemain(1);
                        dateTimeStart = app.trajectory.timeArray(iStart);
                        secFromS = app.trajectory.secFromStart(iStart);
                    end
                    % now check the residue
                    res = app.trajectory.duration-days(nDays);
                    if hours(res)>=app.remainHr
                        iEnd = length(app.trajectory.x);
                        dataOut.x = app.trajectory.x(iStart:iEnd); 
                        dataOut.y = app.trajectory.y(iStart:iEnd); 
                        dataOut.deltaA = app.trajectory.deltaA(iStart:iEnd);
                        dataOut.deltaPx = app.trajectory.deltaPx(iStart:iEnd);
                        dataOut.environment = app.trajectory.environment(iStart:iEnd);
                        dataOut.year = app.trajectory.year(iStart:iEnd);
                        dataOut.month = app.trajectory.month(iStart:iEnd);            
                        dataOut.day = app.trajectory.day(iStart:iEnd);
                        dataOut.hour = app.trajectory.hour(iStart:iEnd);
                        dataOut.min = app.trajectory.min(iStart:iEnd);
                        dataOut.sec = app.trajectory.sec(iStart:iEnd);
                        dataOut.secFromStart = app.trajectory.secFromStart(iStart:iEnd) - secFromS;

                        dt = datestr(datetime(dataOut.year(1),dataOut.month(1),dataOut.day(1)));         % Datetime at beginning
                        fileName = ['morpheus ' dt ' split.mat']; 
                        save(fileName,'-struct','dataOut');                        
                    end
                end
            else    
                % now compute the displacement vectors. These are the distance
                % between successive points of the vectors .x and .y
                deltaX(2:app.len) = diff(app.trajectory.x);
                deltaY(2:app.len) = diff(app.trajectory.y);
                app.trajectory.d(1:app.len-1) = hypot(deltaX(2:app.len),deltaY(2:app.len));
                app.trajectory.d(app.len) = app.trajectory.d(app.len-1);    % padding of the final value
                deltaT = diff(app.trajectory.secFromStart);
                app.trajectory.deltaPx(app.len) = app.trajectory.deltaPx(app.len-1);
                deltaT(app.len) = deltaT(app.len-1);
                
                % Now convert the displacement array from pixels to cm
                app.trajectory.d = app.trajectory.d * app.scaleFactor.Value;
                app.trajectory.speed = app.trajectory.d ./ deltaT; 
                app.trajectory.deltaSpeed = app.trajectory.deltaPx ./ deltaT;
                
                % pad the .speed vector to bring it to same length of the other arrays
                app.trajectory.speed (app.len) = app.trajectory.speed (app.len-1);
                app.trajectory.meanDt = mean(deltaT);
                app.trajectory.medianDt = median(deltaT);
                app.outText.Visible = 1;
                outT = sprintf('Meand SP (s): %1.3f',app.trajectory.medianDt);
                app.outText.Text = outT;
                                        
                % Now check for any segment with abnormally large speed.
                correctWarpSpeed(app,deltaT,6)
                correctWarpSpeed(app,deltaT,12)
                 
                nasty2 = find (app.trajectory.speed > app.highSpeedLbl.Value);

                app.left = app.trajectory.t1;
                app.right = app.trajectory.t2;
                plotActivity (app)                
                plotTrajectory (app)
                app.parseBt.Enable = 1;
                app.reportBt.Enable = 1;
                app.notesBt.Enable = 1;
                app.saveMdataBt.Enable = 1;
                
                % Computation of distance from edges. June 28th, FC & GMR
                % Temporary code
                % it only works with a rectangular arena aligned to the
                % image axis
                                
                % Compute the four arrays holding the translated
                % coordinates of each trajectory point
                
                cord(1:app.len,1) = app.trajectory.x(1:app.len)-app.arenaBottomLeftX.Value;
                cord(1:app.len,2) = app.trajectory.y(1:app.len)-app.arenaTopRightY.Value;
                cord(1:app.len,3) = app.trajectory.x(1:app.len)-app.arenaTopRightX.Value;
                cord(1:app.len,4) = app.trajectory.y(1:app.len)-app.arenaBottomLeftY.Value;
                
                app.trajectory.distance = min (abs(cord),[],2);
                                  
            end
        end

        % Button pushed function: zoomInBtn
        function zoomIn(app, event)
            app.left = app.trajectory.t1;
            app.right = app.left + seconds(app.plotWidth.Value);
            plotActivity(app)
        end

        % Button pushed function: scrollRightBtn
        function scrollRight(app, event)
            app.left = app.right;
            app.right = app.left + seconds(app.plotWidth.Value);
            plotActivity(app)            
        end

        % Button pushed function: scrollLeftBtn
        function scrollLeft(app, event)
            app.right = app.left;
            app.left = app.left - seconds(app.plotWidth.Value);
            plotActivity(app)
        end

        % Button pushed function: resetWndBtn
        function resetWnd(app, event)
            app.left = app.trajectory.t1;
            app.right = app.trajectory.t2;
            plotActivity(app)
        end

        % Value changed function: logSpeedCB
        function logSpeed(app, event)
            plotActivity(app)
        end

        % Value changed function: logDACB
        function logArea(app, event)
            plotActivity(app)
        end

        % Button pushed function: parseBt
        function parseData(app, event)
            % Dec 31, 2019
            % parse the dataIn to identify the behavioural states.
            % State 1: active exploratory: the mouse is moving at a speed larger than speedThr
            % State 2: active grooming or feeding: the mouse is moving less than speedThr but is changing shape as defined by 
            %          an area change larger than areaThr
            % State 3: resting. The mouse is still ad is not moving for a length of time less than the sleep threshold
            % State 4: sleeping. Whenever state 3 last longer than the sleep threshold.
            
            % April 30, 2020.
            % Code optimisation.
            % The code based on the while... end loop took about 1000 s to
            % process about 370.000 data points.
            % May 2, 2020. After vectorization of the cluster
            % identification the time drops to 60 s.
            % Final vectorization: about 4 s.
            
            
%             app.trajectory.smSpeed = smoothdata(app.trajectory.speed,"sgolay",app.rollingWnd.Value);
%             app.trajectory.smDeltaSpeed = smoothdata(app.trajectory.deltaSpeed,"sgolay",app.rollingWnd.Value);
%             toc
            % First, extract the indexes of the elements satisfying the thr
            % condition on speed
%             movingThr = [0.1 0.5 1.0]
%             figure
%             for i=1:length(movingThr)
%                 iImmSp = find(app.trajectory.speed <= movingThr(i));
%                 extDeltaPx = app.trajectory.deltaSpeed(iImmSp);
%                 hold on
% %                histogram(log10(extDeltaPx))
%                 histogram(extDeltaPx)
%             end

            app.parseBt.Text = 'Computing';
            app.parseBt.BackgroundColor = [1 0 0];
            drawnow
            
            % extract the mouse profile area change data set according to the switch position
            if strcmp(app.selectDeltaPxSW.Value,'dA (px/s)')
                extDeltaPx = app.trajectory.deltaSpeed;        
            else
                extDeltaPx = app.trajectory.deltaPx;    
            end
            % If selected, compute the SG smoothed data
            if app.smoothDACB.Value
               extDeltaPx = smoothdata(extDeltaPx,"sgolay",app.rollingWnd.Value*2-1);
               extDeltaPx(extDeltaPx<0) = 10;
               % rollingWnd is the half width. In this way we ensure that
               % the width is an odd number
            end

            % Extract indexes of all frames where the mouse is moving over
            % the threshold for rest or local activity
            iImmSp = find(app.trajectory.speed <= app.thrSpeed.Value);

            if app.autoThrCB.Value                                    
                % Compute the double gaussian fit of the log10 of this data set to extracts two modes.
                % As of Jan 30, I have no clear understanding of the
                % statistics of extDeltaPx. 
                figure
                edges = 0.1:0.05:3.5;
                h = histogram(log10(extDeltaPx(iImmSp)),edges,'Normalization','probability');
                binCenters (1:h.NumBins) = (h.BinEdges(1:h.NumBins) + h.BinEdges(2:h.NumBins+1))/2;
                f = fit(binCenters',h.Values','gauss2','Display','notify','StartPoint',[0.5,1,0.5,0.5,2,0.5]);
                hold on
                plot(f);
                % Compute the threshold by identifying the minimum of the sum of thr
                % two gaussians fit
                app.areaThr.Value = 10^fminbnd(f,1,2);   % f.b1 abd f.b2 are the means of the two Gaussia components
            end
            tic
            % Parsing of the localActivity state.            
            app.parseBt.Text = 'Local Act';
            drawnow
            iLocAct = find(extDeltaPx > app.areaThr.Value);
            ints = intersect (iImmSp,iLocAct);
            app.trajectory.localActivity = detectClusters(app,ints,2);
            
            app.parseBt.Text = 'Resting';
            drawnow
            iImmDA = find(extDeltaPx <= app.areaThr.Value);
            ints = intersect (iImmSp,iImmDA);           % indexes of the element in commons of the arrays of indexes            
            % this intersection represents the sum of state 3 and 4.
            app.trajectory.resting = detectClusters(app,ints,3);

            % Parsing of the exploratory state.            
            app.parseBt.Text = 'Exploratory';
            drawnow
            iImmSp = find(app.trajectory.speed > app.thrSpeed.Value);
            app.trajectory.exploratory = detectClusters(app,iImmSp,1);
            % these events have a further metric which is the average speed
            % during the episode
            for i=1:app.trajectory.exploratory.n
                app.trajectory.exploratory.meanS(i) = mean(app.trajectory.speed(app.trajectory.exploratory.leftI(i):app.trajectory.exploratory.rightI(i)));
            end

            app.parseBt.Text = 'Sleep';
            drawnow
            % Now extract from resting states longer than the min sleep duration threshold
%            sleepI = app.trajectory.resting.duration(app.trajectory.resting.duration>=seconds(app.minSleep.Value));
            sleepI = find(app.trajectory.resting.duration>=seconds(app.minSleep.Value));
            app.trajectory.sleep.n = length(sleepI);
            app.trajectory.sleep.leftI(1:app.trajectory.sleep.n) = app.trajectory.resting.leftI(sleepI);
            app.trajectory.sleep.rightI(1:app.trajectory.sleep.n) = app.trajectory.resting.rightI(sleepI);
            app.trajectory.sleep.duration(1:app.trajectory.sleep.n) = app.trajectory.resting.duration(sleepI);
            app.trajectory.sleep.time1(1:app.trajectory.sleep.n) = app.trajectory.resting.time1(sleepI);
            toc            
            app.parseBt.Text = 'Parse';
            app.parseBt.BackgroundColor = [0.96 0.96 0.96];
            drawnow
        end

        % Button pushed function: reportBt
        function reportTr(app, event)
            if isempty(app.trjH)
                % Call the report app
                app.trjH = trajectoriesRep (app);
            end
        end

        % Value changed function: plotWidth
        function changePlotW(app, event)
            plotActivity(app)
        end

        % Value changed function: displayStatesCB
        function dispSt(app, event)
            plotActivity(app)
        end

        % Button pushed function: notesBt
        function openNotes(app, event)
            notes(app)
        end

        % Button pushed function: saveMdataBt
        function saveTrj(app, event)
            % The entire cleaned-up data is saved together with the computed metrics.
            % The new data is fully compatible with Hypnos and allows a faster 
            % reload of a previously analyzed data. March 17, 2020
            
            % Create a new structure holding the output
            
            dataOut.notes = app.trajectory.notes;
            dataOut.birthdate = app.trajectory.birthdate;
            dataOut.sex = app.trajectory.sex;
            dataOut.geneticBackground = app.trajectory.geneticBackground;
            dataOut.lightCycle = app.trajectory.lightCycle;

            dataOut.x = app.trajectory.x; 
            dataOut.y = app.trajectory.y; 
            dataOut.deltaA = app.trajectory.deltaA;
            dataOut.deltaPx = app.trajectory.deltaPx;
            dataOut.environment = app.trajectory.environment;
            dataOut.year = app.trajectory.year;
            dataOut.month = app.trajectory.month;            
            dataOut.day = app.trajectory.day;
            dataOut.hour = app.trajectory.hour;
            dataOut.min = app.trajectory.min;
            dataOut.sec = app.trajectory.sec;
            dataOut.secFromStart = app.trajectory.secFromStart;
            dataOut.d = app.trajectory.d;
            dataOut.speed = app.trajectory.speed;
            dataOut.state = app.trajectory.state;
            dataOut.stateLabel = app.trajectory.stateLabel;
            % Now save the newly computed time series

            % Create the file name and save the data
            dt = datestr(datetime(dataOut.year(1),dataOut.month(1),dataOut.day(1)));         % Datetime at the beginning
            fileName = [app.dataPath 'hypnos ' dt '.mat']; 
            save(fileName,'-struct','dataOut');

        end

        % Value changed function: autoScaleCB
        function autoscale(app, event)
            plotActivity(app)
        end

        % Value changed function: maxSpeed
        function changeMaxSpeed(app, event)
            plotActivity(app)
        end

        % Value changed function: maxDelta
        function changeMaxArea(app, event)
            plotActivity(app)            
        end

        % Value changed function: drawArenaOnPlotsCB
        function drawArenaOnPlotsCBValueChanged(app, event)
            plotTrajectory(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Hypnos = uifigure('Visible', 'off');
            app.Hypnos.Name = 'Hypnos';
            app.InputDataPanel = uipanel(app.Hypnos);
            app.InputDataPanel.Title = 'Input Data';
            app.OpenButton = uibutton(app.InputDataPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @openMfile, true);
            app.OpenButton.Tooltip = {'Open Morpheus data set'};
            app.OpenButton.Text = 'Open';
            app.outText = uilabel(app.InputDataPanel);
            app.cmpixLabel = uilabel(app.InputDataPanel);
            app.cmpixLabel.Text = 'cm/pix:';
            app.scaleFactor = uieditfield(app.InputDataPanel, 'numeric');
            app.scaleFactor.Limits = [0 10];
            app.scaleFactor.Value = 0.108;
            app.splitData = uicheckbox(app.InputDataPanel);
            app.splitData.Tooltip = {'Activate this option to split  long data (over 24 hrs).'};
            app.splitData.Text = 'Split if longer than 1 dd+hh:';
            app.remainHr = uieditfield(app.InputDataPanel, 'numeric');
            app.remainHr.Limits = [1 23];
            app.remainHr.ValueDisplayFormat = '%.0f';
            app.remainHr.Value = 6;
            app.notesBt = uibutton(app.InputDataPanel, 'push');
            app.notesBt.ButtonPushedFcn = createCallbackFcn(app, @openNotes, true);
            app.notesBt.Text = 'Notes';
            app.HighspeedthresholdcmsEditFieldLabel = uilabel(app.InputDataPanel);
            app.HighspeedthresholdcmsEditFieldLabel.Text = 'High speed threshold (cm/s):';
            app.highSpeedLbl = uieditfield(app.InputDataPanel, 'numeric');
            app.highSpeedLbl.Limits = [40 400];
            app.highSpeedLbl.ValueDisplayFormat = '%.1f';
            app.highSpeedLbl.Value = 80;
            app.ArenaLabel = uilabel(app.InputDataPanel);
            app.ArenaLabel.Text = 'Arena:';
            app.arenaBottomLeftX = uieditfield(app.InputDataPanel, 'numeric');
            app.arenaBottomLeftX.Limits = [0 1240];
            app.arenaBottomLeftX.ValueDisplayFormat = '%.0f';
            app.arenaBottomLeftX.Tooltip = {'Coordinates (x,y) of top left and bottom right corners of the arena'};
            app.arenaBottomLeftX.Value = 84;
            app.arenaBottomLeftY = uieditfield(app.InputDataPanel, 'numeric');
            app.arenaBottomLeftY.Limits = [1 1024];
            app.arenaBottomLeftY.ValueDisplayFormat = '%.0f';
            app.arenaBottomLeftY.Tooltip = {'Coordinates (x,y) of top left and bottom right corners of the arena'};
            app.arenaBottomLeftY.Value = 118;
            app.arenaTopRightX = uieditfield(app.InputDataPanel, 'numeric');
            app.arenaTopRightX.Limits = [0 1240];
            app.arenaTopRightX.ValueDisplayFormat = '%.0f';
            app.arenaTopRightX.Tooltip = {'Coordinates (x,y) of top left and bottom right corners of the arena'};
            app.arenaTopRightX.Value = 564;
            app.arenaTopRightY = uieditfield(app.InputDataPanel, 'numeric');
            app.arenaTopRightY.Limits = [0 1024];
            app.arenaTopRightY.ValueDisplayFormat = '%.0f';
            app.arenaTopRightY.Tooltip = {'Coordinates (x,y) of top left and bottom right corners of the arena'};
            app.arenaTopRightY.Value = 362;
            app.ParametersforanalysisandstatedefinitionsPanel = uipanel(app.Hypnos);
            app.ParametersforanalysisandstatedefinitionsPanel.Title = 'Parameters for analysis and state definitions';
            app.ThresholdspeedcmsLabel = uilabel(app.ParametersforanalysisandstatedefinitionsPanel);
            app.ThresholdspeedcmsLabel.Text = 'Threshold speed (cm/s):';
            app.thrSpeed = uieditfield(app.ParametersforanalysisandstatedefinitionsPanel, 'numeric');
            app.thrSpeed.Limits = [0 Inf];
            app.thrSpeed.Value = 0.5;
            app.DeltaALabel = uilabel(app.ParametersforanalysisandstatedefinitionsPanel);
            app.DeltaALabel.Text = 'Delta A:';
            app.areaThr = uieditfield(app.ParametersforanalysisandstatedefinitionsPanel, 'numeric');
            app.areaThr.Limits = [0 Inf];
            app.areaThr.Tooltip = {'Threshold for delta Px. This is computed automatically by the double gaussia fit of this process'};
            app.areaThr.Value = 50;
            app.MindurationofsleepeventssLabel = uilabel(app.ParametersforanalysisandstatedefinitionsPanel);
            app.MindurationofsleepeventssLabel.Text = 'Min duration of sleep events (s):';
            app.minSleep = uieditfield(app.ParametersforanalysisandstatedefinitionsPanel, 'numeric');
            app.minSleep.Limits = [0 Inf];
            app.minSleep.Value = 20;
            app.LPfilterhalfWdLabel = uilabel(app.ParametersforanalysisandstatedefinitionsPanel);
            app.LPfilterhalfWdLabel.Text = 'LP filter (half Wd):';
            app.rollingWnd = uieditfield(app.ParametersforanalysisandstatedefinitionsPanel, 'numeric');
            app.rollingWnd.Limits = [0 Inf];
            app.rollingWnd.Tooltip = {'Width of Savitzky-Golay filter'};
            app.rollingWnd.Value = 5;
            app.MaxinerruptionofrestphasespLabel = uilabel(app.ParametersforanalysisandstatedefinitionsPanel);
            app.MaxinerruptionofrestphasespLabel.Text = 'Max inerruption of rest phase (sp):';
            app.maxInterruption = uieditfield(app.ParametersforanalysisandstatedefinitionsPanel, 'numeric');
            app.maxInterruption.Limits = [0 Inf];
            app.maxInterruption.Value = 2;
            app.autoThrCB = uicheckbox(app.ParametersforanalysisandstatedefinitionsPanel);
            app.autoThrCB.Text = 'Auto-threshold dA';
            app.SelectdeltaASwitchLabel = uilabel(app.ParametersforanalysisandstatedefinitionsPanel);
            app.SelectdeltaASwitchLabel.Text = 'Select delta A';
            app.selectDeltaPxSW = uiswitch(app.ParametersforanalysisandstatedefinitionsPanel, 'slider');
            app.selectDeltaPxSW.Items = {'dA (px/s)', 'dA (px)'};
            app.selectDeltaPxSW.Value = 'dA (px/s)';
            app.DisplayandOutputPanel = uipanel(app.Hypnos);
            app.DisplayandOutputPanel.Title = 'Display and Output';
            app.logSpeedCB = uicheckbox(app.DisplayandOutputPanel);
            app.logSpeedCB.ValueChangedFcn = createCallbackFcn(app, @logSpeed, true);
            app.logSpeedCB.Text = 'Log of speed';
            app.logDACB = uicheckbox(app.DisplayandOutputPanel);
            app.logDACB.ValueChangedFcn = createCallbackFcn(app, @logArea, true);
            app.logDACB.Text = 'Log of area ';
            app.smoothSpeedCB = uicheckbox(app.DisplayandOutputPanel);
            app.smoothSpeedCB.Text = 'Smooth speed';
            app.smoothDACB = uicheckbox(app.DisplayandOutputPanel);
            app.smoothDACB.Text = 'Sm area';
            app.displayStatesCB = uicheckbox(app.DisplayandOutputPanel);
            app.displayStatesCB.ValueChangedFcn = createCallbackFcn(app, @dispSt, true);
            app.displayStatesCB.Text = 'Display states';
            app.parseBt = uibutton(app.DisplayandOutputPanel, 'push');
            app.parseBt.ButtonPushedFcn = createCallbackFcn(app, @parseData, true);
            app.parseBt.Tooltip = {'Parse the input data for state identification'};
            app.parseBt.Text = 'Parse';
            app.reportBt = uibutton(app.DisplayandOutputPanel, 'push');
            app.reportBt.ButtonPushedFcn = createCallbackFcn(app, @reportTr, true);
            app.reportBt.Text = 'Report';
            app.saveMdataBt = uibutton(app.DisplayandOutputPanel, 'push');
            app.saveMdataBt.ButtonPushedFcn = createCallbackFcn(app, @saveTrj, true);
            app.saveMdataBt.Tooltip = {'Save edited data (cleaned coordinates'; ' computed speed'; ' displacement'; ' pasing and edited notes)'};
            app.saveMdataBt.Text = 'Save M data';
            app.statesOnTrjCB = uicheckbox(app.DisplayandOutputPanel);
            app.statesOnTrjCB.Text = 'States on trajectory';
            app.drawArenaOnPlotsCB = uicheckbox(app.DisplayandOutputPanel);
            app.drawArenaOnPlotsCB.ValueChangedFcn = createCallbackFcn(app, @drawArenaOnPlotsCBValueChanged, true);
            app.drawArenaOnPlotsCB.Tooltip = {'Draw the edges of the arena on the trajectory plots'};
            app.drawArenaOnPlotsCB.Text = 'Show arena';
            app.widthsEditFieldLabel = uilabel(app.Hypnos);
            app.widthsEditFieldLabel.Text = 'width (s)';
            app.plotWidth = uieditfield(app.Hypnos, 'numeric');
            app.plotWidth.Limits = [0 Inf];
            app.plotWidth.ValueChangedFcn = createCallbackFcn(app, @changePlotW, true);
            app.plotWidth.Value = 3600;
            app.scrollLeftBtn = uibutton(app.Hypnos, 'push');
            app.scrollLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @scrollLeft, true);
            app.scrollLeftBtn.Text = '<';
            app.scrollRightBtn = uibutton(app.Hypnos, 'push');
            app.scrollRightBtn.ButtonPushedFcn = createCallbackFcn(app, @scrollRight, true);
            app.scrollRightBtn.Text = '>';
            app.resetWndBtn = uibutton(app.Hypnos, 'push');
            app.resetWndBtn.ButtonPushedFcn = createCallbackFcn(app, @resetWnd, true);
            app.resetWndBtn.Text = 'Reset';
            app.zoomInBtn = uibutton(app.Hypnos, 'push');
            app.zoomInBtn.ButtonPushedFcn = createCallbackFcn(app, @zoomIn, true);
            app.zoomInBtn.Text = 'Zoom';
            app.autoScaleCB = uicheckbox(app.Hypnos);
            app.autoScaleCB.ValueChangedFcn = createCallbackFcn(app, @autoscale, true);
            app.autoScaleCB.Text = 'Y axis autoscale';
            app.autoScaleCB.Value = true;
            app.MaxspeedcmsLabel = uilabel(app.Hypnos);
            app.MaxspeedcmsLabel.Text = 'Max speed (cm/s):';
            app.maxSpeed = uieditfield(app.Hypnos, 'numeric');
            app.maxSpeed.Limits = [0 Inf];
            app.maxSpeed.ValueDisplayFormat = '%.1f';
            app.maxSpeed.ValueChangedFcn = createCallbackFcn(app, @changeMaxSpeed, true);
            app.maxSpeed.Value = 20;
            app.MaxdeltaareapxLabel = uilabel(app.Hypnos);
            app.MaxdeltaareapxLabel.Text = 'Max delta area (px):';
            app.maxDelta = uieditfield(app.Hypnos, 'numeric');
            app.maxDelta.Limits = [0 Inf];
            app.maxDelta.ValueDisplayFormat = '%.0f';
            app.maxDelta.ValueChangedFcn = createCallbackFcn(app, @changeMaxArea, true);
            app.maxDelta.Value = 5000;
            app.ExplorationLabel = uilabel(app.Hypnos);
            app.ExplorationLabel.Text = 'Exploration';
            app.LocalactivityLabel = uilabel(app.Hypnos);
            app.LocalactivityLabel.Text = 'Local activity';
            app.RestLabel = uilabel(app.Hypnos);
            app.RestLabel.Text = 'Rest';
            app.speedAx = uiaxes(app.Hypnos);
            title(app.speedAx, 'Speed')
            ylabel(app.speedAx, 'Speed (cm/s)')
            app.speedAx.XTick = [];
            app.speedAx.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.mapAx = uiaxes(app.Hypnos);
            app.mapAx.XTick = [];
            app.mapAx.YTick = [];
            app.deltaAx = uiaxes(app.Hypnos);
            title(app.deltaAx, 'Delta Area')
            ylabel(app.deltaAx, 'Delta (pixel)')
            % Show the figure after all components are created
            app.Hypnos.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hypnos_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Hypnos)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Hypnos)
        end
    end
end