classdef trajectoriesRep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReportUIFigure                 matlab.ui.Figure
        RefreshButton                  matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        TrajectoriesTab                matlab.ui.container.Tab
        plotTrBt                       matlab.ui.control.Button
        plot1                          matlab.ui.control.UIAxes
        BehaviouralstatesTab           matlab.ui.container.Tab
        ShowXCorr                      matlab.ui.control.Button
        LogCheckBox                    matlab.ui.control.CheckBox
        ExportButton                   matlab.ui.control.Button
        YMaxvalueLabel                 matlab.ui.control.Label
        autoScaleBoxPlot               matlab.ui.control.CheckBox
        maxT                           matlab.ui.control.NumericEditField
        selectStateDD                  matlab.ui.control.DropDown
        SelectboxplotLabel             matlab.ui.control.Label
        compDisp                       matlab.ui.control.Button
        binWidth                       matlab.ui.control.NumericEditField
        BinwidthminLabel               matlab.ui.control.Label
        compBinnedSt                   matlab.ui.control.Button
        boxPlot                        matlab.ui.control.UIAxes
        states                         matlab.ui.control.UIAxes
        integralD                      matlab.ui.control.UIAxes
        MixedStatsTab                  matlab.ui.container.Tab
        FittingOptionsLogDistributionsPanel  matlab.ui.container.Panel
        AllDayRestThr                  matlab.ui.control.EditField
        NightRestThr                   matlab.ui.control.EditField
        DayRestThr                     matlab.ui.control.EditField
        FitButtonT                     matlab.ui.control.Button
        FitButtonN                     matlab.ui.control.Button
        FitButtonD                     matlab.ui.control.Button
        DayRestingFitLamp              matlab.ui.control.Lamp
        DayRestingFitLampLabel         matlab.ui.control.Label
        AllDayRestingFitLamp           matlab.ui.control.Lamp
        AllDayRestingFitLampLabel      matlab.ui.control.Label
        NightRestingFitLamp            matlab.ui.control.Lamp
        NightRestingFitLampLabel       matlab.ui.control.Label
        LogThr_N                       matlab.ui.control.NumericEditField
        LogThrEditFieldLabel_3         matlab.ui.control.Label
        ManualRestFitN                 matlab.ui.control.CheckBox
        LogThr_D                       matlab.ui.control.NumericEditField
        LogThrDay                      matlab.ui.control.Label
        ManualRestFitD                 matlab.ui.control.CheckBox
        RefreshRestButton              matlab.ui.control.Button
        LogThr                         matlab.ui.control.NumericEditField
        LogThrEditFieldLabel           matlab.ui.control.Label
        ManualRestFitCB                matlab.ui.control.CheckBox
        AreaSmooth                     matlab.ui.control.CheckBox
        ShowFitRestCB                  matlab.ui.control.CheckBox
        ShowFitCB                      matlab.ui.control.CheckBox
        RefreshPxButton                matlab.ui.control.Button
        deltaPxSlowBins                matlab.ui.control.NumericEditField
        BinsLabel_4                    matlab.ui.control.Label
        restingDurationBins            matlab.ui.control.NumericEditField
        BinsLabel_3                    matlab.ui.control.Label
        deltaPxBins                    matlab.ui.control.NumericEditField
        BinsLabel_2                    matlab.ui.control.Label
        speedBins                      matlab.ui.control.NumericEditField
        BinsLabel                      matlab.ui.control.Label
        maxYDeltaPxSlow                matlab.ui.control.NumericEditField
        MaxYLabel_4                    matlab.ui.control.Label
        maxXDeltaPxSlow                matlab.ui.control.NumericEditField
        MaxXLabel_4                    matlab.ui.control.Label
        maxYDeltaPx                    matlab.ui.control.NumericEditField
        MaxYLabel_3                    matlab.ui.control.Label
        maxXDeltaPx                    matlab.ui.control.NumericEditField
        MaxXLabel_3                    matlab.ui.control.Label
        maxYResting                    matlab.ui.control.NumericEditField
        MaxYLabel_2                    matlab.ui.control.Label
        maxXResting                    matlab.ui.control.NumericEditField
        MaxXLabel_2                    matlab.ui.control.Label
        maxYSpeed                      matlab.ui.control.NumericEditField
        MaxYLabel                      matlab.ui.control.Label
        maxXSpeed                      matlab.ui.control.NumericEditField
        MaxXLabel                      matlab.ui.control.Label
        DisplaycontrolPanel            matlab.ui.container.Panel
        autoYCB                        matlab.ui.control.CheckBox
        distributionOfLogCB            matlab.ui.control.CheckBox
        plotStatsRS                    matlab.ui.control.Button
        ExportHisto                    matlab.ui.control.Button
        autoXCB                        matlab.ui.control.CheckBox
        YlogScaleCB                    matlab.ui.control.CheckBox
        useEntireDayNightPeriodsCB     matlab.ui.control.CheckBox
        nightDayWidth                  matlab.ui.control.NumericEditField
        WidthofnightdayperiodshhLabel  matlab.ui.control.Label
        hourDayStart                   matlab.ui.control.NumericEditField
        HouratthebeginningofthedayLabel  matlab.ui.control.Label
        hourNightStart                 matlab.ui.control.NumericEditField
        HouratthebeginningofthenightLabel  matlab.ui.control.Label
        restingDurationDistrTotal      matlab.ui.control.UIAxes
        speedDistrTotal                matlab.ui.control.UIAxes
        deltaPxSlowDistrTotal          matlab.ui.control.UIAxes
        deltaPxDistrTotal              matlab.ui.control.UIAxes
        restingDurationDistrNight      matlab.ui.control.UIAxes
        restingDurationDistrDay        matlab.ui.control.UIAxes
        deltaPxSlowDistrNight          matlab.ui.control.UIAxes
        deltaPxSlowDistrDay            matlab.ui.control.UIAxes
        deltaPxDistrNight              matlab.ui.control.UIAxes
        deltaPxDistrDay                matlab.ui.control.UIAxes
        speedDistrNight                matlab.ui.control.UIAxes
        speedDistrDay                  matlab.ui.control.UIAxes
        SpaceDensityTab                matlab.ui.container.Tab
        TimeWindowParametersPanel      matlab.ui.container.Panel
        BinDensityProf_TW              matlab.ui.control.NumericEditField
        BinEditFieldLabel_2            matlab.ui.control.Label
        MaxY_DP_TW                     matlab.ui.control.NumericEditField
        MaxYLabel_6                    matlab.ui.control.Label
        MaxX_DP_TW                     matlab.ui.control.NumericEditField
        MaxXLabel_6                    matlab.ui.control.Label
        FunHisto_TW                    matlab.ui.control.Switch
        ManualAxisDP_TW                matlab.ui.control.CheckBox
        hoursTW                        matlab.ui.control.NumericEditField
        hoursEditFieldLabel            matlab.ui.control.Label
        AddHoursTW                     matlab.ui.control.CheckBox
        GOButtonTW                     matlab.ui.control.Button
        MaxCLimTW                      matlab.ui.control.NumericEditField
        MaxEditFieldLabel_2            matlab.ui.control.Label
        MinCLimTW                      matlab.ui.control.NumericEditField
        MinEditFieldLabel_2            matlab.ui.control.Label
        ManualClimTW                   matlab.ui.control.CheckBox
        ShowColorbarTW                 matlab.ui.control.CheckBox
        TohLabel                       matlab.ui.control.Label
        ToTW                           matlab.ui.control.EditField
        FromTW                         matlab.ui.control.EditField
        FromhLabel                     matlab.ui.control.Label
        Start                          matlab.ui.control.CheckBox
        DensityParametersPanel         matlab.ui.container.Panel
        BinDensityProf                 matlab.ui.control.NumericEditField
        BinEditFieldLabel              matlab.ui.control.Label
        FunHisto                       matlab.ui.control.Switch
        ButtonGroup                    matlab.ui.container.ButtonGroup
        NotRestingData                 matlab.ui.control.RadioButton
        RestingData                    matlab.ui.control.RadioButton
        AllData                        matlab.ui.control.RadioButton
        MaxY_DP                        matlab.ui.control.NumericEditField
        MaxYLabel_5                    matlab.ui.control.Label
        MaxX_DP                        matlab.ui.control.NumericEditField
        MaxXLabel_5                    matlab.ui.control.Label
        ManualAxisDP                   matlab.ui.control.CheckBox
        TimeSwitch                     matlab.ui.control.Switch
        MaxCLim                        matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MinCLim                        matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        ManualClimCheckBox             matlab.ui.control.CheckBox
        ShowColorbar                   matlab.ui.control.CheckBox
        GOButton                       matlab.ui.control.Button
        DensityProfileTW               matlab.ui.control.UIAxes
        TW_Density                     matlab.ui.control.UIAxes
        DensityProfileD                matlab.ui.control.UIAxes
        DensityProfileT                matlab.ui.control.UIAxes
        DensityProfileN                matlab.ui.control.UIAxes
        DensityDAY                     matlab.ui.control.UIAxes
        DensityALLDAY                  matlab.ui.control.UIAxes
        DensityNIGHT                   matlab.ui.control.UIAxes
        notes                          matlab.ui.container.Tab
        WriteTable                     matlab.ui.control.StateButton
        SummaryTable                   matlab.ui.control.Table
        FFTAreaPlotCB                  matlab.ui.control.CheckBox
        FFTSpeedPlotCB                 matlab.ui.control.CheckBox
        FourierButton                  matlab.ui.control.Button
        ComputationandReportingPanel   matlab.ui.container.Panel
        reportmatButton                matlab.ui.control.Button
        WORDreportButton               matlab.ui.control.Button
        wrapAroundGapLbl               matlab.ui.control.Label
        LogscaleCheckBox               matlab.ui.control.CheckBox
        binActogram                    matlab.ui.control.NumericEditField
        BinmLabel                      matlab.ui.control.Label
        DisplayasaheatmapCheckBox      matlab.ui.control.CheckBox
        computeActogram                matlab.ui.control.Button
        hourActogram                   matlab.ui.control.NumericEditField
        startingfromhLabel             matlab.ui.control.Label
        wrapActogramCB                 matlab.ui.control.CheckBox
        StrainandothernotesPanel       matlab.ui.container.Panel
        notesLbl                       matlab.ui.control.Label
        lightCycleLbl                  matlab.ui.control.Label
        sexLbl                         matlab.ui.control.Label
        geneticBackgroundLbl           matlab.ui.control.Label
        ageAtExperimentTimeLbl         matlab.ui.control.Label
        birthDateLbl                   matlab.ui.control.Label
        AcquisitionandimagetreatmentPanel  matlab.ui.container.Panel
        blobAnalyzerFiltersizeLbl      matlab.ui.control.Label
        LPfilterEachFramePxLbl         matlab.ui.control.Label
        LPfilterFlatFieldPxLbl         matlab.ui.control.Label
        analysisModeLbl                matlab.ui.control.Label
        segmentationModeLbl            matlab.ui.control.Label
        thresholdNightAndDayLbl        matlab.ui.control.Label
        cameraExposureLbl              matlab.ui.control.Label
        framesLbl                      matlab.ui.control.Label
        spLbl                          matlab.ui.control.Label
        timeAndDuration                matlab.ui.control.Label
        dirFile                        matlab.ui.control.Label
        actogramArea                   matlab.ui.control.UIAxes
        actogramSpeed                  matlab.ui.control.UIAxes
        timingExp                      matlab.ui.control.Label
        idExperiment                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        plot2                 matlab.ui.control.UIAxes
        plot3                 matlab.ui.control.UIAxes
        plot4                 matlab.ui.control.UIAxes
        plot5                 matlab.ui.control.UIAxes
        plot6                 matlab.ui.control.UIAxes
        plot7                 matlab.ui.control.UIAxes
        plot8                 matlab.ui.control.UIAxes
        plot9                 matlab.ui.control.UIAxes
        plot10                 matlab.ui.control.UIAxes
        plot11                 matlab.ui.control.UIAxes
        plot12                 matlab.ui.control.UIAxes
        plot13                 matlab.ui.control.UIAxes
        plot14                 matlab.ui.control.UIAxes
        plot15                 matlab.ui.control.UIAxes
        plot16                 matlab.ui.control.UIAxes
        plot17                 matlab.ui.control.UIAxes
        plot18                 matlab.ui.control.UIAxes
        plot19                 matlab.ui.control.UIAxes
        plot20                 matlab.ui.control.UIAxes
        plot21                 matlab.ui.control.UIAxes
        plot22                 matlab.ui.control.UIAxes
        plot23                 matlab.ui.control.UIAxes
        plot24                 matlab.ui.control.UIAxes

        stateBoxArraySt1        % vector containing all samples for each state (St1=exploratory)
        stateBoxArraySt2        % vector containing all samples for each state (St2=grooming)
        stateBoxArraySt3        % vector containing all samples for each state (St3=resting)
        stateBoxArraySt4        % vector containing all samples for each state (St4=sleep)
        stateBoxArraySt5        % vector containing speed of all exploratory states
                 
        report                  % structure holding the report data with fields:
        % statePercent          fraction of time spent in each state
        % stateBinT             center datetime of each bin
        % stateBoxCounter       number of state events in each bin

        % quantilesBoxArray1    quantiles for the state duration and speed
        % quantilesBoxArray2
        % quantilesBoxArray3       
        % quantilesBoxArray4
        % quantilesBoxArray5
        % meanDurationBoxArraySt      mean duration of states (and mean speed for state 5)
        % medianDurationBoxArraySt    median duration of states (and mean speed for state 5)
       
        %% fitting Slow Delta Px distribution parameters
        %ratioAN  %ratio between the areas of the first and second peak
        %ratioAD
        
        %% Resting Fitting parameters
        %RestThrN
        %RatioRestN
        %RestThrD
        %RatioRestD
        %RestThr
        
        %% CC parameters
        %CCdayMax
        %MaxDayLag
        %CCnightMax
        %MaxnightLag
        
        %% Fourier Parameters:
        %MaxAmpS_Night
        %FreqS_Night
        %MaxAmpA_Night
        %FreqA_Night
        %MaxAmpS_Day
        %FreqS_Day
        %MaxAmpA_Day
        %FreqA_Day


        gV1                     % gouping variables for box plots
        gV2
        gV3
        gV4
        gV5
        
        binTicks                % Custom labels for binned state plots.
        binTicksBP
        binTickLbl
        BinExportLbl
               
        NightFit
        DayFit
        
        RestNightFit
        RestDayFit
        RestFit
        
        Expl_D
        Groom_D
        Expl_N
        Groom_N
        
        funfit
        funfitD
        funfitN
        
        FirstValue
        FinalValue
    end

    properties (Access = private)
        hypnos                  % app structure of the parent app
        maxValueBox             % max values of Y axis box plots
        word                    % handle to the word instance
    end
    
    methods (Access = public)
        
        function plotStateDuration(app)
            % February 22, 2020. GMR
            % This function plots any of the 4 states by composing the boxlot statement using
            % string concatenation and the eval function
            
            % determine the statistic to display
            st = app.selectStateDD.Value;
            cla(app.boxPlot)
            if app.LogCheckBox.Value== 0
                strBoxPlot = ['boxplot(app.boxPlot,app.stateBoxArraySt' st ',app.gV' st ',''Symbol'','''')'];
                eval(strBoxPlot);
            else
                strLogStatebox = ['LogstateBoxArraySt' st '= log10(app.stateBoxArraySt' st ');'];
                eval(strLogStatebox);
                strBoxPlotLog= ['boxplot(app.boxPlot, LogstateBoxArraySt' st ',app.gV' st ',''Symbol'','''')'];
                eval(strBoxPlotLog);
            end
            
            % determine the display mode and the max Y value of the plot
            if ~app.autoScaleBoxPlot.Value
                if ~app.LogCheckBox.Value
                    maxY = app.maxT.Value;
                    app.boxPlot.YLim = [0  maxY];
                else
                    maxY = app.maxT.Value;
                    strMin= ['minY= min(LogstateBoxArraySt' st ',[],2);'];
                    eval(strMin);
                    minY=(minY - 0.5);
                    app.boxPlot.YLim = [minY, maxY];
                end
                
            end
            app.boxPlot.XTick = app.binTicksBP;
            app.boxPlot.XTickLabel = app.binTickLbl;

            alignPlots(app)
            % boxplot(app.boxPlot,app.stateBoxArraySt(value)(:),app.gV(value)(:),'Symbol','')
        end
        
        function ind = findI(app,dt)
            % March 23, 2020
            % This function identify the element of the array timeArray the
            % provides the closest datetime to dt
            delta = app.hypnos.trajectory.timeArray - dt;
            [minV, ind] = min(abs(delta)); 
        end
    end
    
    methods (Access = private)
        
        function alignPlots(app)
            % February 2020
            % This function aligns the inner positions of the states plot
            
            ipID = app.integralD.InnerPosition;
            ipSt = app.states.InnerPosition;
            ipBox = app.boxPlot.InnerPosition;
            dw = (-ipID(3) + ipSt(3));
            app.states.Position(3) = app.states.Position(3) - dw;
            app.states.Position(1) = app.states.Position(1) + dw;
            dw = (-ipID(3) + ipBox(3));
            app.boxPlot.Position(3) = app.boxPlot.Position(3) - dw;
            app.boxPlot.Position(1) = app.boxPlot.Position(1) + dw;
            
            % the second iteration perfects the alignment
            ipID = app.integralD.InnerPosition;
            ipSt = app.states.InnerPosition;
            ipBox = app.boxPlot.InnerPosition;
            dw = (-ipID(3) + ipSt(3));
            app.states.Position(3) = app.states.Position(3) - dw;
            app.states.Position(1) = app.states.Position(1) + dw;
            dw = (-ipID(3) + ipBox(3));
            app.boxPlot.Position(3) = app.boxPlot.Position(3) - dw;
            app.boxPlot.Position(1) = app.boxPlot.Position(1) + dw;
        end
        
        
        function ind = findDI(app, dArray, dt)
            % Find the closest duration value to dt in the array dArray
            delta = dArray - dt;
            [minV, ind] = min(abs(delta));
        end
        
        
        function computeAllHistograms(app)
            % This function acts as a simple container of all the calls to
            % the computations of each histo couple
            computeSpeedHisto (app)
            computeDeltaPixHisto (app)
            computeDeltaPixSlowHisto (app)
            computeRestingDurationHisto (app)
        end
        
        function computeDeltaPixSlowHisto(app)
            % First: compute the indexes of the data subset 
            moonIndex = findIndNight (app);
            sunIndex = findIndDay (app);
            
            
            [slowInd, col] = find(app.hypnos.trajectory.speed <= app.hypnos.thrSpeed.Value);
            indDay = intersect(sunIndex, slowInd);
            indNight = intersect(moonIndex, slowInd);
%                 FinalIndexDay=SunIndex.*IdentityDAY;
%                 FinalIndexDay=nonzeros(FinalIndexDay);
%                 app.DayDeltaSlow=app.hypnos.trajectory.deltaPx(FinalIndexDay,1);
 
            % create the bin edges
            w = app.maxXDeltaPxSlow.Value / app.deltaPxSlowBins.Value;
            edges = 0:w:app.maxXDeltaPxSlow.Value;
            [app.report.deltaPxSlowN, edges] = histcounts(app.hypnos.trajectory.deltaPx(indNight),edges,'Normalization', 'probability');
            [app.report.deltaPxSlowD, edges] = histcounts(app.hypnos.trajectory.deltaPx(indDay),edges,'Normalization', 'probability');
            [app.report.deltaPxSlowT, edges] = histcounts(app.hypnos.trajectory.deltaPx(slowInd),edges,'Normalization', 'probability');
            app.report.mdDeltaPxSlowN = median(app.hypnos.trajectory.deltaPx(indNight));
            app.report.mdDeltaPxSlowD = median(app.hypnos.trajectory.deltaPx(indDay));
            app.report.mdDeltaPxSlowT = median(app.hypnos.trajectory.deltaPx(slowInd));            
            app.report.mnDeltaPxSlowN = mean(app.hypnos.trajectory.deltaPx(indNight));
            app.report.mnDeltaPxSlowD = mean(app.hypnos.trajectory.deltaPx(indDay));
            app.report.mnDeltaPxSlowT = mean(app.hypnos.trajectory.deltaPx(slowInd));            
            app.report.maxDeltaPxSlowT = max(app.hypnos.trajectory.deltaPx(slowInd));            
            app.report.deltaPxSlowED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
            
            % create the bin edges for the distribution of log deltaPx
            w = (log10(app.maxXDeltaPxSlow.Value)-0.5) / app.deltaPxSlowBins.Value;    % we start from 3 px/sec
            edges = 0.5:w:(log10(app.maxXDeltaPxSlow.Value));
            [app.report.logDeltaPxSlowN, edges] = histcounts(log10(app.hypnos.trajectory.deltaPx(indNight)),edges,'Normalization', 'probability');            
            [app.report.logDeltaPxSlowD, edges] = histcounts(log10(app.hypnos.trajectory.deltaPx(indDay)),edges,'Normalization', 'probability');            
            [app.report.logDeltaPxSlowT, edges] = histcounts(log10(app.hypnos.trajectory.deltaPx(slowInd)),edges,'Normalization', 'probability');            
            app.report.logDeltaPxSlowED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
            
           
        end
        
        function computeDeltaPixHisto(app)
            indNight = findIndNight (app);
            indDay = findIndDay (app);
            % create the bin edges
            w = app.maxXDeltaPx.Value / app.deltaPxBins.Value;
            edges = 0:w:app.maxXDeltaPx.Value;
            [app.report.deltaPxN, edges] = histcounts(app.hypnos.trajectory.deltaPx(indNight),edges,'Normalization', 'probability');
            [app.report.deltaPxD, edges] = histcounts(app.hypnos.trajectory.deltaPx(indDay),edges,'Normalization', 'probability');
            [app.report.deltaPxT, edges] = histcounts(app.hypnos.trajectory.deltaPx,edges,'Normalization', 'probability');
            app.report.mnDeltaPxN = mean(app.hypnos.trajectory.deltaPx(indNight));
            app.report.mnDeltaPxD = mean(app.hypnos.trajectory.deltaPx(indDay));
            app.report.mnDeltaPxT = mean(app.hypnos.trajectory.deltaPx);            
            app.report.mdDeltaPxN = median(app.hypnos.trajectory.deltaPx(indNight));
            app.report.mdDeltaPxD = median(app.hypnos.trajectory.deltaPx(indDay));
            app.report.mdDeltaPxT = median(app.hypnos.trajectory.deltaPx);            
            app.report.maxDeltaPxT = max(app.hypnos.trajectory.deltaPx);            
            app.report.deltaPxED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
            
            % create the bin edges for the distribution of log deltaPx
            w = (log10(app.maxXDeltaPx.Value)-0.5) / app.deltaPxBins.Value;    % we start from 3 px/sec
            edges = 0.5:w:log10(app.maxXDeltaPx.Value);
            [app.report.logDeltaPxN, edges] = histcounts(log10(app.hypnos.trajectory.deltaPx(indNight)),edges,'Normalization', 'probability');            
            [app.report.logDeltaPxD, edges] = histcounts(log10(app.hypnos.trajectory.deltaPx(indDay)),edges,'Normalization', 'probability');            
            [app.report.logDeltaPxT, edges] = histcounts(log10(app.hypnos.trajectory.deltaPx),edges,'Normalization', 'probability');            
            app.report.logDeltaPxED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2; 
            
            
        end
        
        function computeSpeedHisto(app)      % Compute the distribution of the mouse speed
            indNight = findIndNight (app);
            indDay = findIndDay (app);
            % create the bin edges
            w = app.maxXSpeed.Value / app.speedBins.Value;
            edges = 0:w:app.maxXSpeed.Value;
            [app.report.speedN, edges] = histcounts(app.hypnos.trajectory.speed(indNight),edges,'Normalization', 'probability');
            [app.report.speedD, edges] = histcounts(app.hypnos.trajectory.speed(indDay),edges,'Normalization', 'probability');
            [app.report.speedT, edges] = histcounts(app.hypnos.trajectory.speed,edges,'Normalization', 'probability');
            app.report.mnSpeedN = mean(app.hypnos.trajectory.speed(indNight));
            app.report.mnSpeedD = mean(app.hypnos.trajectory.speed(indDay));
            app.report.mnSpeedT = mean(app.hypnos.trajectory.speed);
            app.report.mdSpeedN = median(app.hypnos.trajectory.speed(indNight));
            app.report.mdSpeedD = median(app.hypnos.trajectory.speed(indDay));
            app.report.mdSpeedT = median(app.hypnos.trajectory.speed);
            app.report.maxSpeedT = max(app.hypnos.trajectory.speed);

            app.report.speedED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
            
            % create the bin edges for the distribution of log speed
            w = (log10(app.maxXSpeed.Value)) / app.speedBins.Value;
            edges = 0:w:log10(app.maxXSpeed.Value);
            [app.report.logSpeedN, edges] = histcounts(log10(app.hypnos.trajectory.speed(indNight)),edges,'Normalization', 'probability');            
            [app.report.logSpeedD, edges] = histcounts(log10(app.hypnos.trajectory.speed(indDay)),edges,'Normalization', 'probability');            
            [app.report.logSpeedT, edges] = histcounts(log10(app.hypnos.trajectory.speed),edges,'Normalization', 'probability');            
            app.report.logSpeedED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
        end     
        
        function computeRestingDurationHisto(app)
            % Histo of duration of resting events.
            % Identify the index set of day and night periods. 

            indNight = findIndNight (app);
            indDay = findIndDay (app);
            % Get the intersection between the night period and the resting
            % events that begins (leftI) in this period.
            [~,indN,~] = intersect(app.hypnos.trajectory.resting.leftI,indNight);
            [~,indD,~] = intersect(app.hypnos.trajectory.resting.leftI,indDay);
            
            % create the bin edges
            w = app.maxXResting.Value / app.restingDurationBins.Value;
            edges = 0:w:app.maxXResting.Value;
            [app.report.restN, edges] = histcounts(seconds(app.hypnos.trajectory.resting.duration(indN)),edges,'Normalization', 'probability');
            [app.report.restD, edges] = histcounts(seconds(app.hypnos.trajectory.resting.duration(indD)),edges,'Normalization', 'probability');
            [app.report.restT, edges] = histcounts(seconds(app.hypnos.trajectory.resting.duration),edges,'Normalization', 'probability');
            app.report.mdRestN = median(seconds(app.hypnos.trajectory.resting.duration(indN)));
            app.report.mdRestD = median(seconds(app.hypnos.trajectory.resting.duration(indD)));
            app.report.mdRestT = median(seconds(app.hypnos.trajectory.resting.duration));
            app.report.mnRestN = mean(seconds(app.hypnos.trajectory.resting.duration(indN)));
            app.report.mnRestD = mean(seconds(app.hypnos.trajectory.resting.duration(indD)));
            app.report.mnRestT = mean(seconds(app.hypnos.trajectory.resting.duration));
            app.report.maxRestT = max(seconds(app.hypnos.trajectory.resting.duration));
            app.report.restED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
            
            % create the bin edges for the distribution of log speed
            w = (log10(app.maxXResting.Value)) / app.restingDurationBins.Value;
            edges = 0:w:log10(app.maxXResting.Value);
            [app.report.logRestN, edges] = histcounts(log10(seconds(app.hypnos.trajectory.resting.duration(indN))),edges,'Normalization', 'probability');            
            [app.report.logRestD, edges] = histcounts(log10(seconds(app.hypnos.trajectory.resting.duration(indD))),edges,'Normalization', 'probability');            
            [app.report.logRestT, edges] = histcounts(log10(seconds(app.hypnos.trajectory.resting.duration)),edges,'Normalization', 'probability');            
            app.report.logRestED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
            
%             %smooth
%             SmRestingDuration=seconds(app.hypnos.trajectory.resting.duration);
%             SmRestingDuration=smoothdata(SmRestingDuration,"sgolay");
%             [app.report.SmlogRestN, edges] = histcounts(log10(SmRestingDuration(indN)),edges,'Normalization', 'probability');            
%             [app.report.SmlogRestD, edges] = histcounts(log10(SmRestingDuration(indD)),edges,'Normalization', 'probability');            
%             [app.report.SmlogRestT, edges] = histcounts(log10(SmRestingDuration),edges,'Normalization', 'probability');            
%             
            
        end

        function plotAllHistograms(app)
            % This function acts as a simple container of all the calls to
            % the plot of each histo couple
            plotSpeedHisto (app)
            plotDeltaPixHisto (app)
            plotDeltaPixSlowHisto (app)
            plotRestingDurationHisto (app)
        end
        
        function plotDeltaPixSlowHisto(app)
            if app.distributionOfLogCB.Value
                bar(app.deltaPxSlowDistrNight,app.report.logDeltaPxSlowED,app.report.logDeltaPxSlowN)
                bar(app.deltaPxSlowDistrDay,app.report.logDeltaPxSlowED,app.report.logDeltaPxSlowD)
                bar(app.deltaPxSlowDistrTotal,app.report.logDeltaPxSlowED,app.report.logDeltaPxSlowT)
            else
                bar(app.deltaPxSlowDistrNight,app.report.deltaPxSlowED,app.report.deltaPxSlowN)
                bar(app.deltaPxSlowDistrDay,app.report.deltaPxSlowED,app.report.deltaPxSlowD)
                bar(app.deltaPxSlowDistrTotal,app.report.deltaPxSlowED,app.report.deltaPxSlowT)
            end
            % Write the median on the axis title bar
            app.deltaPxSlowDistrNight.Title.String = sprintf('Delta Px During Rest (Night) %1.1f',app.report.mdDeltaPxSlowN);
            app.deltaPxSlowDistrDay.Title.String = sprintf('Delta Px During Rest (Day) %1.1f',app.report.mdDeltaPxSlowD);
            app.deltaPxSlowDistrTotal.Title.String = sprintf('Total Mean:%1.0f Median:%1.0f Max:%1.0f',...
                app.report.mnDeltaPxSlowT, app.report.mdDeltaPxSlowT,app.report.maxDeltaPxSlowT);
            
            % check for Y log scale
            if app.YlogScaleCB.Value
                app.deltaPxSlowDistrDay.YScale = 'log';
                app.deltaPxSlowDistrNight.YScale = 'log';      
                app.deltaPxSlowDistrTotal.YScale = 'log';   
            else
                app.deltaPxSlowDistrDay.YScale = 'linear';
                app.deltaPxSlowDistrNight.YScale = 'linear';      
                app.deltaPxSlowDistrTotal.YScale = 'linear';   
            end
            if app.autoXCB.Value
                app.deltaPxSlowDistrDay.XLimMode = 'auto';
                app.deltaPxSlowDistrNight.XLimMode = 'auto';      
                app.deltaPxSlowDistrTotal.XLimMode = 'auto';
            else
                app.deltaPxSlowDistrDay.XLimMode = 'manual';
                app.deltaPxSlowDistrNight.XLimMode = 'manual';      
                app.deltaPxSlowDistrTotal.XLimMode = 'manual';
                if app.distributionOfLogCB.Value
                    xleft = 0;
                    xright = log10(app.maxXDeltaPxSlow.Value);
                else
                    xleft = 0;
                    xright = app.maxXDeltaPxSlow.Value;
                end
                app.deltaPxSlowDistrDay.XLim = [xleft,xright];
                app.deltaPxSlowDistrNight.XLim = [xleft,xright];      
                app.deltaPxSlowDistrTotal.XLim = [xleft,xright];
            end
            if app.autoYCB.Value
                app.deltaPxSlowDistrDay.YLimMode = 'auto';
                app.deltaPxSlowDistrNight.YLimMode = 'auto';      
                app.deltaPxSlowDistrTotal.YLimMode = 'auto';
            else
                app.deltaPxSlowDistrDay.YLimMode = 'manual';
                app.deltaPxSlowDistrNight.YLimMode = 'manual';      
                app.deltaPxSlowDistrTotal.YLimMode = 'manual';

                app.deltaPxSlowDistrDay.YLim = [0,app.maxYDeltaPxSlow.Value];
                app.deltaPxSlowDistrNight.YLim = [0,app.maxYDeltaPxSlow.Value];      
                app.deltaPxSlowDistrTotal.YLim = [0,app.maxYDeltaPxSlow.Value];
            end            
            app.deltaPxSlowDistrDay.XTickLabel = [];
            
             %smooth data. 
            app.report.SmlogDeltaPxSlowN=smoothdata(app.report.logDeltaPxSlowN,"sgolay");
            app.report.SmlogDeltaPxSlowD=smoothdata(app.report.logDeltaPxSlowD,"sgolay");
            app.report.SmlogDeltaPxSlowT=smoothdata(app.report.logDeltaPxSlowT,"sgolay");
            
        end
        
        function plotDeltaPixHisto(app)
            if app.distributionOfLogCB.Value
                bar(app.deltaPxDistrNight,app.report.logDeltaPxED,app.report.logDeltaPxN)
                bar(app.deltaPxDistrDay,app.report.logDeltaPxED,app.report.logDeltaPxD)
                bar(app.deltaPxDistrTotal,app.report.logDeltaPxED,app.report.logDeltaPxT)
            else
                bar(app.deltaPxDistrNight,app.report.deltaPxED,app.report.deltaPxN)
                bar(app.deltaPxDistrDay,app.report.deltaPxED,app.report.deltaPxD)
                bar(app.deltaPxDistrTotal,app.report.deltaPxED,app.report.deltaPxT)
            end
            % Write the median on the axis title bar
            app.deltaPxDistrNight.Title.String = sprintf('Delta Px Distribution (Night) %1.1f',app.report.mdDeltaPxN);
            app.deltaPxDistrDay.Title.String = sprintf('Delta Px Distribution (Day) %1.1f',app.report.mdDeltaPxD);
            app.deltaPxDistrTotal.Title.String = sprintf('Total Mean:%1.0f Median:%1.0f Max:%1.0f',...
                app.report.mnDeltaPxT, app.report.mdDeltaPxT,app.report.maxDeltaPxT);
            
            % check for Y log scale
            if app.YlogScaleCB.Value
                app.deltaPxDistrDay.YScale = 'log';
                app.deltaPxDistrNight.YScale = 'log';      
                app.deltaPxDistrTotal.YScale = 'log';   
            else
                app.deltaPxDistrDay.YScale = 'linear';
                app.deltaPxDistrNight.YScale = 'linear';      
                app.deltaPxDistrTotal.YScale = 'linear';   
            end
            if app.autoXCB.Value
                app.deltaPxDistrDay.XLimMode = 'auto';
                app.deltaPxDistrNight.XLimMode = 'auto';      
                app.deltaPxDistrTotal.XLimMode = 'auto';
            else
                app.deltaPxDistrDay.XLimMode = 'manual';
                app.deltaPxDistrNight.XLimMode = 'manual';      
                app.deltaPxDistrTotal.XLimMode = 'manual';
                if app.distributionOfLogCB.Value
                    xleft = 0;
                    xright = log10(app.maxXDeltaPx.Value);
                else
                    xleft = 0;
                    xright = app.maxXDeltaPx.Value;
                end
                app.deltaPxDistrDay.XLim = [xleft,xright];
                app.deltaPxDistrNight.XLim = [xleft,xright];      
                app.deltaPxDistrTotal.XLim = [xleft,xright];
            end
            if app.autoYCB.Value
                app.deltaPxDistrDay.YLimMode = 'auto';
                app.deltaPxDistrNight.YLimMode = 'auto';      
                app.deltaPxDistrTotal.YLimMode = 'auto';
            else
                app.deltaPxDistrDay.YLimMode = 'manual';
                app.deltaPxDistrNight.YLimMode = 'manual';      
                app.deltaPxDistrTotal.YLimMode = 'manual';

                app.deltaPxDistrDay.YLim = [0,app.maxYDeltaPx.Value];
                app.deltaPxDistrNight.YLim = [0,app.maxYDeltaPx.Value];      
                app.deltaPxDistrTotal.YLim = [0,app.maxYDeltaPx.Value];
            end
            app.deltaPxDistrDay.XTickLabel = [];
        end
        
        function plotSpeedHisto(app)
            if app.distributionOfLogCB.Value
                bar(app.speedDistrNight,app.report.logSpeedED,app.report.logSpeedN)
                bar(app.speedDistrDay,app.report.logSpeedED,app.report.logSpeedD)
                bar(app.speedDistrTotal,app.report.logSpeedED,app.report.logSpeedT)
            else
                bar(app.speedDistrNight,app.report.speedED,app.report.speedN)
                bar(app.speedDistrDay,app.report.speedED,app.report.speedD)
                bar(app.speedDistrTotal,app.report.speedED,app.report.speedT)
            end

            % Write the median on the axis title bar
            app.speedDistrNight.Title.String = sprintf('Speed Distribution (Night) mean: %1.2f',app.report.mnSpeedN);
            app.speedDistrDay.Title.String = sprintf('Speed Distribution (Day) mean: %1.2f',app.report.mnSpeedD);
            app.speedDistrTotal.Title.String = sprintf('Total Mean:%1.2f Median:%1.2f Max:%1.0f',...
                app.report.mnSpeedT, app.report.mdSpeedT,app.report.maxSpeedT);

            % check for Y log scale
            if app.YlogScaleCB.Value
                app.speedDistrDay.YScale = 'log';
                app.speedDistrNight.YScale = 'log';      
                app.speedDistrTotal.YScale = 'log';   
            else
                app.speedDistrDay.YScale = 'linear';
                app.speedDistrNight.YScale = 'linear';      
                app.speedDistrTotal.YScale = 'linear';   
            end
            if app.autoXCB.Value
                app.speedDistrDay.XLimMode = 'auto';
                app.speedDistrNight.XLimMode = 'auto';      
                app.speedDistrTotal.XLimMode = 'auto';
            else
                app.speedDistrDay.XLimMode = 'manual';
                app.speedDistrNight.XLimMode = 'manual';      
                app.speedDistrTotal.XLimMode = 'manual';
                if app.distributionOfLogCB.Value
                    xleft = 0;
                    xright = log10(app.maxXSpeed.Value);
                else
                    xleft = 0;
                    xright = app.maxXSpeed.Value;
                end
                app.speedDistrDay.XLim = [xleft,xright];
                app.speedDistrNight.XLim = [xleft,xright];      
                app.speedDistrTotal.XLim = [xleft,xright];
            end
            if app.autoYCB.Value
                app.speedDistrDay.YLimMode = 'auto';
                app.speedDistrNight.YLimMode = 'auto';      
                app.speedDistrTotal.YLimMode = 'auto';
            else
                app.speedDistrDay.YLimMode = 'manual';
                app.speedDistrNight.YLimMode = 'manual';      
                app.speedDistrTotal.YLimMode = 'manual';

                app.speedDistrDay.YLim = [0,app.maxYSpeed.Value];
                app.speedDistrNight.YLim = [0,app.maxYSpeed.Value];      
                app.speedDistrTotal.YLim = [0,app.maxYSpeed.Value];
            end
            app.speedDistrDay.XTickLabel = [];
        end     
        
        function plotRestingDurationHisto(app)
            if app.distributionOfLogCB.Value
                bar(app.restingDurationDistrNight,app.report.logRestED,app.report.logRestN)
                bar(app.restingDurationDistrDay,app.report.logRestED,app.report.logRestD)
                bar(app.restingDurationDistrTotal,app.report.logRestED,app.report.logRestT)
            else
                bar(app.restingDurationDistrNight,app.report.restED,app.report.restN)
                bar(app.restingDurationDistrDay,app.report.restED,app.report.restD)
                bar(app.restingDurationDistrTotal,app.report.restED,app.report.restT)
            end

            % Write the median on the axis title bar
            app.restingDurationDistrNight.Title.String = sprintf('Resting State Duration (Night) %1.2f',app.report.mdRestN);
            app.restingDurationDistrDay.Title.String = sprintf('Resting State Duration (Day) %1.2f',app.report.mdRestD);
            app.restingDurationDistrTotal.Title.String = sprintf('Total Mean:%1.2f Median:%1.2f Max:%1.0f',...
                app.report.mnRestT,app.report.mdRestT,app.report.maxRestT);

            % check for Y log scale
            if app.YlogScaleCB.Value
                app.restingDurationDistrDay.YScale = 'log';
                app.restingDurationDistrNight.YScale = 'log';      
                app.restingDurationDistrTotal.YScale = 'log';   
            else
                app.restingDurationDistrDay.YScale = 'linear';
                app.restingDurationDistrNight.YScale = 'linear';      
                app.restingDurationDistrTotal.YScale = 'linear';   
            end
            if app.autoXCB.Value
                app.restingDurationDistrDay.XLimMode = 'auto';
                app.restingDurationDistrNight.XLimMode = 'auto';      
                app.restingDurationDistrTotal.XLimMode = 'auto';
            else
                app.restingDurationDistrDay.XLimMode = 'manual';
                app.restingDurationDistrNight.XLimMode = 'manual';      
                app.restingDurationDistrTotal.XLimMode = 'manual';
                if app.distributionOfLogCB.Value
                    xleft = 0;
                    xright = log10(app.maxXResting.Value);
                else
                    xleft = 0;
                    xright = app.maxXResting.Value;
                end
                app.restingDurationDistrDay.XLim = [xleft,xright];
                app.restingDurationDistrNight.XLim = [xleft,xright];      
                app.restingDurationDistrTotal.XLim = [xleft,xright];
            end
            if app.autoYCB.Value
                app.restingDurationDistrDay.YLimMode = 'auto';
                app.restingDurationDistrNight.YLimMode = 'auto';      
                app.restingDurationDistrTotal.YLimMode = 'auto';
            else
                app.restingDurationDistrDay.YLimMode = 'manual';
                app.restingDurationDistrNight.YLimMode = 'manual';      
                app.restingDurationDistrTotal.YLimMode = 'manual';

                app.restingDurationDistrDay.YLim = [0,app.maxYResting.Value];
                app.restingDurationDistrNight.YLim = [0,app.maxYResting.Value];      
                app.restingDurationDistrTotal.YLim = [0,app.maxYResting.Value];
            end
            app.restingDurationDistrDay.XTickLabel = [];   
            
        end
                
        function ind = findIndNight(app)
            % This function returns the array ind that contain the indexes
            % of all elements belonging to the selected night-time period
            if app.useEntireDayNightPeriodsCB.Value
                ind = find(app.hypnos.trajectory.environment==0);
            else    
                % Statistics in a specific time window
                % Compute datetime of the beginning and end of each period
                
                % Compose the night interval
                % Find the (datetime) beginning of the interval
                dt1 = app.hypnos.trajectory.t1;             % .t1 is the beginning of the acquisition
                % Now clear the properties Minute and Second of the datime object dt1 and impose the hour of beginning
                dt1.Hour = app.hourNightStart.Value;
                dt1.Minute = 0;
                dt1.Second = 0;
                % If you get lucky, .dt1 is within the recording period but it cannot be excleded that the recording started after it
                if dt1 < app.hypnos.trajectory.t1
                    % the beginning of the night period must be shifted to the next day
                    dt1 = dt1 + days(1);
                end
                % Wonderful, now let's find dt2
                dt2 = dt1 + hours(app.nightDayWidth.Value);
                if dt2 > app.hypnos.trajectory.t2   % Check if the interval ends after the end of the recording
                    % Wrap around from the beginning of the record
                    ind =  find(isbetween(app.hypnos.trajectory.timeArray,dt1,app.hypnos.trajectory.t2));
                    % Now we need to get the missing points from the
                    % beginning of the record
                    firstBit = app.hypnos.trajectory.t2 - dt1;      % this is the duration of the interval we included in ind
                    dt2 = dt2 - days(1);    % The end is wrapped back in time for one day
                    dt1wrap = app.hypnos.trajectory.t2 - days(1);
                    ind = [ind; find(isbetween(app.hypnos.trajectory.timeArray,dt1wrap,dt2))];
                else
                    ind = find(isbetween(app.hypnos.trajectory.timeArray,dt1,dt2));
                end 
            end

        end
        
        function ind = findIndDay(app)
            % This function returns the array ind that contain the indexes
            % of all elements belonging to the selected day-time period
            if app.useEntireDayNightPeriodsCB.Value
                ind = find(app.hypnos.trajectory.environment==1);
            else    
                % Statistics in a specific time window
                % Compute datetime of the beginning and end of each period
                
                % Compose the day interval
                % Find the (datetime) beginning of the interval
                dt1 = app.hypnos.trajectory.t1;             % .t1 is the beginning of the acquisition
                % Now clear the properties Minute and Second of the datime object dt1 and impose the hour of beginning
                dt1.Hour = app.hourDayStart.Value;
                dt1.Minute = 0;
                dt1.Second = 0;
                % If you get lucky, .dt1 is within the recording period but it cannot be excleded that the recording started after it
                if dt1 < app.hypnos.trajectory.t1
                    % the beginning of the day period must be shifted to the next day
                    dt1 = dt1 + days(1);
                end
                % Wonderful, now let's find dt2
                dt2 = dt1 + hours(app.nightDayWidth.Value);
                if dt2 > app.hypnos.trajectory.t2   % Check if the interval ends after the end of the recording
                    % Wrap around from the beginning of the record
                    ind =  find(isbetween(app.hypnos.trajectory.timeArray,dt1,app.hypnos.trajectory.t2));
                    % Now we need to get the missing points from the
                    % beginning of the record
                    firstBit = app.hypnos.trajectory.t2 - dt1;      % this is the duration of the interval we included in ind
                    dt2 = dt2 - days(1);    % The end is wrapped back in time for one day
                    dt1wrap = app.hypnos.trajectory.t2 - days(1);
                    ind = [ind; find(isbetween(app.hypnos.trajectory.timeArray,dt1wrap,dt2))];
                else
                    ind = find(isbetween(app.hypnos.trajectory.timeArray,dt1,dt2));
                end 
            end
        end

        
        function busyHisto(app)
            % Change statistics button colour into red because we are busy
            % working and nobody is allowed to bother us
            app.plotStatsRS.Text = 'Drawing...';
            app.plotStatsRS.BackgroundColor = [1,0,0];
            drawnow
        end
        
        function doneHisto(app)
            app.plotStatsRS.Text = 'Histograms';
            app.plotStatsRS.BackgroundColor = [0.96,0.96,0.96];
            drawnow            
        end
        
        function DensityAllDay(app)
            %app.Position=sqrt((app.hypnos.trajectory.x).^2 + (app.hypnos.trajectory.y).^2);
            
            xi=(0:10:640);
            yi=(0:10:480);
            if app.AllData.Value==1
                xr=interp1(xi,1:numel(xi),app.hypnos.trajectory.x,'nearest');
                yr=interp1(yi,1:numel(yi),app.hypnos.trajectory.y,'nearest');
                %[fun_ALLday x_ALLday]=ksdensity(app.Position);
            end
            if app.RestingData.Value==1
                [slowIndex, ~] = find(app.hypnos.trajectory.speed <= app.hypnos.thrSpeed.Value);
                slowx=app.hypnos.trajectory.x(slowIndex);
                slowy=app.hypnos.trajectory.y(slowIndex);
                %SlowPosition=app.Position(slowIndex);
                xr=interp1(xi,1:numel(xi),slowx,'nearest');
                yr=interp1(yi,1:numel(yi),slowy,'nearest');
                %[fun_ALLday x_ALLday]=ksdensity(SlowPosition);
            end
            if app.NotRestingData.Value==1
                [FastIndex, ~] = find(app.hypnos.trajectory.speed > app.hypnos.thrSpeed.Value);
                FastX=app.hypnos.trajectory.x(FastIndex);
                FastY=app.hypnos.trajectory.y(FastIndex);
                xr=interp1(xi,1:numel(xi),FastX,'nearest');
                yr=interp1(yi,1:numel(yi),FastY,'nearest');
            end
            
            Z=accumarray([xr yr],1, [65 49]);
            Z= Z.*(app.hypnos.trajectory.meanDt);
            Z=log10(Z);
            pcolor(app.DensityALLDAY,Z');
            colormap(app.DensityALLDAY, 'jet');
            xlim(app.DensityALLDAY,[0 64])
            ylim(app.DensityALLDAY, [0 48]);
            %colorbar;
            if app.ManualClimCheckBox.Value==1
                set(app.DensityALLDAY,'CLim',[app.MinCLim.Value app.MaxCLim.Value])
                %caxis(app.DensityALLDAY,);
                if app.ShowColorbar.Value==1
                    colorbar(app.DensityALLDAY);
                else
                    colorbar(app.DensityALLDAY,"Visible","off");
                end
            else
                caxis(app.DensityALLDAY,'auto');
                if app.ShowColorbar.Value==1
                    colorbar(app.DensityALLDAY);
                else
                    colorbar(app.DensityALLDAY,"Visible","off");
                end
            end
           % plot(app.DensityProfileT, x_ALLday,fun_ALLday)
            
        end
        
        function DensityDayNight(app)
            xi=(0:10:640);
            yi=(0:10:480);
            [sunIndex, ~] = find(app.hypnos.trajectory.environment ==1);
            [moonIndex, ~] = find(app.hypnos.trajectory.environment ==0);
            sunx= app.hypnos.trajectory.x(sunIndex);
            suny= app.hypnos.trajectory.y(sunIndex);
            moonx= app.hypnos.trajectory.x(moonIndex);
            moony= app.hypnos.trajectory.y(moonIndex);
            if app.AllData.Value==1
                Dxr=interp1(xi,1:numel(xi),sunx,'nearest');
                Dyr=interp1(yi,1:numel(yi),suny,'nearest');
                Nxr=interp1(xi,1:numel(xi),moonx,'nearest');
                Nyr=interp1(yi,1:numel(yi),moony,'nearest');
                
            end
            if app.RestingData.Value==1
                [slowIndex, ~] = find(app.hypnos.trajectory.speed <= app.hypnos.thrSpeed.Value);
                SlowDayI=intersect(sunIndex,slowIndex);
                Dayslowx=app.hypnos.trajectory.x(SlowDayI);
                Dayslowy=app.hypnos.trajectory.y(SlowDayI);
                Dxr=interp1(xi,1:numel(xi),Dayslowx,'nearest');
                Dyr=interp1(yi,1:numel(yi),Dayslowy,'nearest');
                
                SlowNightI=intersect(moonIndex,slowIndex);
                Nightslowx=app.hypnos.trajectory.x(SlowNightI);
                Nightslowy=app.hypnos.trajectory.y(SlowNightI);
                Nxr=interp1(xi,1:numel(xi),Nightslowx,'nearest');
                Nyr=interp1(yi,1:numel(yi),Nightslowy,'nearest');
                
            end
            
            if app.NotRestingData.Value==1
                [FastIndex, ~] = find(app.hypnos.trajectory.speed > app.hypnos.thrSpeed.Value);
                FastDayI=intersect(sunIndex,FastIndex);
                Dayfastx=app.hypnos.trajectory.x(FastDayI);
                Dayfasty=app.hypnos.trajectory.y(FastDayI);
                Dxr=interp1(xi,1:numel(xi),Dayfastx,'nearest');
                Dyr=interp1(yi,1:numel(yi),Dayfasty,'nearest');
                
                FastNightI=intersect(moonIndex,FastIndex);
                Nightfastx=app.hypnos.trajectory.x(FastNightI);
                Nightfasty=app.hypnos.trajectory.y(FastNightI);
                Nxr=interp1(xi,1:numel(xi),Nightfastx,'nearest');
                Nyr=interp1(yi,1:numel(yi),Nightfasty,'nearest');
                
            end
            
            DZ=accumarray([Dxr Dyr],1, [65 49]); %here we have a matrix 65x49 in which each cell has the number of time in which the mouse has crossed that area
            DZ= DZ.*(app.hypnos.trajectory.meanDt);%convertion into seconds
            %             DZ=minutes(DZ);
            if strcmp(app.TimeSwitch.Value,'Time')
                DZ=log10(DZ);
            else
                DZ=DZ./seconds(app.hypnos.trajectory.duration);
                DZ=log10(DZ);
            end
            
            pcolor(app.DensityDAY, DZ');
            colormap (app.DensityDAY, 'jet');
            xlim(app.DensityDAY,[0 64])
            ylim(app.DensityDAY, [0 48]);
            if app.ShowColorbar.Value==0
                colorbar(app.DensityDAY,"Visible","off");
                colorbar(app.DensityNIGHT,"Visible","off");
            end
            if app.ManualClimCheckBox.Value==1
                caxis(app.DensityDAY,[app.MinCLim.Value app.MaxCLim.Value]);
                colorbar(app.DensityDAY,"Visible","off");
            else
                caxis(app.DensityDAY,'auto');
                if app.ShowColorbar.Value==1
                    colorbar(app.DensityDAY);
                end
            end
            %colorbar(app.DensityDAY);
            
            
            NZ=accumarray([Nxr Nyr],1, [65 49]);
            NZ= NZ.*(app.hypnos.trajectory.meanDt);
            NZ=log10(NZ);
            pcolor(app.DensityNIGHT ,NZ');
            colormap (app.DensityNIGHT, 'jet');
            xlim(app.DensityNIGHT,[0 64])
            ylim(app.DensityNIGHT, [0 48]);
            
            if app.ManualClimCheckBox.Value==1
                caxis(app.DensityNIGHT,[app.MinCLim.Value app.MaxCLim.Value]);
                colorbar(app.DensityNIGHT,"Visible","off");
            else
                caxis(app.DensityNIGHT,'auto');
                if app.ShowColorbar.Value==1
                    colorbar(app.DensityNIGHT);
                end
            end
            %colorbar;
            
            
        end
        
        function SlowDeltaPxAreas(app)
            %this function calculate the area of the two peaks of the Slow
            %DeltaPx distribution
            if ~app.AreaSmooth.Value
                %Fitting during NIGHT NON-smoothed
                app.NightFit=fit((app.report.logDeltaPxSlowED)',(app.report.logDeltaPxSlowN)','gauss2');
                %Fitting during DAY NON-smoothed
                app.DayFit=fit((app.report.logDeltaPxSlowED)',(app.report.logDeltaPxSlowD)','gauss2');
            else
                %Fitting during NIGHT (data smoothed)
                app.NightFit=fit((app.report.logDeltaPxSlowED)',(app.report.SmlogDeltaPxSlowN)','gauss2');
                %Fitting during DAY (data smoothed)
                app.DayFit=fit((app.report.logDeltaPxSlowED)',(app.report.SmlogDeltaPxSlowD)','gauss2');
            end
            %Night Thr and Area Ratio Computation
            minvalueN=fminbnd(app.NightFit,1,2);
            [~,minbinN] = (min(abs(app.report.logDeltaPxSlowED - minvalueN)));
            areaN_1=sum(app.report.logDeltaPxSlowN(1:minbinN));
            areaN_2=sum(app.report.logDeltaPxSlowN(minbinN:end));
            app.report.ratioAN=areaN_1/areaN_2;
            %fprintf(' During the night the ratio between the first peak and the second is %4.3f\n', app.report.ratioAN)
            
            %Day Thr and Area Ratio Computation
            minvalueD=fminbnd(app.DayFit,1,2);
            [~,minbinD] = (min(abs(app.report.logDeltaPxSlowED - minvalueD)));
            areaD_1=sum(app.report.logDeltaPxSlowD(1:minbinD));
            areaD_2=sum(app.report.logDeltaPxSlowD(minbinD:end));
            app.report.ratioAD=areaD_1/areaD_2;
            %fprintf(' During the day the ratio between the first peak and the second is %4.3f\n', app.report.ratioAD)
            
            %The fittings are not shown unless you select this checkbox
            if app.ShowFitCB.Value==1
                SlowDeltaPxFit(app)
            end
        end
        
        function SlowDeltaPxFit(app)
            %Here we show the Night Slow Delta Px Distribution fitting
                %non smoothed fitting
                NonSM_NightFit=fit((app.report.logDeltaPxSlowED)',(app.report.logDeltaPxSlowN)','gauss2');
                NonSM_DayFit=fit((app.report.logDeltaPxSlowED)',(app.report.logDeltaPxSlowD)','gauss2');
                %Smoothed Fitting
                SM_NightFit=fit((app.report.logDeltaPxSlowED)',(app.report.SmlogDeltaPxSlowN)','gauss2');
                SM_DayFit=fit((app.report.logDeltaPxSlowED)',(app.report.SmlogDeltaPxSlowD)','gauss2');
           
                
                if app.AreaSmooth.Value
                    %Night
                    figure
                    bar(app.report.logDeltaPxSlowED,app.report.SmlogDeltaPxSlowN);
                    hold on
                    plot (SM_NightFit)
                    title ('Smoothed Night Slow Delta Px Fit')
                    xlabel ('Fluctuation of Area During Resting')
                    %Day
                    figure
                    bar(app.report.logDeltaPxSlowED,app.report.SmlogDeltaPxSlowD);
                    hold on
                    plot (SM_DayFit)
                    title ('Smoothed Day Slow Delta Px Fit')
                    xlabel ('Fluctuation of Area During Resting')
                    
                else
                    %night
                    figure
                    bar(app.report.logDeltaPxSlowED,app.report.logDeltaPxSlowN);
                    hold on
                    plot (NonSM_NightFit)
                    title ('Night Slow Delta Px Fit')
                    xlabel ('Fluctuation of Area During Resting')
                    %Day
                    figure
                    bar(app.report.logDeltaPxSlowED,app.report.logDeltaPxSlowD);
                    hold on
                    plot (NonSM_DayFit)
                    title ('Day Slow Delta Px Fit')
                    xlabel ('Fluctuation of Area During Resting')
                end
           
        end
        
        function RestingFit(app)
            if ~app.ManualRestFitCB.Value
                Rest_Tot=log10(seconds(app.hypnos.trajectory.resting.duration));
                [app.funfit x]=ksdensity(Rest_Tot,app.report.logRestED);
                min_rest=islocalmin(app.funfit);
                min_idx=find(min_rest==1);
                %%It is possible that sometimes no minimum is detected, in
                %%that case the value of Thr is read by the edit window of
                %%the manual mode (set at 20 seconds= 1.3)
                if isempty(min_idx)==1
                    app.report.RestThr=app.LogThr.Value;
                    %RestThrDur=10.^(app.report.RestThr);
                    %fprintf ('Hypothetic Sleep Thr could be %4.3f\n', RestThrDur)
                    f = warndlg('No local minima. The threeshold value is taken from manual mode window. If you want to change it choose manual mode and refresh');
                    app.AllDayRestingFitLamp.Color = [1,1,0];
                else
                    app.report.RestThr=app.report.logRestED(min_idx);
                    %RestThrDur=10.^(app.report.RestThr);
                    %fprintf ('Hypothetic Sleep Thr could be %4.3f\n', RestThrDur)
                    app.AllDayRestingFitLamp.Color = [0,1,0];
                end
                %it could happen that the function spot more than one
                % local minimum, in that case the function will mediate the
                % two suggesting you to change into manual
                if length(min_idx)>1
                    app.report.RestThr= mean(app.report.RestThr);
                    f = warndlg('Two local minima. The threeshold value is calculated by mediating the local minima values. Please choose manual mode and refresh');
                    app.AllDayRestingFitLamp.Color = [1,1,0];
                end
            else
                app.report.RestThr=app.LogThr.Value;
                %RestThrDur=10.^(app.report.RestThr);
                %fprintf ('Hypothetic Sleep Thr could be %4.3f\n', RestThrDur)
                app.AllDayRestingFitLamp.Color = [0,1,0];
                
            end
            % The thr value is written in an Edit Field near the fit button
            AlldayThr_txt=10^(app.report.RestThr);
            AlldayThr_txt=num2str(AlldayThr_txt);
            app.AllDayRestThr.Value=AlldayThr_txt;
            
            %% Area Ratio All Day
            [~,RestMinIdx] = (min(abs(app.report.logRestED - app.report.RestThr )));
            areaT_1=sum(app.report.logRestT(1:RestMinIdx));
            areaT_2=sum(app.report.logRestT(RestMinIdx:end));
            app.report.RatioRestT=areaT_1/areaT_2;
            
            if app.ShowFitRestCB.Value
                PlotRestFit(app);
            end
            
            
        end
        
        function PlotRestFit(app)
            %all day Resting Duration Fit Plot
            
            figure
            if ~app.ManualRestFitCB.Value
                FitRest=app.funfit./10;
                plot(app.report.logRestED,FitRest)
                hold on
                bar(app.report.logRestED,app.report.logRestT)
            else
                bar(app.report.logRestED,app.report.logRestT)
                hold on
                xline(app.report.RestThr);
            end
            
            
%             ExpNorm=fittype(@(a,b,a2,b2,c2,x) a*exp(b*x)+a2.*exp(-((x-b2)./c2).^2));
%             if app.RestSmooth.Value==0
%                 bar(app.report.logRestED,app.report.logRestT);
%                 FitNOSm=fit(app.report.logRestED',app.report.logRestT', ExpNorm, 'StartPoint', [0,0.5,0.5,2,0.5]);
%                 hold on
%                 plot(FitNOSm)
%             else
%                 SmoothRest=smoothdata(app.report.logRestT,"sgolay");
%                 bar(app.report.logRestED,SmoothRest);
%                 FitSM=fit(app.report.logRestED',SmoothRest', ExpNorm, 'StartPoint', [0,0.5,0.5,2,0.5]);
%                 hold on
%                 plot (FitSM)
%             end
%             
            title ('ALL DAY Resting Duration Distribution Fit')
            xlabel ('Resting Duration (s)')
            
            %Rest Fit Plot During Day
            figure
            if ~app.ManualRestFitD.Value
                FitRestD=app.funfitD./10;
                plot(app.report.logRestED,FitRestD)
                hold on
                bar(app.report.logRestED,app.report.logRestD)
            else
                bar(app.report.logRestED,app.report.logRestD)
                hold on
                xline(app.report.RestThrD);
            end
            title ('DAY Resting Duration Distribution Fit')
            xlabel ('Resting Duration (s)')
            
             %Rest Fit Plot During Night
            figure
            if ~app.ManualRestFitN.Value
                FitRestN=app.funfitN./10;
                plot(app.report.logRestED,FitRestN)
                hold on
                bar(app.report.logRestED,app.report.logRestN)
            else
                bar(app.report.logRestED,app.report.logRestN)
                hold on
                xline(app.report.RestThrN);
            end
            title ('NIGHT Resting Duration Distribution Fit')
            xlabel ('Resting Duration (s)')
            
        end
        
        function  GrEx_Coupling(app)
            %Cross correlation between grooming and exploratory fraction of
            %time during night and day.
            
            figure
            [CCday DayLag]=xcorr(app.Expl_D,app.Groom_D);
            DayLag=DayLag.*app.binWidth.Value;
            plot(DayLag, CCday)
            title('XCorr Grooming and Exploratory behaviour during Day')
            xlabel('lag (min)')
            
            
            figure
            [CCnight NightLag]=xcorr(app.Expl_N,app.Groom_N);
            NightLag=NightLag.*app.binWidth.Value;
            plot(NightLag, CCnight)
            title('XCorr Grooming and Exploratory behaviour during Night')
            xlabel('lag (min)')
            
        end
        
        function SleepAnalysis(app)
            %% The day events are those that BEGIN before 7PM, accordigly night events are those that start before 7AM.
            DayIdx=find(app.hypnos.trajectory.environment==1);
            NightIdx=find(app.hypnos.trajectory.environment==0);
            [~,SleepIdx_D,~] = intersect(app.hypnos.trajectory.sleep.leftI,DayIdx);
            [~, SleepIdx_N, ~]=intersect(app.hypnos.trajectory.sleep.leftI,NightIdx);
            %Number of Sleep Events During Day, Night, All Day
            app.report.SleepEvents_AllDay= app.hypnos.trajectory.sleep.n;
            app.report.SleepEvents_Night=length(SleepIdx_N);
            app.report.SleepEvents_Day=length(SleepIdx_D);
            %Mean Sleep Duration
            SleepyDay=app.hypnos.trajectory.sleep.duration(SleepIdx_D);
            SleepyNight=app.hypnos.trajectory.sleep.duration(SleepIdx_N);
            app.report.mnsleepdurationD=mean(SleepyDay);
            app.report.mnsleepdurationN=mean(SleepyNight);
            app.report.mnsleepdurationT=mean(app.hypnos.trajectory.sleep.duration);
            % Median Sleep Duration
            app.report.mdsleepdurationD=median(SleepyDay);
            app.report.mdsleepdurationN=median(SleepyNight);
            app.report.mdsleepdurationT=median(app.hypnos.trajectory.sleep.duration);
            %Longest sleep events
            app.report.maxsleepdurationD=max(SleepyDay);
            app.report.maxsleepdurationN=max(SleepyNight);
            app.report.maxsleepdurationT=max(app.hypnos.trajectory.sleep.duration);
            %Amount of time spent sleeping 
            app.report.SleepyTimeD=sum(SleepyDay);
            app.report.SleepyTimeN=sum(SleepyNight);
            app.report.SleepyTimeT=sum(app.hypnos.trajectory.sleep.duration);
            
           
        end
        
        function  DensityProfile(app)
            cla(app.DensityProfileT);
            cla(app.DensityProfileD);
            cla(app.DensityProfileN);
            app.GOButton.Text = 'Computing';
            app.GOButton.BackgroundColor = [1,0,0];
            drawnow
            
            %all day density profile
            [sunIndex, col] = find(app.hypnos.trajectory.environment ==1);
            [moonIndex, col] = find(app.hypnos.trajectory.environment ==0);
            
            if app.AllData.Value==1
                Distance_MatrixT=app.hypnos.trajectory.distance;
                State_MatrixT=app.hypnos.trajectory.state;
                Distance_MatrixD=app.hypnos.trajectory.distance(sunIndex);
                State_MatrixD=app.hypnos.trajectory.state(sunIndex);
                Distance_MatrixN=app.hypnos.trajectory.distance(moonIndex);
                State_MatrixN=app.hypnos.trajectory.state(moonIndex);
            end
            if app.RestingData.Value==1
                [slowIndex, ~] = find(app.hypnos.trajectory.speed <= app.hypnos.thrSpeed.Value);
                Distance_MatrixT= app.hypnos.trajectory.distance(slowIndex);
                State_MatrixT=app.hypnos.trajectory.state(slowIndex);
                
                %DAY DATA
                SlowDayI=intersect(sunIndex,slowIndex);
                Distance_MatrixD=app.hypnos.trajectory.distance(SlowDayI);
                State_MatrixD=app.hypnos.trajectory.state(SlowDayI);
                
                %NIGHT DATA
                SlowNightI=intersect(moonIndex,slowIndex);
                Distance_MatrixN=app.hypnos.trajectory.distance(SlowNightI);
                State_MatrixN=app.hypnos.trajectory.state(SlowNightI);
                
            end
            if app.NotRestingData.Value==1
                [FastIndex, ~] = find(app.hypnos.trajectory.speed > app.hypnos.thrSpeed.Value);
                Distance_MatrixT= app.hypnos.trajectory.distance(FastIndex);
                State_MatrixT=app.hypnos.trajectory.state(FastIndex);
                
                %DAY DATA
                FastDayI=intersect(sunIndex,FastIndex);
                Distance_MatrixD= app.hypnos.trajectory.distance(FastDayI);
                State_MatrixD=app.hypnos.trajectory.state(FastDayI);
                
                %NIGHT DATA
                FastNightI=intersect(moonIndex,FastIndex);
                Distance_MatrixN= app.hypnos.trajectory.distance(FastNightI);
                State_MatrixN=app.hypnos.trajectory.state(FastNightI);
                
            end
            if strcmp(app.FunHisto.Value, 'Function')
                [app.report.fun_ALLday, app.report.x_ALLday]=ksdensity(Distance_MatrixT);
                [app.report.fun_DAY, app.report.x_DAY]=ksdensity(Distance_MatrixD);
                [app.report.fun_NIGHT, app.report.x_NIGHT]=ksdensity(Distance_MatrixN);
                
                plot(app.DensityProfileT, app.report.x_ALLday, app.report.fun_ALLday)
                plot(app.DensityProfileD, app.report.x_DAY, app.report.fun_DAY)
                plot(app.DensityProfileN, app.report.x_NIGHT, app.report.fun_NIGHT)
                if ~ app.ManualAxisDP.Value
                    xlim(app.DensityProfileT, [min(app.report.x_ALLday), max(app.report.x_ALLday)]);
                    ylim(app.DensityProfileT, [min(app.report.fun_ALLday), max(app.report.fun_ALLday)]);
                    xlim(app.DensityProfileD, [min(app.report.x_DAY), max(app.report.x_DAY)]);
                    ylim(app.DensityProfileD, [min(app.report.fun_DAY), max(app.report.fun_DAY)]);
                    xlim(app.DensityProfileN, [min(app.report.x_NIGHT), max(app.report.x_NIGHT)]);
                    ylim(app.DensityProfileN, [min(app.report.fun_NIGHT), max(app.report.fun_NIGHT)]);
                else
                    xlim(app.DensityProfileT, [min(app.report.x_ALLday), app.MaxX_DP.Value]);
                    ylim(app.DensityProfileT, [min(app.report.fun_ALLday), app.MaxY_DP.Value]);
                    xlim(app.DensityProfileD, [min(app.report.x_DAY), app.MaxX_DP.Value]);
                    ylim(app.DensityProfileD, [min(app.report.fun_DAY), app.MaxY_DP.Value]);
                    xlim(app.DensityProfileN, [min(app.report.x_NIGHT), app.MaxX_DP.Value]);
                    ylim(app.DensityProfileN, [min(app.report.fun_NIGHT), app.MaxY_DP.Value]);
                end
            else
                
                Bin_w = (max(Distance_MatrixT)- min(Distance_MatrixT)) / app.BinDensityProf.Value;
                edges = (min(Distance_MatrixT)):Bin_w:max(Distance_MatrixT);
                [app.report.DensProfT, edges] = histcounts(Distance_MatrixT,edges,'Normalization', 'probability');
                [app.report.DensProfD, edges] = histcounts(Distance_MatrixD,edges,'Normalization', 'probability');
                [app.report.DensProfN, edges] = histcounts(Distance_MatrixN,edges,'Normalization', 'probability');
                app.report.DensProfED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
                
                %we are parsing the bins according to the state in which the
                %mouse is in order to assign them a specific colour code
                                
                for i=1:app.BinDensityProf.Value
                    st=num2str(i);
                    str_tempMat=['Temp_MatT= find(Distance_MatrixT< edges(' st '+1)& Distance_MatrixT>= edges(' st '));'];
                    str_tempMatD=['Temp_MatD= find(Distance_MatrixD< edges(' st '+1)& Distance_MatrixD>= edges(' st '));'];
                    str_tempMatN=['Temp_MatN= find(Distance_MatrixN< edges(' st '+1)& Distance_MatrixN>= edges(' st '));'];
                    eval(str_tempMat);
                    eval (str_tempMatD);
                    eval(str_tempMatN);
                    str_mode=['Temp_MatT= mode(State_MatrixT(Temp_MatT));'];
                    str_modeD=['Temp_MatD= mode(State_MatrixD(Temp_MatD));'];
                    str_modeN=['Temp_MatN= mode(State_MatrixN(Temp_MatN));'];
                    eval(str_mode);
                    eval(str_modeD)
                    eval(str_modeN);
                    
                    final_str=['StatusT(' st ',1)=Temp_MatT;'];
                    eval(final_str);
                    final_strD=['StatusD(' st ',1)=Temp_MatD;'];
                    eval(final_strD);
                    final_strN=['StatusN(' st ',1)=Temp_MatN;'];
                    eval(final_strN);
                    
                end
                %find the edges indeces
                %this for loop evaluate the median value
                % (= the more represented between 1-expl 2-groom 3-rest 4-sleep)
                %of the state matrix taken in the chosen time window  for each
                %bin
                
                Expl_IdxD= find(StatusD==1);
                Groom_IdxD= find(StatusD==2);
                Rest_IdxD= find(StatusD==3);
                Sleep_IdxD= find(StatusD==4);
                ExplMatrixD=zeros(app.BinDensityProf.Value,1);
                GroomMatrixD=zeros(app.BinDensityProf.Value,1);
                RestMatrixD=zeros(app.BinDensityProf.Value,1);
                SleepMatrixD=zeros(app.BinDensityProf.Value,1);
                ExplMatrixD(Expl_IdxD,1)=app.report.DensProfD(Expl_IdxD);
                GroomMatrixD(Groom_IdxD,1)=app.report.DensProfD(Groom_IdxD);
                RestMatrixD(Rest_IdxD,1)=app.report.DensProfD(Rest_IdxD);
                SleepMatrixD(Sleep_IdxD,1)=app.report.DensProfD(Sleep_IdxD);
                
                
                %[app.report.DensProf_TW, edges] = histcounts(TimeWindow_Distance,edges,'Normalization', 'probability');
                %app.report.DensProf_TWED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
                bar(app.DensityProfileD,app.report.DensProfED,ExplMatrixD,'b')
                hold(app.DensityProfileD,'on');
                bar(app.DensityProfileD,app.report.DensProfED,GroomMatrixD,'r')
                bar(app.DensityProfileD,app.report.DensProfED,RestMatrixD,'g')
                bar(app.DensityProfileD,app.report.DensProfED,SleepMatrixD,'k')
                hold(app.DensityProfileD,'off');
                
                Expl_IdxN= find(StatusN==1);
                Groom_IdxN= find(StatusN==2);
                Rest_IdxN= find(StatusN==3);
                Sleep_IdxN= find(StatusN==4);
                ExplMatrixN=zeros(app.BinDensityProf.Value,1);
                GroomMatrixN=zeros(app.BinDensityProf.Value,1);
                RestMatrixN=zeros(app.BinDensityProf.Value,1);
                SleepMatrixN=zeros(app.BinDensityProf.Value,1);
                ExplMatrixN(Expl_IdxN,1)=app.report.DensProfN(Expl_IdxN);
                GroomMatrixN(Groom_IdxN,1)=app.report.DensProfN(Groom_IdxN);
                RestMatrixN(Rest_IdxN,1)=app.report.DensProfN(Rest_IdxN);
                SleepMatrixN(Sleep_IdxN,1)=app.report.DensProfN(Sleep_IdxN);
                
                
                %[app.report.DensProf_TW, edges] = histcounts(TimeWindow_Distance,edges,'Normalization', 'probability');
                %app.report.DensProf_TWED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
                bar(app.DensityProfileN,app.report.DensProfED,ExplMatrixN,'b')
                hold(app.DensityProfileN,'on');
                bar(app.DensityProfileN,app.report.DensProfED,GroomMatrixN,'r')
                bar(app.DensityProfileN,app.report.DensProfED,RestMatrixN,'g')
                bar(app.DensityProfileN,app.report.DensProfED,SleepMatrixN,'k')
                hold(app.DensityProfileN,'off');
                
                
                Expl_IdxT= find(StatusT==1);
                Groom_IdxT= find(StatusT==2);
                Rest_IdxT= find(StatusT==3);
                Sleep_IdxT= find(StatusT==4);
                ExplMatrixT=zeros(app.BinDensityProf.Value,1);
                GroomMatrixT=zeros(app.BinDensityProf.Value,1);
                RestMatrixT=zeros(app.BinDensityProf.Value,1);
                SleepMatrixT=zeros(app.BinDensityProf.Value,1);
                ExplMatrixT(Expl_IdxT,1)=app.report.DensProfT(Expl_IdxT);
                GroomMatrixT(Groom_IdxT,1)=app.report.DensProfT(Groom_IdxT);
                RestMatrixT(Rest_IdxT,1)=app.report.DensProfT(Rest_IdxT);
                SleepMatrixT(Sleep_IdxT,1)=app.report.DensProfT(Sleep_IdxT);
                
                
                %[app.report.DensProf_TW, edges] = histcounts(TimeWindow_Distance,edges,'Normalization', 'probability');
                %app.report.DensProf_TWED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
                bar(app.DensityProfileT,app.report.DensProfED,ExplMatrixT,'b')
                hold(app.DensityProfileT,'on');
                bar(app.DensityProfileT,app.report.DensProfED,GroomMatrixT,'r')
                bar(app.DensityProfileT,app.report.DensProfED,RestMatrixT,'g')
                bar(app.DensityProfileT,app.report.DensProfED,SleepMatrixT,'k')
                hold(app.DensityProfileT,'off');
                
                
                %bar(app.DensityProfileT,app.report.DensProfED,app.report.DensProfT)
                %bar(app.DensityProfileD,app.report.DensProfED,app.report.DensProfD);
                %bar(app.DensityProfileN,app.report.DensProfED,app.report.DensProfN);
                
                if ~ app.ManualAxisDP.Value
                    xlim(app.DensityProfileT, [(min(app.report.DensProfED)-1), max(app.report.DensProfED)]);
                    ylim(app.DensityProfileT, [min(app.report.DensProfT), max(app.report.DensProfT)]);
                    xlim(app.DensityProfileD, [(min(app.report.DensProfED)-1), max(app.report.DensProfED)]);
                    ylim(app.DensityProfileD, [min(app.report.DensProfD), max(app.report.DensProfD)]);
                    xlim(app.DensityProfileN, [(min(app.report.DensProfED)-1), max(app.report.DensProfED)]);
                    ylim(app.DensityProfileN, [min(app.report.DensProfN), max(app.report.DensProfN)]);
                else
                    xlim(app.DensityProfileT, [(min(app.report.DensProfED)-1), app.MaxX_DP.Value]);
                    ylim(app.DensityProfileT, [min(app.report.DensProfT), app.MaxY_DP.Value]);
                    xlim(app.DensityProfileD, [(min(app.report.DensProfED)-1), app.MaxX_DP.Value]);
                    ylim(app.DensityProfileD, [min(app.report.DensProfD), app.MaxY_DP.Value]);
                    xlim(app.DensityProfileN, [(min(app.report.DensProfED)-1), app.MaxX_DP.Value]);
                    ylim(app.DensityProfileN, [min(app.report.DensProfN), app.MaxY_DP.Value]);
                end
            end
            app.GOButton.Text = 'GO!';
            app.GOButton.BackgroundColor = [0.95,0.95,0.95];
            drawnow


        end
        
        function RestingFitDay(app)
            IndDay=find(app.hypnos.trajectory.environment==1);
            [~,indD,~] = intersect(app.hypnos.trajectory.resting.leftI,IndDay);
            
            if ~app.ManualRestFitD.Value
                Rest_Day=log10(seconds(app.hypnos.trajectory.resting.duration(indD)));
                [app.funfitD x]=ksdensity(Rest_Day,app.report.logRestED);
                min_rest=islocalmin(app.funfitD);
                min_idx=find(min_rest==1);
                %%It is possible that sometimes no minimum is detected, in
                %%that case the value of Thr is read by the edit window of
                %%the manual mode (set at 20 seconds= 1.3)
                if isempty(min_idx)==1
                    app.report.RestThrD=app.LogThr_D.Value;
                    %                     RestThrDur=10.^(app.report.RestThrD);
                    %                     fprintf ('Hypothetic Day Sleep Thr could be %4.3f\n', RestThrDur)
                    f = warndlg('No Day local minima. The threeshold value is taken from manual mode window. If you want to change it choose manual mode and refresh');
                    app.DayRestingFitLamp.Color = [1,1,0];
                else
                    app.report.RestThrD=app.report.logRestED(min_idx);
                    %                     RestThrDur=10.^(app.report.RestThrD);
                    %                     fprintf ('Hypothetic Day Sleep Thr could be %4.3f\n', RestThrDur)
                    app.DayRestingFitLamp.Color = [0,1,0];
                end
                %it could happen that the function spot more than one
                % local minimum, in that case the function will mediate the
                % two suggesting you to change into manual
                if length(min_idx)>1
                    app.report.RestThrD= mean(app.report.RestThrD);
                    f = warndlg('Two Day local minima. The threeshold value is calculated by mediating the local minima values. Please choose manual mode and refresh');
                    app.DayRestingFitLamp.Color = [1,1,0];
                end
            else
                app.report.RestThrD=app.LogThr_D.Value;
                %                 RestThrDur=10.^(app.report.RestThrD);
                %                 fprintf ('Hypothetic Day Sleep Thr could be %4.3f\n', RestThrDur)
                app.DayRestingFitLamp.Color = [0,1,0];
                
            end
            % The thr value is written in an Edit Field near the fit button
            DayThr_txt=10^(app.report.RestThrD);
            DayThr_txt=num2str(DayThr_txt);
            app.DayRestThr.Value=DayThr_txt;
            
            %% Area Ratio During Day
            [~,RestMinIdxD] = (min(abs(app.report.logRestED - app.report.RestThrD )));
            areaD_1=sum(app.report.logRestD(1:RestMinIdxD));
            areaD_2=sum(app.report.logRestD(RestMinIdxD:end));
            app.report.RatioRestD= areaD_1/areaD_2;
            
        end
        
        function  RestingFitNight(app)
            IndNight=find(app.hypnos.trajectory.environment==0);
            [~,indN,~] = intersect(app.hypnos.trajectory.resting.leftI,IndNight);
            
            if ~app.ManualRestFitN.Value
                Rest_Night=log10(seconds(app.hypnos.trajectory.resting.duration(indN)));
                [app.funfitN x]=ksdensity(Rest_Night,app.report.logRestED);
                min_rest=islocalmin(app.funfitN);
                min_idx=find(min_rest==1);
                %%It is possible that sometimes no minimum is detected, in
                %%that case the value of Thr is read by the edit window of
                %%the manual mode (set at 20 seconds= 1.3)
                if isempty(min_idx)==1
                    app.report.RestThrN=app.LogThr_N.Value;
                    %                     RestThrDur=10.^(app.report.RestThrN);
                    %                     fprintf ('Hypothetic Night Sleep Thr could be %4.3f\n', RestThrDur)
                    f = warndlg('No Night local minima. The threeshold value is taken from manual mode window. If you want to change it choose manual mode and refresh');
                    app.NightRestingFitLamp.Color = [1,1,0];
                else
                    app.report.RestThrN=app.report.logRestED(min_idx);
                    %                     RestThrDur=10.^(app.report.RestThrN);
                    %                     fprintf ('Hypothetic Night Sleep Thr could be %4.3f\n', RestThrDur)
                    app.NightRestingFitLamp.Color = [0,1,0];
                end
                %it could happen that the function spot more than one
                % local minimum, in that case the function will mediate the
                % two suggesting you to change into manual
                if length(min_idx)>1
                    app.report.RestThrN= mean(app.report.RestThrN);
                    f = warndlg('Two Night local minima. The threeshold value is calculated by mediating the local minima values. Please choose manual mode and refresh');
                    app.NightRestingFitLamp.Color = [1,1,0];
                end
            else
                app.report.RestThrN=app.LogThr_N.Value;
                %                 RestThrDur=10.^(app.report.RestThrN);
                %                 fprintf ('Hypothetic Night Sleep Thr could be %4.3f\n', RestThrDur)
                app.NightRestingFitLamp.Color = [0,1,0];
                
            end
            % The thr value is written in an Edit Field near the fit button
            NightThr_txt=10^(app.report.RestThrN);
            NightThr_txt=num2str(NightThr_txt);
            app.NightRestThr.Value=NightThr_txt;
            
            %% Area Ratio During Night
            [~,RestMinIdxN] = (min(abs(app.report.logRestED - app.report.RestThrN )));
            areaN_1=sum(app.report.logRestN(1:RestMinIdxN));
            areaN_2=sum(app.report.logRestN(RestMinIdxN:end));
            app.report.RatioRestN=areaN_1/areaN_2;
            
    end
    
    function StartnFinalValues(app)
            %Define starting value
        if app.Start.Value==1
            app.FirstValue=app.hypnos.trajectory.timeArray(1);
        else
            app.FirstValue=app.FromTW.Value;
            app.FirstValue=str2double(app.FirstValue);
            idxFV=find(app.FirstValue == hour(app.hypnos.trajectory.timeArray));
            app.FirstValue=app.hypnos.trajectory.timeArray(idxFV(1));
        end
        %Define Final Value
        if app.AddHoursTW.Value==1
            app.FinalValue= app.FirstValue + hours(app.hoursTW.Value);
        else
            app.FinalValue=str2double(app.ToTW.Value);
            idxFV2=find(app.FinalValue == hour(app.hypnos.trajectory.timeArray));
            app.FinalValue=app.hypnos.trajectory.timeArray(idxFV2(1));
        end
        
        end
    
    function  TimeWindowDensity(app)
        xi=(0:10:640);
        yi=(0:10:480);
        StartnFinalValues(app)
        
        Idx_TW=find(app.hypnos.trajectory.timeArray>=app.FirstValue& app.hypnos.trajectory.timeArray< app.FinalValue);
        X_TW=app.hypnos.trajectory.x(Idx_TW);
        Y_TW=app.hypnos.trajectory.y(Idx_TW);
        TW_xr=interp1(xi,1:numel(xi),X_TW,'nearest');
        TW_yr=interp1(yi,1:numel(yi),Y_TW,'nearest');
        
        TW_Z=accumarray([TW_xr TW_yr],1, [65 49]); %here we have a matrix 65x49 in which each cell has the number of time in which the mouse has crossed that area
        TW_Z= TW_Z.*(app.hypnos.trajectory.meanDt);%convertion into seconds
        if strcmp(app.TimeSwitch.Value,'Time')
            TW_Z=log10(TW_Z);
        else
            TW_Z=TW_Z./seconds(app.hypnos.trajectory.duration);
            TW_Z=log10(TW_Z);
        end
        
        pcolor(app.TW_Density, TW_Z');
        colormap (app.TW_Density, 'jet');
        xlim(app.TW_Density,[0 64])
        ylim(app.TW_Density, [0 48]);
        if app.ShowColorbarTW.Value==0
            colorbar(app.TW_Density,"Visible","off");
            colorbar(app.TW_Density,"Visible","off");
        end
        if app.ManualClimTW.Value==1
            caxis(app.TW_Density,[app.MinCLimTW.Value app.MaxCLimTW.Value]);
            if app.ShowColorbarTW.Value==1
                colorbar(app.TW_Density);
            end
        else
            caxis(app.TW_Density,'auto');
            if app.ShowColorbarTW.Value==1
                colorbar(app.TW_Density);
            end
        end
        
        
    end


    function TimeWindowDensityProfile(app)
        cla(app.DensityProfileTW);
        app.GOButtonTW.Text = 'Computing';
        app.GOButtonTW.BackgroundColor = [1,0,0];
        drawnow
        
        Idx_TW=find(app.hypnos.trajectory.timeArray>=app.FirstValue& app.hypnos.trajectory.timeArray< app.FinalValue);
        
        if strcmp(app.FunHisto_TW.Value, 'Function')
            [app.report.fun_TW, app.report.x_TW]=ksdensity(app.hypnos.trajectory.distance(Idx_TW));
            plot(app.DensityProfileTW,app.report.x_TW,app.report.fun_TW)
            if ~ app.ManualAxisDP_TW.Value
                xlim(app.DensityProfileTW, [min(app.report.x_TW), max(app.report.x_TW)]);
                ylim(app.DensityProfileTW, [min(app.report.fun_TW), max(app.report.fun_TW)]);
            else
                xlim(app.DensityProfileTW, [min(app.report.x_TW), app.MaxX_DP_TW.Value]);
                ylim(app.DensityProfileTW, [min(app.report.fun_TW), app.MaxY_DP_TW.Value]);
            end
        else
            TimeWindow_Distance=app.hypnos.trajectory.distance(Idx_TW);
            TimeWindow_State=app.hypnos.trajectory.state(Idx_TW);
            Bin_w = (max(TimeWindow_Distance)- min(TimeWindow_Distance)) / app.BinDensityProf_TW.Value;
            edges = (min(TimeWindow_Distance)):Bin_w:max(TimeWindow_Distance);
            [app.report.DensProf_TW, edges] = histcounts(TimeWindow_Distance,edges,'Normalization', 'probability');
            app.report.DensProf_TWED = (edges(1:length(edges)-1) + edges(2:length(edges)))/2;
            
            %we are parsing the bins according to the state in which the
            %mouse is in order to assign them a specific colour code
            
            for i=1:app.BinDensityProf_TW.Value
                st=num2str(i);
                str_tempMat=['Temp_Mat= find(TimeWindow_Distance< edges(' st '+1)& TimeWindow_Distance>= edges(' st '));'];
                eval(str_tempMat);
                str_mode=[ 'Temp_Mat= mode(TimeWindow_State(Temp_Mat));'];
                eval(str_mode);
                final_str=['StatusTW(' st ',1)=Temp_Mat;'];
                eval(final_str);
            end
            
            Expl_Idx= find(StatusTW==1);
            Groom_Idx= find(StatusTW==2);
            Rest_Idx= find(StatusTW==3);
            Sleep_Idx= find(StatusTW==4);
            ExplMatrix=zeros(app.BinDensityProf_TW.Value,1);
            GroomMatrix=zeros(app.BinDensityProf_TW.Value,1);
            RestMatrix=zeros(app.BinDensityProf_TW.Value,1);
            SleepMatrix=zeros(app.BinDensityProf_TW.Value,1);
            ExplMatrix(Expl_Idx,1)=app.report.DensProf_TW(Expl_Idx);
            GroomMatrix(Groom_Idx,1)=app.report.DensProf_TW(Groom_Idx);
            RestMatrix(Rest_Idx,1)=app.report.DensProf_TW(Rest_Idx);
            SleepMatrix(Sleep_Idx,1)=app.report.DensProf_TW(Sleep_Idx);
            
            
            bar(app.DensityProfileTW,app.report.DensProf_TWED,ExplMatrix,'b')
            hold(app.DensityProfileTW,'on');
            bar(app.DensityProfileTW,app.report.DensProf_TWED,GroomMatrix,'r')
            bar(app.DensityProfileTW,app.report.DensProf_TWED,RestMatrix,'g')
            bar(app.DensityProfileTW,app.report.DensProf_TWED,SleepMatrix,'k')
            hold(app.DensityProfileTW,'off');
            if ~ app.ManualAxisDP_TW.Value
                xlim(app.DensityProfileTW, [min(app.report.DensProf_TWED), max(app.report.DensProf_TWED)]);
                ylim(app.DensityProfileTW, [min(app.report.DensProf_TW), max(app.report.DensProf_TW)]);
            else
                xlim(app.DensityProfileTW, [min(app.report.DensProf_TWED), app.MaxX_DP_TW.Value]);
                ylim(app.DensityProfileTW, [min(app.report.DensProf_TW), app.MaxY_DP_TW.Value]);
            end
        end
        app.GOButtonTW.Text = 'GO!';
        app.GOButtonTW.BackgroundColor = [0.96,0.96,0.96];
        drawnow
        
    end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, source)
            % First, transfer to the report app the Hypnos app struture
            app.hypnos = source;
            
            % create the heading
            app.idExperiment.Text = app.hypnos.dataCompleteFileName;
            txtOut = sprintf('Recording from: %s to %s. Duration of recording (hh:mm:ss): %s', datestr(app.hypnos.trajectory.t1),...
                datestr(app.hypnos.trajectory.t2), app.hypnos.trajectory.duration);
            app.timingExp.Text = txtOut;
            
            % fill the fields of the notes tab           
            app.dirFile.Text = app.hypnos.dataCompleteFileName;
            app.timeAndDuration.Text = sprintf('Recording from: %s to %s. Duration of recording (hh:mm:ss): %s', datestr(app.hypnos.trajectory.t1),...
                datestr(app.hypnos.trajectory.t2), app.hypnos.trajectory.duration);
            app.framesLbl.Text = sprintf('Frames: %1d', app.hypnos.len);      % to be fixed trajectory.len
            app.spLbl.Text = sprintf('Sampling period (s): %1.3f', app.hypnos.trajectory.medianDt);

            % now create an output for the acquisition parameters. These
            % have been saved in the trajectory struct from morpheus 3.0 onwards.
            if isfield(app.hypnos.trajectory, 'exposure')
                app.cameraExposureLbl.Text = app.hypnos.trajectory.exposure; 
                app.thresholdNightAndDayLbl.Text = sprintf('Threshold night: %1.1f day: %1.1f',...
                    [app.hypnos.trajectory.nightThr app.hypnos.trajectory.dayThr]);
                AMstring = [];              % Compose string for the description of the analysis mode
                % Flat correction
                if app.hypnos.trajectory.flagFlat
                    AMstring = 'Flat corrected.';
                    if app.hypnos.trajectory.flagFlatLP
                        app.LPfilterFlatFieldPxLbl.Text = sprintf('LP filter flat field (px): %1d',...
                            app.hypnos.trajectory.flatLP);
                        AMstring = [AMstring ' LP filter.'];
                    else
                        app.LPfilterFlatFieldPxLbl.Text = sprintf('LP filter flat field (px): NA');
                        AMstring = [AMstring ' No LP filter.'];
                    end
                else
                    AMstring = 'No flat.';                    
                    app.LPfilterFlatFieldPxLbl.Text = sprintf('LP filter flat field (px): NA');
                end
                % Frame LP filter
                if app.hypnos.trajectory.flagFltFr
                    AMstring = [AMstring ' Frame filter.'];
                    app.LPfilterEachFramePxLbl.Text = sprintf('LP filter of each frame (px): %1d',...
                            app.hypnos.trajectory.fltFrPix);
                else
                    AMstring = [AMstring ' No frame filter.'];                 
                    app.LPfilterEachFramePxLbl.Text = sprintf('LP filter of each frame (px): NA');
                end
                app.analysisModeLbl.Text = AMstring;
                % Blob analyzer
                if app.hypnos.trajectory.flagBlob
                    AMstring = stringf('Blob analyzer. Ignore blobs smaller than %d px. Mouse size: %d', ...
                        [app.hypnos.trajectory.flagIgnore app.hypnos.trajectory.flagMouse]);
                else
                    AMstring = 'No blob analyzer';
                end
                app.blobAnalyzerFiltersizeLbl.Text = AMstring;
            end
            if isfield(app.hypnos.trajectory, 'segMode')
                app.segmentationModeLbl.Text = app.hypnos.trajectory.segMode;
            else
                app.segmentationModeLbl.Text = 'Simple threshold binarization';
            end
            
            % Experiment notes
            app.sexLbl.Text = app.hypnos.trajectory.sex;
            app.birthDateLbl.Text = datestr(app.hypnos.trajectory.birthdate);
            app.geneticBackgroundLbl.Text = app.hypnos.trajectory.geneticBackground;
            app.lightCycleLbl.Text = app.hypnos.trajectory.lightCycle;
            app.notesLbl.Text = app.hypnos.trajectory.notes;
            % compute the age at the time of the experiment in days
            age = round(days(app.hypnos.trajectory.t1 - app.hypnos.trajectory.birthdate));
            app.ageAtExperimentTimeLbl.Text = sprintf('Age at experiment time: %d days',age);
            
            % adjust the inner position property of the trajectory plots
            app.plot1.Units = 'pixels';
            app.plot1.Position = [3 560 190 164]; %520
            app.plot1.Toolbar.Visible = 'off';
            
            % generate the matrix of trajectory plots
            for j=1:4
                if j==1, is=2;
                else
                    is=1;
                end
                vpos = 560 - (j-1)*164;
                for i=is:6       % columns of plots
                    nmPl = i + 6*(j-1);
                    nwPlot = ['plot' num2str(nmPl)];
                    app.(nwPlot) = uiaxes(app.TrajectoriesTab);
                    app.(nwPlot).Units = 'pixels';
                    title(app.(nwPlot), 'Title')
                    xlabel(app.(nwPlot), '')
                    ylabel(app.(nwPlot), '')
                    app.(nwPlot).XLim = [1 640];
                    app.(nwPlot).YLim = [1 480];
                    app.(nwPlot).XTick = [];
                    app.(nwPlot).YTick = [];
                    xpos = 3+200*(i-1);
                    app.(nwPlot).Position = [xpos vpos 190 164];
                    app.(nwPlot).Toolbar.Visible = 'off';
                end
            end
            app.maxValueBox(1) = 20;       % Exploratory
            app.maxValueBox(2) = 20;        % local exploratory
            app.maxValueBox(3) = 80;        % resting
            app.maxValueBox(4) = 600;       % sleep
            app.maxValueBox(5) = 15;        % speed during exploratory states
            app.maxT.Value = app.maxValueBox(1);

        end

        % Button pushed function: plotTrBt
        function plotTrj(app, event)
            % This callback plots the trajectories in 24 panels each one covering one hour
            binDuration = hours(1);
            binH = hours(0.5);
            % compute midtime of each plot
            npl = ceil(app.hypnos.trajectory.duration / binDuration);
            if npl>24, npl = 24;
            end
            for i=1:npl
                dtime(i) = app.hypnos.trajectory.t1 + binH + (i-1)*binDuration;
                nPlt = ['plot' num2str(i)];
                app.(nPlt).Visible = 'on';
                cla(app.(nPlt));
                title(app.(nPlt), datestr(dtime(i)))
            end
            % Hide the unused plots
            for i=npl+1:24
               nPlt = ['plot' num2str(i)];
               app.(nPlt).Visible = 'off';
            end
            % now find the indexes of the trajectory in each panel and plot the
            % trajectory
            dtimeL(1) = 1;
            dtimeR(1) = ceil(3600/app.hypnos.trajectory.meanDt);
            for i=1:npl
                if i>1
                    dtimeL(i) = dtimeR(i-1) + 1;
                    dtimeR(i) = dtimeL(i) + ceil(3600/app.hypnos.trajectory.meanDt);
                    if dtimeR(i) > app.hypnos.len
                        dtimeR(i) = app.hypnos.len;
                    end
                end
                nPlt = ['plot' num2str(i)];
                % now plot the trajectory
                cla(app.(nPlt))
                % now lets identify the predominant environmental parameter for each panel
                ev(i) = mean(app.hypnos.trajectory.environment(dtimeL(i):dtimeR(i)));
                if ev(i) < 0.5, clr = 'b';
                else
                    clr = 'g';
                end
                plot(app.(nPlt),app.hypnos.trajectory.x(dtimeL(i):dtimeR(i)),app.hypnos.trajectory.y(dtimeL(i):dtimeR(i)),"Color",'b')
                iD = dtimeL(i) + find(app.hypnos.trajectory.environment(dtimeL(i):dtimeR(i))==1) - 1;
                hold (app.(nPlt), 'on');
                plot(app.(nPlt),app.hypnos.trajectory.x(iD),app.hypnos.trajectory.y(iD),"Color",'g')
                hold (app.(nPlt), 'off');
%                 plot(app.(nPlt),app.hypnos.trajectory.x(dtimeL(i):dtimeR(i)),...
%                     app.hypnos.trajectory.y(dtimeL(i):dtimeR(i)),"Color",clr)
            
            
            end
        end

        % Button pushed function: compBinnedSt
        function compBinStates(app, event)
            
            % January, February 2020. GMR
            % Compute the frequency and the statistics of the duration of states upon division of the
            % recording interval in bins of duration app.binWidth minutes.
            % March 2020. Code modified to include the explicit computation of bin statistics. FC.
            
            % Compute the average duration of states and the stats of speed attained during the exploratory phase 

            app.compBinnedSt.Text = 'Computing...';
            app.compBinnedSt.BackgroundColor = [1,0,0];
            drawnow

            nbin = floor(minutes(app.hypnos.trajectory.t2 - app.hypnos.trajectory.t1)/app.binWidth.Value);
            app.report.stateBinT = datetime;               % this line casts app.report.stateBinT as a DateTime array
            app.report.stateBinT(1:nbin) = datetime;       % and this preallocates the array of DateTime
            app.report.stateBoxCounter(1:nbin,1:5) = 0;
            tim(1:nbin) = datetime;
            
            app.report.statePercent = zeros(nbin,5);   %%%% % of time spent in each state in each bin
            
            app.stateBoxArraySt1 = [];     % Vectors holding the state durations that will be used for the box plot 
            app.stateBoxArraySt2 = []; 
            app.stateBoxArraySt3 = []; 
            app.stateBoxArraySt4 = []; 
            app.stateBoxArraySt5 = [];     % Statistics of speed during exploratory state
            
            app.gV1 = [];       % These are the grouping variables used for the computation of statistics
            app.gV2 = [];
            app.gV3 = [];
            app.gV4 = [];
            app.gV5 = [];   
            
            app.report.quantilesBoxArray1 = zeros(nbin,5); % Arrays containing the quantiles for the cumulative 
            app.report.quantilesBoxArray2 = zeros(nbin,5); % probabilities of 0.05,0.25,0.50,0.75,0.95
            app.report.quantilesBoxArray3 = zeros(nbin,5);             
            app.report.quantilesBoxArray4 = zeros(nbin,5);           
            app.report.quantilesBoxArray5 = zeros(nbin,5);
            
            app.report.meanDurationBoxArraySt = zeros(nbin,5);   % Array containing the mean, one column for each status
            app.report.medianDurationBoxArraySt = zeros(nbin,5);   % Array containing the mean, one column for each status
            app.BinExportLbl = [];

            
            for bin = 1:nbin
                tleft = app.hypnos.trajectory.t1 + minutes((bin-1) * app.binWidth.Value);
                ileft = ceil(seconds(tleft-app.hypnos.trajectory.t1)/app.hypnos.trajectory.meanDt + 1);
                tright = app.hypnos.trajectory.t1 + minutes(bin * app.binWidth.Value) - ...
                    seconds(app.hypnos.trajectory.medianDt);
                iright = ceil(seconds(tright-app.hypnos.trajectory.t1)/app.hypnos.trajectory.meanDt);
                tim(bin) = tleft + minutes(app.binWidth.Value/2);
                app.report.stateBinT(bin) = tim(bin);
%               iright = ceil(ileft + app.plotWidth.Value/app.trajectory.meanDt);
                if iright>app.hypnos.len, iright = app.hypnos.len;
                end
                app.BinExportLbl=tim';
                % count frames spent in each behavioural state

                for frame = ileft:iright
                    app.report.statePercent(bin,app.hypnos.trajectory.state(frame)) = ...
                        app.report.statePercent(bin,app.hypnos.trajectory.state(frame)) + 1;
                end

                % convert to percentage
                app.report.statePercent(bin,1:3) = app.report.statePercent(bin,1:3)/(iright-ileft+1); 
                
                % Identify all states that begins in this bin. February 18, 2020
                % The state description are held in the structures
                % app.hypnos.trajectory.resting, ...localActivity, ...exploratory with
                % fields: .leftI; .rightI; .duration
                % Additionally, the app.hypnos.trajectory.exploratory state has field
                % .meanS holding the mean speed during each exploratory state
                
                % extract the events falling in the bin
                dts = datestr(tim(bin));
                
                % State 4 (sleep)
                ir = find(app.hypnos.trajectory.sleep.leftI>=ileft & ...
                    app.hypnos.trajectory.sleep.leftI<iright);
                app.report.stateBoxCounter(bin,4) = length(ir);
                binCnt =  length(ir);
                if binCnt==0
                    % no elements in this bin. In order to properly plot the box we insert a single element of duration 0.
                    binCnt = 1;
                    exR = 0;
                else
                    exR = seconds(app.hypnos.trajectory.sleep.duration(ir));     % convert the array expressing the duration in seconds 
                                                                      % (as double) into a 'duration' variable
                end
                app.stateBoxArraySt4 = [app.stateBoxArraySt4 exR];
               
                app.report.quantilesBoxArray4(bin,1:5) = quantile(exR,[0.05 0.25 0.50 0.75 0.95]);
                app.report.meanDurationBoxArraySt(bin,4) = mean(exR);
                app.report.medianDurationBoxArraySt(bin,4) = median(exR);
               
                app.gV4 = [app.gV4; repmat(bin,binCnt,1)];

                ir = find(app.hypnos.trajectory.resting.leftI>=ileft & ...
                    app.hypnos.trajectory.resting.leftI<iright);
                app.report.stateBoxCounter(bin,3) = length(ir);
                binCnt =  length(ir);
                if binCnt==0, binCnt = 1;
                    exR = 0;
                else
                    exR = seconds(app.hypnos.trajectory.resting.duration(ir));     % convert the duration array into seconds
                end
                app.stateBoxArraySt3 = [app.stateBoxArraySt3 exR];
                app.gV3 = [app.gV3; repmat(bin,binCnt,1)]; 
                app.report.quantilesBoxArray3(bin,1:5) = quantile(exR,[0.05 0.25 0.50 0.75 0.95]);
                app.report.meanDurationBoxArraySt(bin,3) = mean(exR);
                app.report.medianDurationBoxArraySt(bin,3) = median(exR);
                
                ir = find(app.hypnos.trajectory.localActivity.leftI >= ...
                    ileft & app.hypnos.trajectory.localActivity.leftI<iright);
                app.report.stateBoxCounter(bin,2) = length(ir);
                binCnt =  length(ir);
                if binCnt==0, binCnt = 1;
                    exR = 0;
                else
                    exR = seconds(app.hypnos.trajectory.localActivity.duration(ir));
                end
                app.stateBoxArraySt2 = [app.stateBoxArraySt2 exR];
                app.gV2 = [app.gV2; repmat(bin,binCnt,1)]; 
                app.report.quantilesBoxArray2(bin,1:5) = quantile(exR,[0.05 0.25 0.50 0.75 0.95]);
                app.report.meanDurationBoxArraySt(bin,2) = mean(exR);
                app.report.medianDurationBoxArraySt(bin,2) = median(exR);

                % Exploratory state. We also prepare for the computation of the speed statistics
                ir = find(app.hypnos.trajectory.exploratory.leftI >= ...
                    ileft & app.hypnos.trajectory.exploratory.leftI<iright);
                app.report.stateBoxCounter(bin,1) = length(ir);
                binCnt =  length(ir);
                if binCnt==0, binCnt = 1;
                    exR = 0;
                    exS = 0;
                else
                    exR = seconds(app.hypnos.trajectory.exploratory.duration(ir));
                    exS = app.hypnos.trajectory.exploratory.meanS(ir);
                end
                app.stateBoxArraySt1 = [app.stateBoxArraySt1 exR];
                app.gV1 = [app.gV1; repmat(bin,binCnt,1)];
                app.report.quantilesBoxArray1(bin,1:5) = quantile(exR,[0.05 0.25 0.50 0.75 0.95]);
                app.report.meanDurationBoxArraySt(bin,1) = mean(exR);
                app.report.medianDurationBoxArraySt(bin,1) = median(exR);
                
                app.stateBoxArraySt5 = [app.stateBoxArraySt5 exS]; 
                app.gV5 = [app.gV5; repmat(bin,binCnt,1)];
                
                app.report.quantilesBoxArray5(bin,1:5) = quantile(exS,[0.05 0.25 0.50 0.75 0.95]);
                app.report.meanDurationBoxArraySt(bin,5) = mean(exS);
                app.report.medianDurationBoxArraySt(bin,5) = median(exS);
            end
            
            % One note about the labels on the time axis. 
            % The most natural choice is to display the datetime of each bin
            % but this takes lots of space. The simplest thing is to show
            % only the time of the day.
            app.binTickLbl = [];
            if bin <= 24, resLbl = 1;   % this variable is used to compute the number of labels on the X axis
                nLbl = nbin;
                app.binTicks = tim;
                app.binTicksBP = [1:nbin]; 
                for i=1:nbin
                    app.binTickLbl(i) = str2num([num2str(hour(tim(i))) '.' num2str(minute(tim(i)))]);
                end
            else
                resLbl = 4;
                app.binTicks = tim(1:resLbl:nbin);
                app.binTicksBP = [1:4:nbin];
                for i=1:length(app.binTicks)
                    app.binTickLbl(i) = str2num([num2str(hour(app.binTicks(i))) '.' num2str(minute(app.binTicks(i)))]);
                end
                nLbl = length(app.binTicks);
            end
             
            plot(app.states,app.report.stateBinT,app.report.statePercent(:,1),'-*','Color','b')
            hold (app.states,'on')
            plot(app.states,app.report.stateBinT,app.report.statePercent(:,2),'-*','Color','r')
            plot(app.states,app.report.stateBinT,app.report.statePercent(:,3),'-*','Color','g')
            hold (app.states,'off')
            app.states.XLim = [app.hypnos.trajectory.t1 app.hypnos.trajectory.t2];  
            app.states.XTick = app.binTicks;
            app.states.XTickLabel = app.binTickLbl';
            %axes(app.boxPlot, 'YScale', 'log' )
            
            %Cross Correlation of Grooming and Exploratory activity during
            %day
            DayIdx=find(hour(app.report.stateBinT)<19& hour(app.report.stateBinT)>=7);
            app.Expl_D=app.report.statePercent(DayIdx,1);
            Explmean_D=mean(app.Expl_D);
            app.Expl_D=app.Expl_D-Explmean_D;
            app.Groom_D=app.report.statePercent(DayIdx,2);
            Groommean_D= mean(app.Groom_D);
            app.Groom_D=app.Groom_D-Groommean_D;
            [CCday DayLag ]=xcorr(app.Expl_D,app.Groom_D);
            [app.report.CCdayMax CCdayIdx]= max (CCday);
            app.report.MaxDayLag= DayLag(CCdayIdx).*app.binWidth.Value;
            %Cross Correlation of Grooming and Exploratory activity during
            %night
            NightIdx_1=find(hour(app.report.stateBinT)>=19& hour(app.report.stateBinT)<=23);
            NightIdx_2=find(hour(app.report.stateBinT)>=0 & hour(app.report.stateBinT)<7);
            NightIdx=[NightIdx_1 NightIdx_2];
            app.Expl_N=app.report.statePercent(NightIdx,1);
            Explmean_N= mean(app.Expl_N);
            app.Expl_N=app.Expl_N-Explmean_N;
            app.Groom_N=app.report.statePercent(NightIdx,2);
            Groommean_N=mean(app.Groom_N);
            app.Groom_N=app.Groom_N-Groommean_N;
            [CCnight NightLag ]=xcorr(app.Expl_N,app.Groom_N);
            [app.report.CCnightMax CCnightIdx]= max (CCnight);
            app.report.MaxNightLag= NightLag(CCnightIdx).*app.binWidth.Value;
            
            plotStateDuration(app)
            alignPlots(app)
            app.compBinnedSt.Text = 'Binned states';
            app.compBinnedSt.BackgroundColor = [0.96,0.96,0.96];
            drawnow
            
        end

        % Button pushed function: compDisp
        function computeDisplacement(app, event)
            % January 18, 2020
            % Compute integral displacement and plot according to the
            % behavioural state (blu: exploratory; red: local activity;
            % green: resting)
            
            % May 1, 2020. Code optimisation. Timing of the 2 operations:
            % computation: 0.35 s. Plotting: 0.4 s.  Asus 375000 frames.
            
            clrSt = ['b' 'r'  'g'];
            app.compDisp.Text = 'Computing...';
            app.compDisp.BackgroundColor = [1,0,0];
            drawnow
            % Compute the integral of displacement
            cla(app.integralD)
            app.hypnos.trajectory.dispInt = ones(app.hypnos.len,1);
            app.hypnos.trajectory.dispInt(1) = app.hypnos.trajectory.d(1);
            app.report.dispInt = cumsum(app.hypnos.trajectory.d); 

            hold(app.integralD,'on')
            
            % May 1, 2020. The three states are plotted separatelyafter decimation
            % This is both for performance improvement and for a better representaion
            % of the activity state transitions. This also determined a timing improvement
            % of the code parsing the trace for the state identification.
            stepT = 50;
            %stepT = floor(length(app.hypnos.trajectory.state)/factorD);
            decT = app.hypnos.trajectory.timeArray(1:stepT:end);
            dispT = app.report.dispInt(1:stepT:end);
            stateT = app.hypnos.trajectory.state(1:stepT:end);
%            scatter(app.integralD,decT,dispT,0.2,clrSt(stateT))                
            
             for st=3:-1:1       % loop on all states
                 extEl = find(stateT == st);
                 scatter(app.integralD,decT(extEl),dispT(extEl),1,clrSt(st))                
             end
            app.integralD.XTickLabel = [];
            app.integralD.XLim = [app.hypnos.trajectory.t1 app.hypnos.trajectory.t2];          
            drawnow
            % Now compute integral displacement during day and night
            app.report.intDispN = sum(app.hypnos.trajectory.d(app.hypnos.trajectory.environment==0))/100;
            app.report.intDispD = sum(app.hypnos.trajectory.d(app.hypnos.trajectory.environment==1))/100;            
            app.integralD.Title.String = sprintf('Integral of trajectory. Day: %1.0f m. Night; %1.0f m',...
                app.report.intDispD, app.report.intDispN);
            app.compDisp.Text = 'Displacement';
            app.compDisp.BackgroundColor = [0.96,0.96,0.96];
            drawnow
        end

        % Value changed function: selectStateDD
        function selectState(app, event)
            st = str2num(app.selectStateDD.Value);
            app.maxT.Value = app.maxValueBox(st);
            plotStateDuration (app)
        end

        % Value changed function: maxT
        function changeMaxY(app, event)
            value = app.maxT.Value;
            st = str2num(app.selectStateDD.Value);
            app.maxValueBox(st) = value;
            plotStateDuration(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            filename= 'BoxPlotData';
          %% Create table for each state:
           %state1= exploratory, state2=local act, state 3=resting, 
           % state 4=sleep; state 5= speed. Each state is in its
           % corresponding sheet
           
           Sheet1Table= table(app.BinExportLbl,app.report.meanDurationBoxArraySt(:,1),app.report.quantilesBoxArray1(:,1),app.report.quantilesBoxArray1(:,2),app.report.quantilesBoxArray1(:,3),app.report.quantilesBoxArray1(:,4),app.report.quantilesBoxArray1(:,5));
           Sheet2Table= table(app.BinExportLbl,app.report.meanDurationBoxArraySt(:,2),app.report.quantilesBoxArray2(:,1),app.report.quantilesBoxArray2(:,2),app.report.quantilesBoxArray2(:,3),app.report.quantilesBoxArray2(:,4),app.report.quantilesBoxArray2(:,5));
           Sheet3Table= table(app.BinExportLbl,app.report.meanDurationBoxArraySt(:,3),app.report.quantilesBoxArray3(:,1),app.report.quantilesBoxArray3(:,2),app.report.quantilesBoxArray3(:,3),app.report.quantilesBoxArray3(:,4),app.report.quantilesBoxArray3(:,5));
           Sheet4Table= table(app.BinExportLbl,app.report.meanDurationBoxArraySt(:,4),app.report.quantilesBoxArray4(:,1),app.report.quantilesBoxArray4(:,2),app.report.quantilesBoxArray4(:,3),app.report.quantilesBoxArray4(:,4),app.report.quantilesBoxArray4(:,5));
           Sheet5Table= table(app.BinExportLbl,app.report.meanDurationBoxArraySt(:,5),app.report.quantilesBoxArray5(:,1),app.report.quantilesBoxArray5(:,2),app.report.quantilesBoxArray5(:,3),app.report.quantilesBoxArray5(:,4),app.report.quantilesBoxArray5(:,5));
          
           %% Defining the variables name for each of the Table 
           Sheet1Table.Properties.VariableNames{'Var1'} = 'Bin';
           Sheet1Table.Properties.VariableNames{'Var2'} = 'Mean';
           Sheet1Table.Properties.VariableNames {'Var3'} = '5perc';
           Sheet1Table.Properties.VariableNames {'Var4'} = '25perc';
           Sheet1Table.Properties.VariableNames {'Var5'} = 'median';
           Sheet1Table.Properties.VariableNames {'Var6'} = '75perc';
           Sheet1Table.Properties.VariableNames {'Var7'} = '95perc';
           
           Sheet2Table.Properties.VariableNames{'Var1'} = 'Bin';
           Sheet2Table.Properties.VariableNames{'Var2'} = 'Mean';
           Sheet2Table.Properties.VariableNames {'Var3'} = '5perc';
           Sheet2Table.Properties.VariableNames {'Var4'} = '25perc';
           Sheet2Table.Properties.VariableNames {'Var5'} = 'median';
           Sheet2Table.Properties.VariableNames {'Var6'} = '75perc';
           Sheet2Table.Properties.VariableNames {'Var7'} = '95perc';
           
           Sheet3Table.Properties.VariableNames{'Var1'} = 'Bin';
           Sheet3Table.Properties.VariableNames{'Var2'} = 'Mean';
           Sheet3Table.Properties.VariableNames {'Var3'} = '5perc';
           Sheet3Table.Properties.VariableNames {'Var4'} = '25perc';
           Sheet3Table.Properties.VariableNames {'Var5'} = 'median';
           Sheet3Table.Properties.VariableNames {'Var6'} = '75perc';
           Sheet3Table.Properties.VariableNames {'Var7'} = '95perc';
           
           Sheet4Table.Properties.VariableNames{'Var1'} = 'Bin';
           Sheet4Table.Properties.VariableNames{'Var2'} = 'Mean';
           Sheet4Table.Properties.VariableNames {'Var3'} = '5perc';
           Sheet4Table.Properties.VariableNames {'Var4'} = '25perc';
           Sheet4Table.Properties.VariableNames {'Var5'} = 'median';
           Sheet4Table.Properties.VariableNames {'Var6'} = '75perc';
           Sheet4Table.Properties.VariableNames {'Var7'} = '95perc';
           
           Sheet5Table.Properties.VariableNames{'Var1'} = 'Bin';
           Sheet5Table.Properties.VariableNames{'Var2'} = 'Mean';
           Sheet5Table.Properties.VariableNames {'Var3'} = '5perc';
           Sheet5Table.Properties.VariableNames {'Var4'} = '25perc';
           Sheet5Table.Properties.VariableNames {'Var5'} = 'median';
           Sheet5Table.Properties.VariableNames {'Var6'} = '75perc';
           Sheet5Table.Properties.VariableNames {'Var7'} = '95perc';      
      
           %% write table in the excel file. Each sheet represent the statistics for one of the 5 states
           expdate=datestr(app.hypnos.trajectory.t1,'dd-mmm-yyyy');
           
           writetable(Sheet1Table,[app.hypnos.dataPath filename expdate '.xls' ],'Sheet',1);
           writetable(Sheet2Table,[app.hypnos.dataPath filename expdate '.xls' ],'Sheet',2);
           writetable(Sheet3Table,[app.hypnos.dataPath filename expdate '.xls' ],'Sheet',3);
           writetable(Sheet4Table,[app.hypnos.dataPath filename expdate '.xls' ],'Sheet',4);
           writetable(Sheet5Table,[app.hypnos.dataPath filename expdate '.xls' ],'Sheet',5);


        end

        % Callback function: plotStatsRS, useEntireDayNightPeriodsCB
        function computeAndPlotDistributions(app, event)
            busyHisto (app)
            % the computation and plotting of the histograms are decoupled in
            % order to speed up code execution.
            
            computeAllHistograms(app);
            plotAllHistograms(app);
            %Automatic Fitting of SlowDeltaPx
            SlowDeltaPxAreas(app);
            %The Resting Fit is more complicated so there're three buttons
            %to press, here we change color lamp to indicate that the
            %fitting has not yet be done
            app.DayRestingFitLamp.Color = [1,0,0];
            app.NightRestingFitLamp.Color = [1,0,0];
            app.AllDayRestingFitLamp.Color = [1,0,0];
            doneHisto (app)
        end

        % Button pushed function: ExportHisto
        function ExportHistoPushed(app, event)
            %% exporting the data calculated before
            
            app.ExportHisto.Text = 'Saving files';
            app.ExportHisto.BackgroundColor = [1,0,0];
            drawnow
            
            %% Preparing data to export
                SpeedEdges=zeros(length(app.report.logSpeedED),1);
                NightSpeed=zeros(length(app.report.logSpeedED),1);
                DaySpeed=zeros(length(app.report.logSpeedED),1);
                AllDaySpeed=zeros(length(app.report.logSpeedED),1);
                RestEdges=zeros(length(app.report.logRestED),1);
                NightRest=zeros(length(app.report.logRestED),1);
                DayRest=zeros(length(app.report.logRestED),1);
                AllDayRest=zeros(length(app.report.logRestED),1);
                DeltaPxEdges=zeros(length(app.report.logDeltaPxED),1);
                NightDeltaPx=zeros(length(app.report.logDeltaPxED),1);
                DayDeltaPx=zeros(length(app.report.logDeltaPxED),1);
                AllDayDeltaPx=zeros(length(app.report.logDeltaPxED),1);
                DeltaPxSlowEdges=zeros(length(app.report.logDeltaPxSlowED),1);
                NightDeltaPxSlow=zeros(length(app.report.logDeltaPxSlowED),1);
                DayDeltaPxSlow=zeros(length(app.report.logDeltaPxSlowED),1);
                AllDayDeltaPxSlow=zeros(length(app.report.logDeltaPxSlowED),1);
                
            
            if app.distributionOfLogCB.Value
                SpeedEdges=app.report.logSpeedED';
                NightSpeed=app.report.logSpeedN';
                DaySpeed=app.report.logSpeedD';
                AllDaySpeed=app.report.logSpeedT';
                RestEdges=app.report.logRestED';
                NightRest=app.report.logRestN';
                DayRest=app.report.logRestD';
                AllDayRest=app.report.logRestT';
                DeltaPxEdges=app.report.logDeltaPxED';
                NightDeltaPx=app.report.logDeltaPxN';
                DayDeltaPx=app.report.logDeltaPxD';
                AllDayDeltaPx=app.report.logDeltaPxT';
                DeltaPxSlowEdges=app.report.logDeltaPxSlowED';
                NightDeltaPxSlow=app.report.logDeltaPxSlowN';
                DayDeltaPxSlow=app.report.logDeltaPxSlowD';
                AllDayDeltaPxSlow=app.report.logDeltaPxSlowT';
                
                
            else
                SpeedEdges=app.report.speedED';
                NightSpeed=app.report.speedN';
                DaySpeed=app.report.speedD';
                AllDaySpeed=app.report.speedT';
                RestEdges=app.report.restED';
                NightRest=app.report.restN';
                DayRest=app.report.restD';
                AllDayRest=app.report.restT';
                DeltaPxEdges=app.report.deltaPxED';
                NightDeltaPx= app.report.deltaPxN';
                DayDeltaPx=app.report.deltaPxD';
                AllDayDeltaPx=app.report.deltaPxT';
                DeltaPxSlowEdges=app.report.deltaPxSlowED';
                NightDeltaPxSlow=app.report.deltaPxSlowN';
                DayDeltaPxSlow=app.report.deltaPxSlowD';
                AllDayDeltaPxSlow=app.report.deltaPxSlowT';
            end
            
            SpeedTable=table(SpeedEdges,NightSpeed,DaySpeed,AllDaySpeed);
            RestTable=table(RestEdges,NightRest,DayRest,AllDayRest);
            DeltaTable=table(DeltaPxEdges,NightDeltaPx,DayDeltaPx,AllDayDeltaPx);
            SlowDeltaTable=table(DeltaPxSlowEdges,NightDeltaPxSlow,DayDeltaPxSlow,AllDayDeltaPxSlow);
            
            filenameALLDAY= 'DistributionDataAllDay';
            DayStart_idx=find(hour(app.hypnos.trajectory.timeArray) == app.hourDayStart.Value);
            DayStart=app.hypnos.trajectory.timeArray(DayStart_idx);
            DayStart=DayStart(1);
            DayEnd=DayStart+hours(app.nightDayWidth.Value);
            DayStart=num2str(hour(DayStart));
            DayEnd=num2str(hour(DayEnd));
            
            NightStart_idx=find(hour(app.hypnos.trajectory.timeArray) == app.hourNightStart.Value);
            NightStart=app.hypnos.trajectory.timeArray(NightStart_idx);
            NightStart=NightStart(1);
            NightEnd=NightStart+hours(app.nightDayWidth.Value);
            NightStart=num2str(hour(NightStart));
            NightEnd=num2str(hour(NightEnd));
            filenameINTERVAL= 'DistributionDataINTERVAL' ;
            expdate=datestr(app.hypnos.trajectory.t1,'dd-mmm-yyyy');
            
                %here we generate the excel file:
                %sheet1=speed distribution statistics
                %sheet2=Rest distribution statistics
                %sheet3=Delta Px Distribution statistics
                %sheet4=Slow Delta Px Distribution statistics
                
            if app.useEntireDayNightPeriodsCB.Value==1
                writetable(SpeedTable, [app.hypnos.dataPath filenameALLDAY expdate '.xls' ] ,'Sheet',1 );
                writetable(RestTable, [app.hypnos.dataPath filenameALLDAY expdate '.xls' ] ,'Sheet',2 );
                writetable(DeltaTable, [app.hypnos.dataPath filenameALLDAY expdate '.xls' ] ,'Sheet',3 );
                writetable(SlowDeltaTable, [app.hypnos.dataPath filenameALLDAY expdate '.xls' ] ,'Sheet',4 );
                
                app.ExportHisto.Text = 'Export';
                app.ExportHisto.BackgroundColor = [0.96,0.96,0.96];
                fprintf('Entire Day statistics saved in the directory\n')
                drawnow
                
            else
                writetable(SpeedTable, [app.hypnos.dataPath filenameINTERVAL expdate '(d)' DayStart '-' DayEnd '& (n)' NightStart '-' NightEnd  '.xls' ] ,'Sheet',1 );
                writetable(RestTable, [app.hypnos.dataPath filenameINTERVAL expdate '(d)' DayStart '-' DayEnd '& (n)' NightStart '-' NightEnd '.xls' ] ,'Sheet',2 );
                writetable(DeltaTable, [app.hypnos.dataPath filenameINTERVAL expdate '(d)' DayStart '-' DayEnd '& (n)' NightStart '-' NightEnd '.xls' ] ,'Sheet',3 );
                writetable(SlowDeltaTable, [app.hypnos.dataPath filenameINTERVAL expdate '(d)' DayStart '-' DayEnd '& (n)' NightStart '-' NightEnd '.xls' ] ,'Sheet',4 );
                
                app.ExportHisto.Text = 'Export';
                app.ExportHisto.BackgroundColor = [0.96,0.96,0.96];
                fprintf ('Time interval from statistics saved in the directory\n')
                drawnow
            end
        end

        % Callback function
        function setYlogScaleCB(app, event)
            value = app.YlogScaleCB.Value;
            if app.YlogScaleCB.Value == 1    
                app.speedDistrDay.YScale='log';
                app.speedDistrNight.YScale='log'; 
                app.speedDistrTotal.YScale='log'; 

                app.restingDurationDistrDay.YScale='log';
                app.restingDurationDistrNight.YScale='log';
                
                app.deltaPxDistrDay.YScale='log';
                app.deltaPxDistrNight.YScale='log';
                app.deltaPxSlowDistrDay.YScale='log';                
                app.deltaPxSlowDistrNight.YScale='log';
            else
                app.speedDistrDay.XScale='linear';
                app.speedDistrDay.YScale='linear';
                app.speedDistrTotal.YScale='linear'; 

                app.speedDistrNight.XScale='linear';
                app.speedDistrNight.YScale='linear';

                app.restingDurationDistrDay.YScale='linear';
                app.restingDurationDistrNight.YScale='linear';
                app.deltaPxDistrDay.XScale='linear';
                app.deltaPxDistrDay.YScale='linear';
                app.deltaPxDistrNight.XScale='linear';
                app.deltaPxDistrNight.YScale='linear';
                app.deltaPxSlowDistrDay.XScale='linear';
                app.deltaPxSlowDistrDay.YScale='linear';
                app.deltaPxSlowDistrNight.XScale='linear';
                app.deltaPxSlowDistrNight.YScale='linear';
            end
        end

        % Value changed function: YlogScaleCB, autoXCB, autoYCB, 
        % distributionOfLogCB
        function autoLimCBValueChanged(app, event)
            plotAllHistograms(app)
        end

        % Button pushed function: RefreshPxButton
        function RefreshPxButtonPushed(app, event)
               SlowDeltaPxAreas (app);
               if app.AreaSmooth.Value
                   fprintf('Smoothed Delta Px chosen\n')
               else
                   fprintf('Non-Smoothed Delta Px chosen\n')
               end
        end

        % Button pushed function: computeActogram
        function compActogram(app, event)
            % March 22, 2020
            % This function computed and display a raster plot showing the
            % amount of activity in a 24 long cycle. Different options are
            % provided for plotting and for the phase of the plot

            % Iniatilisation of arrays (0.3 s for 180k elements)
            
            lTemp = floor(24 * 3600 / app.hypnos.trajectory.medianDt) + 100;
            temp.speed = zeros(lTemp,1);
            temp.deltaSpeed = zeros(lTemp,1);
            temp.deltaPx = zeros(lTemp,1);
            temp.dTime(1:lTemp) = datetime;
            temp.deltaT = temp.dTime - datetime;
            temp.env = zeros(lTemp,1);
            temp.oldI = zeros(lTemp,1);                        
            
            app.report.actSpeed = [];
            app.report.actDeltaPx = [];
            app.report.actdeltaSpeed = [];
            app.report.actTime = duration(seconds(0));  % Cast this array as duration
            app.report.env = [];
                
            if app.wrapActogramCB.Value
                temp.timeAtStart = app.hourActogram.Value;
            else
                temp.timeAtStart = hour(app.hypnos.trajectory.t1) + minute(app.hypnos.trajectory.t1)/60;
            end

            % 1) define the timing and indexes of the actogram
            if app.wrapActogramCB.Value
                startHr(1) = app.hypnos.trajectory.t1;
                startHr(1).Hour = app.hourActogram.Value;
                startHr(1).Minute = 0;
                startHr(1).Second = 0;
                % two possibilities: startHr preceeds t1 or not
                if startHr(1) >= app.hypnos.trajectory.t1
                    % now we must break the original data in two segments: from
                    % startHc all the way to the end plus from the same time of
                    % the end minus 1 day until startHrI - 1 for wrap around
                    startHrI(1) = findI(app,startHr(1));  % findI accepts a datetime and identifies the index of the array 
                                                    % .timeArray that provide the closest time. 
                    % Now we search for the end of the 24 hr period. If the recording were longer than 24 hrs
                    % there might be no need for wrap around
                    endT = startHr(1) + hours(24);
                    if endT <= app.hypnos.trajectory.t2
                        % the record is long enough not to require wrap around
                        nSegment = 1;
                        endHr(1) = endT;
                        endHrI(1) = findI(app,endHr(1));
                        lengthW = endHrI(1) - startHrI(1) + 1;       % length of the wrapped data
                    else
                        % the record is not long enough. Go until the end
                        % and then wrap around
                        endHr(1) = app.hypnos.trajectory.t2;      
                        endHrI(1) = app.hypnos.trajectory.len;
                        startHr(2) = endHr(1) - days(1) + seconds(app.hypnos.trajectory.medianDt);  % control > .t1
                        if startHr(2) < app.hypnos.trajectory.t1
                            d = startHr(2) - app.hypnos.trajectory.t1;
                            app.wrapAroundGapLbl.Text = sprintf('Wrap around gap (s): %i',seconds(d));
                        end
                        startHrI(2) = findI(app,startHr(2));
                        endHr(2) = startHr(1) - seconds(app.hypnos.trajectory.medianDt);
                        endHrI(2) = findI(app,endHr(2));
                        lengthW = endHrI(1) - startHrI(1) + 1 + endHrI(2) - startHrI(2) + 1;
                        nSegment = 2;
                    end
                else
                    %startHr preceeds t1. therefore we have to add 1 day to startHr
                    startHr(1) = startHr(1) + days(1);
                    startHrI(1) = findI(app,startHr(1));  % findI accepts a datetime and identifies the index of the array 
                                                          % .timeArray that provide the closest time. 
                    endHr(1) = app.hypnos.trajectory.t2;      
                    endHrI(1) = app.hypnos.trajectory.len;
                    startHr(2) = endHr(1) - days(1) + seconds(app.hypnos.trajectory.medianDt);  % control > .t1
                    startHrI(2) = findI(app,startHr(2));
                    endHr(2) = startHr(1) - seconds(app.hypnos.trajectory.medianDt);
                    endHrI(2) = findI(app,endHr(2));
                    lengthW = endHrI(1) - startHrI(1) + 1 + endHrI(2) - startHrI(2) + 1;
                    nSegment = 2;
                end
            else
                % No wrap around of actigram. Debugged on July 29th, 2020
                startHr(1) = app.hypnos.trajectory.t1;
                startHrI(1) = 1;
                endHr(1) = app.hypnos.trajectory.t1 + hours(24);
                endHrI(1) = findI(app,endHr(1));
                if endHr(1) > app.hypnos.trajectory.t2
                    endHr(1) = app.hypnos.trajectory.t2;
                    endHrI(1) = app.hypnos.trajectory.len;
                end                
                lengthW = endHrI(1) - startHrI(1) + 1;
                nSegment = 1;
            end

            % 2) Extraction of the data and creation of a new ordered array.
            % We operate on the speed and deltaPx arrays.
            % At this stage we must also create a time array that starts at
            % the beginning of the actogram until its end

            tStart = startHr(1);
            jRend = endHrI(1)-startHrI(1)+1;    % jRend is the index of the end of the actogram
            % The indexes of startHr and endHr refer to the segments in
            % which the actogram is split when wrapping around. During the
            % computation of the non wrapped around actogram we have one
            % segment only.
            
            temp.speed(1:jRend) = app.hypnos.trajectory.speed(startHrI(1):endHrI(1));
            temp.deltaSpeed(1:jRend) = app.hypnos.trajectory.deltaSpeed(startHrI(1):endHrI(1));
            temp.deltaPx(1:jRend) = app.hypnos.trajectory.deltaPx(startHrI(1):endHrI(1));
            temp.dTime(1:jRend) = app.hypnos.trajectory.timeArray(startHrI(1):endHrI(1));

            % deltaT will be used to compute the time axis of the actogram

            temp.deltaT(1:jRend) = app.hypnos.trajectory.timeArray(startHrI(1):endHrI(1))-tStart;        
            temp.env(1:jRend) = app.hypnos.trajectory.environment(startHrI(1):endHrI(1));
            temp.oldI(1:jRend) = startHrI(1):endHrI(1);     % for debug purposes 
            
            % if there is a second segment this is used for the wrap around
            if nSegment == 2
                % time to complete the wrap around
                % compute corresponding index on the target temp array
                jSt = jRend + 1;
                jRend = jSt + endHrI(2) - startHrI(2);
                temp.speed(jSt:jRend) = app.hypnos.trajectory.speed(startHrI(2):endHrI(2));
                temp.deltaSpeed(jSt:jRend) = app.hypnos.trajectory.deltaSpeed(startHrI(2):endHrI(2));
                temp.deltaPx(jSt:jRend) = app.hypnos.trajectory.deltaPx(startHrI(2):endHrI(2));
                temp.dTime(jSt:jRend) = app.hypnos.trajectory.timeArray(startHrI(2):endHrI(2));
                % deltaT will be used to compute the time axis of the actogram
                temp.deltaT(jSt:jRend) = app.hypnos.trajectory.timeArray(startHrI(2):endHrI(2))-(tStart-days(1));
                temp.env(jSt:jRend) = app.hypnos.trajectory.environment(startHrI(2):endHrI(2));
                temp.oldI(jSt:jRend) = startHrI(2):endHrI(2);                        
            end
            
            if app.wrapActogramCB.Value 
                % controllare questo per actogramma wrappato
                temp.deltaT = temp.deltaT + hours(app.hourActogram.Value);
            end

            % 3) Trim the temp array to their correct length (they were
            % initialized with some elements to spare!)
            l = length(temp.speed);
            if l>lengthW
                temp.speed(lengthW+1:l) = [];
                temp.deltaSpeed(lengthW+1:l) = [];
                temp.deltaPx(lengthW+1:l) = [];
                temp.dTime(lengthW+1:l) = [];
                temp.deltaT(lengthW+1:l) = [];
                temp.env(lengthW+1:l) = [];   
                temp.oldI(lengthW+1:l) = [];                     
            end            
            
            % 4) Now that our arrays are ready we can safely bin the data
            
            totDuration = temp.deltaT(lengthW) - temp.deltaT(1);            
            nbin = ceil(minutes(totDuration)/app.binActogram.Value);
            
            % Compute the indexes of of the edges of each bin.
            % tLeft and tRight are the timing of each bin expressed in minutes relatively 
            % to the beginning of the data set
            t1 = temp.deltaT(1);
            tLeft = 0:app.binActogram.Value:(nbin-1)*app.binActogram.Value; 
            tRight = app.binActogram.Value:app.binActogram.Value:nbin*app.binActogram.Value;
            
%            tLeft = minutes(tLeft) + hours(app.hourActogram.Value);  CHECK FOR WRAPPED!!!
            tLeft = minutes(tLeft); %+ hours(temp.timeAtStart);
            
%            tRight = minutes(tRight) + hours(app.hourActogram.Value);
            tRight = minutes(tRight); %+ hours(temp.timeAtStart);
            
            iLeft(1) = 1;
            for jBin = 2:nbin
                iLeft(jBin) = findDI(app,temp.deltaT,tLeft(jBin));
                iRight(jBin-1) = iLeft(jBin) - 1;
            end
            iRight(nbin) = lengthW;
            
            % compute means in each bin
            for jBin=1:nbin
                app.report.actSpeed(jBin) = mean(temp.speed(iLeft(jBin):iRight(jBin)));
                app.report.actDeltaPx(jBin) = mean(temp.deltaPx(iLeft(jBin):iRight(jBin)));
                app.report.actdeltaSpeed(jBin) = mean(temp.deltaSpeed(iLeft(jBin):iRight(jBin)));
                app.report.actTime(jBin) = mean(temp.deltaT(iLeft(jBin):iRight(jBin)));
                app.report.env(jBin) = round(mean(temp.env(iLeft(jBin):iRight(jBin))));
            end
            app.report.barSpeed = bar(app.actogramSpeed,app.report.actTime,app.report.actSpeed,1,'FaceColor','flat');
            app.report.barArea = bar(app.actogramArea,app.report.actTime,app.report.actDeltaPx,1,'FaceColor','flat');
            % time labels
            Clr(1,:) = [0,0,1];       % Night color
            Clr(2,:) = [0,1,0];       % Day color
            % The following 'mistery statement' is used to take care of the
            % possible presence of Nan in the emv array. This happens when
            % some bins are missing due to the data being shorter than 24
            % hours
            app.report.env(isnan(app.report.env(1:nbin))) = 0;
            clrArray(1:nbin,1) = ones(nbin,1) .* Clr(1+app.report.env(1:nbin),1);
            clrArray(1:nbin,2) = ones(nbin,1) .* Clr(1+app.report.env(1:nbin),2);
            clrArray(1:nbin,3) = ones(nbin,1) .* Clr(1+app.report.env(1:nbin),3);

            app.report.barSpeed.CData = clrArray;
            app.report.barArea.CData = clrArray;
            
%             if app.wrapActogramCB.Value
%                 tstart = app.hourActogram.Value;
%             else
%                 tstart = hour(startHr(1)) + minute(startHr(1))/60;
%             end
            timSc = [temp.timeAtStart:2:temp.timeAtStart+24];
            app.actogramSpeed.XTick = hours(timSc);
            app.actogramArea.XTick = hours(timSc);
            app.actogramSpeed.XTickLabel = mod(timSc,24);
            app.actogramArea.XTickLabel = mod(timSc,24);
            app.actogramSpeed.XLim = [app.actogramSpeed.XTick(1) app.actogramSpeed.XTick(end)];
            app.actogramArea.XLim = [app.actogramSpeed.XTick(1) app.actogramSpeed.XTick(end)];

            ipID = app.actogramSpeed.InnerPosition;
            ipSt = app.actogramArea.InnerPosition;
            dw = (-ipID(3) + ipSt(3));
            app.actogramArea.Position(3) = app.actogramArea.Position(3) - dw;
            app.actogramArea.Position(1) = app.actogramArea.Position(1) + dw;

            % the second iteration perfects the alignment
            ipSt = app.actogramSpeed.InnerPosition;
            ipID = app.actogramArea.InnerPosition;
            dw = (-ipID(3) + ipSt(3));
            app.actogramArea.Position(3) = app.actogramArea.Position(3) - dw;
            app.actogramArea.Position(1) = app.actogramArea.Position(1) + dw;
          
        end

        % Callback function
        function distributionOfLogCBValueChanged(app, event)
            value = app.distributionOfLogCB.Value;
            busyHisto(app)
            computeSpeedHisto (app)
            plotSpeedHisto (app)
            doneHisto(app)
        end

        % Value changed function: maxXSpeed, speedBins
        function redoSpeedHist(app, event)
            busyHisto(app)
            computeSpeedHisto (app)
            plotSpeedHisto (app)
            doneHisto(app)
        end

        % Value changed function: maxYSpeed
        function refreshSD(app, event)
            plotSpeedHisto(app)
        end

        % Value changed function: deltaPxBins, maxXDeltaPx
        function redoDeltaPxHist(app, event)
            busyHisto(app)
            computeDeltaPixHisto (app)
            plotDeltaPixHisto (app)
            doneHisto(app)
        end

        % Value changed function: maxYDeltaPx
        function refreshDelta(app, event)
            plotDeltaPixHisto(app)
        end

        % Value changed function: deltaPxSlowBins, maxXDeltaPxSlow
        function redoDeltaPxSlow(app, event)
            busyHisto(app)
            computeDeltaPixSlowHisto (app)
            plotDeltaPixSlowHisto (app)
            doneHisto(app)
        end

        % Value changed function: maxYDeltaPxSlow
        function refreshDeltaSlow(app, event)
            plotDeltaPixSlowHisto(app)
        end

        % Value changed function: maxXResting, restingDurationBins
        function redoRestHist(app, event)
            busyHisto(app)
            computeRestingDurationHisto (app)
            plotRestingDurationHisto (app)
            doneHisto(app)
        end

        % Value changed function: maxYResting
        function refreshRH(app, event)
            plotRestingDurationHisto(app)
        end

        % Button pushed function: WORDreportButton
        function createWordReport(app, event)
            % First, open a word instance as an activex server 
            app.word = actxserver('Word.Application');          % start Word as an ActiveX server
            app.word.Visible = 1;                               % make Word Server Visible
            % Generate the name of the report file
            fname =  [extractBefore(app.hypnos.dataCompleteFileName,'.') '.doc'];
            % create a word document with this name
            newD = app.word.Documents.Add;                      % newD is the word document object

            selection=app.word.Selection;                           % creation of the Selection object https://docs.microsoft.com/en-us/office/vba/api/word.selection
            selection.Font.Name='Times New Roman';                  % set Font
            selection.Font.Size=10;                                 % set font size
            
            % write a new paragraph of the word document
            
            st = 'prova stringa';
            typeWord(st,1,'')
            
%                if ~exist(word_file_p,'file')
        % Save file as new:
            newD.Save %invoke(word_handle_p,'SaveAs',word_file_p,1);
%     else
%         % Save existing file:
%         invoke(word_handle_p,'Save');
%     end
    % Close the word window:
            newD.Close;            
    % Quit MS Word
    invoke(actx_word_p,'Quit');            
    % Close Word and terminate ActiveX:
    delete(actx_word_p);            

        end 
        function typeWord(st, endPar, style, colorP)
            % this function prints the text contained in the string or cell
            % array 'st' to the wordDocument object
            if(endPar(1))
                app.word.Selection.TypeParagraph;
            end
       	    app.word.Selection.Style = style;
            if(nargin == 4)         %check if the color is defined
                app.word.Selection.Font.ColorIndex=colorP;     
            end
    
	    app.word.Selection.TypeText(st);
            app.word.Selection.Font.ColorIndex='wdAuto';        %set back to default color
%     for k=1:enters_p(2)    
%         actx_word_p.Selection.TypeParagraph; %enter
%     end
        return

        end

        % Value changed function: LogCheckBox
        function LogCheckBoxValueChanged(app, event)
            value = app.LogCheckBox.Value;
            plotStateDuration(app)
        end

        % Value changed function: autoScaleBoxPlot
        function autoScaleBoxPlotValueChanged(app, event)
            value = app.autoScaleBoxPlot.Value;
            plotStateDuration(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
             % create the heading
            app.idExperiment.Text = app.hypnos.dataCompleteFileName;
            txtOut = sprintf('Recording from: %s to %s. Duration of recording (hh:mm:ss): %s', datestr(app.hypnos.trajectory.t1),...
                datestr(app.hypnos.trajectory.t2), app.hypnos.trajectory.duration);
            app.timingExp.Text = txtOut;
            
            % fill the fields of the notes tab           
            app.dirFile.Text = app.hypnos.dataCompleteFileName;
            app.timeAndDuration.Text = sprintf('Recording from: %s to %s. Duration of recording (hh:mm:ss): %s', datestr(app.hypnos.trajectory.t1),...
                datestr(app.hypnos.trajectory.t2), app.hypnos.trajectory.duration);
            app.framesLbl.Text = sprintf('Frames: %1d', app.hypnos.len);      % to be fixed trajectory.len
            app.spLbl.Text = sprintf('Sampling period (s): %1.3f', app.hypnos.trajectory.medianDt);
        end

        % Button pushed function: GOButton
        function GODensityButton(app, event)
            DensityAllDay(app);
            DensityDayNight(app);
            DensityProfile(app)
            
        end

        % Value changed function: ShowFitCB
        function ShowFitCBValueChanged(app, event)
            value = app.ShowFitCB.Value;
            if app.ShowFitCB.Value==1
                SlowDeltaPxFit(app);
            end
        end

        % Value changed function: ShowFitRestCB
        function ShowFitRestCBValueChanged(app, event)
            value = app.ShowFitRestCB.Value;
            if app.ShowFitRestCB.Value==1
                PlotRestFit (app);
                
            end
        end

        % Button pushed function: ShowXCorr
        function ShowXCorrButtonPushed(app, event)
            GrEx_Coupling(app)
        end

        % Button pushed function: FourierButton
        function FourierButtonPushed(app, event)
            %Fourier Trasform of speed
            %night
            NightIdx=find(app.hypnos.trajectory.environment==0);
            Speedfft_N=fft(app.hypnos.trajectory.speed(NightIdx));
            tempAmpS_night= abs(Speedfft_N/length(NightIdx));
            AmpS_night=tempAmpS_night(1:length(NightIdx)/2+1);
            AmpS_night(2:end-1)=2*AmpS_night(2:end-1);
            NSpeedFreq=4*(0:(length(NightIdx)/2))/length(NightIdx);
            [app.report.MaxAmpS_Night MaxSIdx_Night]=max(AmpS_night(2:end));
            FreqS_Night=NSpeedFreq(MaxSIdx_Night);
            app.report.FreqS_Night=FreqS_Night*3600;
            %day
            DayIdx=find(app.hypnos.trajectory.environment==1);
            Speedfft_D=fft(app.hypnos.trajectory.speed(DayIdx));
            tempAmpS_day= abs(Speedfft_D/length(DayIdx));
            AmpS_day=tempAmpS_day(1:length(DayIdx)/2+1);
            AmpS_day(2:end-1)=2*AmpS_day(2:end-1);
            DSpeedFreq=4*(0:(length(DayIdx)/2))/length(DayIdx);
            [app.report.MaxAmpS_Day MaxSIdx_Day]=max(AmpS_day(2:end));
            FreqS_Day=DSpeedFreq(MaxSIdx_Day);
            app.report.FreqS_Day=FreqS_Day*3600;
            
            %Fourier Trasform of area
            %night
            Areafft_N=fft(app.hypnos.trajectory.deltaPx(NightIdx));
            tempAmpA_night= abs(Areafft_N/length(NightIdx));
            AmpA_night=tempAmpA_night(1:length(NightIdx)/2+1);
            AmpA_night(2:end-1)=2*AmpA_night(2:end-1);
            NAreaFreq=4*(0:(length(NightIdx)/2))/length(NightIdx);
            [app.report.MaxAmpA_Night MaxAIdx_Night]=max(AmpA_night(2:end));
            FreqA_Night=NAreaFreq(MaxAIdx_Night);
            app.report.FreqA_Night=FreqA_Night*3600;
            %day
            Areafft_D=fft(app.hypnos.trajectory.deltaPx(DayIdx));
            tempAmpA_day= abs(Areafft_D/length(DayIdx));
            AmpA_day=tempAmpA_day(1:length(DayIdx)/2+1);
            AmpA_day(2:end-1)=2*AmpA_day(2:end-1);
            DAreaFreq=4*(0:(length(DayIdx)/2))/length(DayIdx);
            [app.report.MaxAmpA_Day MaxAIdx_Day]=max(AmpA_day(2:end));
            FreqA_Day=DAreaFreq(MaxAIdx_Day);
            app.report.FreqA_Day=FreqA_Day*3600;
            app.WriteTable.Enable= 'on';
            fprintf('Fourier Transform Computed\n')
            
            if app.FFTSpeedPlotCB.Value==1
                figure
                subplot (1,2,1)
                plot(DSpeedFreq,AmpS_day)
                xlim([0 0.001])
                title('Fft Day Speed')
                xlabel('freq (Hz)')
                ylabel ('Amplitude')
                subplot (1,2,2)
                plot(NSpeedFreq,AmpS_night)
                xlim([0 0.001])
                title('Fft Night Speed')
                xlabel('freq (Hz)')
                ylabel ('Amplitude')
            end
            if app.FFTAreaPlotCB.Value==1
                figure
                subplot(1,2,1)
                plot(DAreaFreq,AmpA_day)
                xlim([0 0.001])
                title('Fft Day Area')
                xlabel('freq (Hz)')
                ylabel ('Amplitude')
                subplot(1,2,2)
                plot(NAreaFreq,AmpA_night)
                xlim([0 0.001])
                title ('Fft Night Area')
                xlabel('freq (Hz)')
                ylabel ('Amplitude')
                
                
            end

        end

        % Value changed function: WriteTable
        function WriteTableValueChanged(app, event)
            value = app.WriteTable.Value;
            SleepAnalysis(app);
            %% 20/05/2020 Summary table
            blankM=NaN;
            app.report.intDispT=app.report.intDispD+app.report.intDispN;
            DisplTable=[app.report.intDispD; app.report.intDispN; app.report.intDispT];
            CCTable= [app.report.CCdayMax; app.report.CCnightMax; blankM];
            LagTable= [app.report.MaxDayLag; app.report.MaxNightLag; blankM];
            SpeedTable=[app.report.mdSpeedD; app.report.mdSpeedN; app.report.mdSpeedT];
            SpeedMeanTable= [app.report.mnSpeedD; app.report.mnSpeedN; app.report.mnSpeedT]; 
            RestTable= [app.report.mdRestD; app.report.mdRestN; app.report.mdRestT];
            RestMeanTable= [app.report.mnRestD; app.report.mnRestN; app.report.mnRestT];
            PxTable= [app.report.mdDeltaPxD; app.report.mdDeltaPxN; app.report.mdDeltaPxT];
            PxMeanTable= [app.report.mnDeltaPxD; app.report.mnDeltaPxN; app.report.mnDeltaPxT];
            SlowPxTable= [app.report.mdDeltaPxSlowD;app.report.mdDeltaPxSlowN;app.report.mdDeltaPxSlowT ];
            SlowPxMeanTable= [app.report.mnDeltaPxSlowD;app.report.mnDeltaPxSlowN;app.report.mnDeltaPxSlowT ];
            RatioTable= [app.report.ratioAD; app.report.ratioAN; blankM ];
            RestThrTable=[10^(app.report.RestThrD); 10^(app.report.RestThrN); 10^(app.report.RestThr)];
            RestRatioTable= [app.report.RatioRestD; app.report.RatioRestN; app.report.RatioRestT];
            MeanSleepTable=[app.report.mnsleepdurationD; app.report.mnsleepdurationN;app.report.mnsleepdurationT];
            MedianSleepTable=[app.report.mdsleepdurationD; app.report.mdsleepdurationN; app.report.mdsleepdurationT];
            MaxSleepTable=[app.report.maxsleepdurationD; app.report.maxsleepdurationN;app.report.maxsleepdurationT];
            SleepEventsTable=[app.report.SleepEvents_Day; app.report.SleepEvents_Night;app.report.SleepEvents_AllDay];
            TimeSleepingTable=[app.report.SleepyTimeD; app.report.SleepyTimeN; app.report.SleepyTimeT];
            SpeedFFTTable= [app.report.FreqS_Day; app.report.FreqS_Night; blankM];
            AreaFFTTable=[ app.report.FreqA_Day; app.report.FreqA_Night; blankM];
            
            SumTable= table(DisplTable,CCTable,LagTable,SpeedTable,SpeedMeanTable, RestTable,RestMeanTable, PxTable, PxMeanTable, SlowPxTable, SlowPxMeanTable, RatioTable, RestThrTable, RestRatioTable,MeanSleepTable, MedianSleepTable, MaxSleepTable,SleepEventsTable, TimeSleepingTable, SpeedFFTTable,AreaFFTTable);
            set(app.SummaryTable, 'Data', SumTable);
            expdate=datestr(app.hypnos.trajectory.t1,'dd-mmm-yyyy');
            writetable(SumTable, [app.hypnos.dataPath 'SumTable ' expdate '.xlsx'] );
            
        end

        % Button pushed function: RefreshRestButton
        function RefreshRestButtonPushed(app, event)
            RestingFit (app);
            RestingFitDay(app);
            RestingFitNight(app);
            if app.ManualRestFitCB.Value
                fprintf('Manual All Day Rest Thr chosen\n')
            else
                fprintf ('Automatic All Day Rest Thr chosen\n')
            end
            
            if app.ManualRestFitD.Value
                fprintf('Manual Day Rest Thr chosen\n')
            else
                fprintf ('Automatic Day Rest Thr chosen\n')
            end
            
            if app.ManualRestFitN.Value
                fprintf('Manual Night Rest Thr chosen\n')
            else
                fprintf ('Automatic Night Rest Thr chosen\n')
            end
        end

        % Button pushed function: reportmatButton
        function reportmatButtonPushed(app, event)
            dt = datestr(datetime(app.hypnos.trajectory.year(1),...
                app.hypnos.trajectory.month(1),app.hypnos.trajectory.day(1)));         % Datetime at the beginning
            ReportStr=app.report;
            save([app.hypnos.dataPath 'report ' dt], 'ReportStr');
        end

        % Button pushed function: FitButtonD
        function FitButtonDPushed(app, event)
            RestingFitDay (app);
           % app.DayRestingFitLamp.Color = [0,1,0];
        end

        % Button pushed function: FitButtonN
        function FitButtonNPushed(app, event)
            RestingFitNight(app);
            %app.NightRestingFitLamp.Color = [0,1,0];
        end

        % Button pushed function: FitButtonT
        function FitButtonTPushed(app, event)
            RestingFit(app);
            %app.AllDayRestingFitLamp.Color = [0,1,0];
        end

        % Value changed function: ManualRestFitD
        function ManualRestFitDValueChanged(app, event)
            
            if app.ManualRestFitD.Value
                app.DayRestingFitLamp.Color=[1,1,0];
            end
            
            if ~app.ManualRestFitD.Value
                app.DayRestingFitLamp.Color=[1,1,0];
            end
        end

        % Value changed function: ManualRestFitN
        function ManualRestFitNValueChanged(app, event)
            
            if app.ManualRestFitN.Value
                app.NightRestingFitLamp.Color=[1,1,0];
            end
            
            if ~app.ManualRestFitN.Value
                app.NightRestingFitLamp.Color=[1,1,0];
            end
        end

        % Value changed function: ManualRestFitCB
        function ManualRestFitCBValueChanged(app, event)
            if app.ManualRestFitCB.Value
                app.AllDayRestingFitLamp.Color=[1,1,0];
            end
            
            if ~app.ManualRestFitCB.Value
                app.AllDayRestingFitLamp.Color=[1,1,0];
            end
        end

        % Value changed function: ManualClimCheckBox
        function ManualClimCheckBoxValueChanged(app, event)
            value = app.ManualClimCheckBox.Value;
            if app.ManualClimCheckBox.Value==1
                DensityAllDay(app);
                DensityDayNight(app);
            end
        end

        % Button pushed function: GOButtonTW
        function GOButtonTWPushed(app, event)
            TimeWindowDensity(app);
            TimeWindowDensityProfile(app);
        end

        % Value changed function: ManualAxisDP
        function ManualAxisDPValueChanged(app, event)
            DensityProfile(app);
        end

        % Value changed function: ManualAxisDP_TW
        function ManualAxisDP_TWValueChanged(app, event)
            StartnFinalValues(app)
            TimeWindowDensityProfile(app);
        end

        % Value changed function: ManualClimTW
        function ManualClimTWValueChanged(app, event)
            TimeWindowDensity(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReportUIFigure = uifigure('Visible', 'off');
            app.ReportUIFigure.Name = 'Report';
            app.idExperiment = uilabel(app.ReportUIFigure);
            app.idExperiment.Text = 'Directory and id Exp.';
            app.timingExp = uilabel(app.ReportUIFigure);
            app.timingExp.Text = 'datetime beginning and end';
            app.TabGroup = uitabgroup(app.ReportUIFigure);
            app.TrajectoriesTab = uitab(app.TabGroup);
            app.TrajectoriesTab.Title = 'Trajectories';
            app.plot1 = uiaxes(app.TrajectoriesTab);
            title(app.plot1, 'Title')
            app.plot1.XLim = [1 640];
            app.plot1.YLim = [1 480];
            app.plot1.XTick = [];
            app.plot1.YTick = [];
            app.plotTrBt = uibutton(app.TrajectoriesTab, 'push');
            app.plotTrBt.ButtonPushedFcn = createCallbackFcn(app, @plotTrj, true);
            app.plotTrBt.Tooltip = {'Plot trajectories as a function of the recording mid-time'};
            app.plotTrBt.Text = 'Plot Tr';
            app.BehaviouralstatesTab = uitab(app.TabGroup);
            app.BehaviouralstatesTab.Title = 'Behavioural states';
            app.integralD = uiaxes(app.BehaviouralstatesTab);
            title(app.integralD, 'Integral of trajectory')
            ylabel(app.integralD, 'Integral of displacement')
            app.states = uiaxes(app.BehaviouralstatesTab);
            title(app.states, 'Behavioural states')
            ylabel(app.states, 'Fraction of time')
            app.boxPlot = uiaxes(app.BehaviouralstatesTab);
            title(app.boxPlot, 'Duration of each behavioural state')
            xlabel(app.boxPlot, 'Time of the day at mid bin (hh:mm)')
            ylabel(app.boxPlot, 'Time (s)')
            app.compBinnedSt = uibutton(app.BehaviouralstatesTab, 'push');
            app.compBinnedSt.ButtonPushedFcn = createCallbackFcn(app, @compBinStates, true);
            app.compBinnedSt.Text = 'Binned states';
            app.BinwidthminLabel = uilabel(app.BehaviouralstatesTab);
            app.BinwidthminLabel.Text = 'Bin width (min):';
            app.binWidth = uieditfield(app.BehaviouralstatesTab, 'numeric');
            app.binWidth.Limits = [0 Inf];
            app.binWidth.Value = 5;
            app.compDisp = uibutton(app.BehaviouralstatesTab, 'push');
            app.compDisp.ButtonPushedFcn = createCallbackFcn(app, @computeDisplacement, true);
            app.compDisp.Text = 'Displacement';
            app.SelectboxplotLabel = uilabel(app.BehaviouralstatesTab);
            app.SelectboxplotLabel.Text = 'Select box plot:';
            app.selectStateDD = uidropdown(app.BehaviouralstatesTab);
            app.selectStateDD.Items = {'Exploratory', 'Local Act', 'Resting', 'Sleep', 'Mean speed'};
            app.selectStateDD.ItemsData = {'1', '2', '3', '4', '5'};
            app.selectStateDD.ValueChangedFcn = createCallbackFcn(app, @selectState, true);
            app.selectStateDD.Value = '1';
            app.maxT = uieditfield(app.BehaviouralstatesTab, 'numeric');
            app.maxT.Limits = [0 Inf];
            app.maxT.ValueDisplayFormat = '%.0f';
            app.maxT.ValueChangedFcn = createCallbackFcn(app, @changeMaxY, true);
            app.autoScaleBoxPlot = uicheckbox(app.BehaviouralstatesTab);
            app.autoScaleBoxPlot.ValueChangedFcn = createCallbackFcn(app, @autoScaleBoxPlotValueChanged, true);
            app.autoScaleBoxPlot.Text = 'Autoscale';
            app.YMaxvalueLabel = uilabel(app.BehaviouralstatesTab);
            app.YMaxvalueLabel.Text = 'Y Max value:';
            app.ExportButton = uibutton(app.BehaviouralstatesTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.LogCheckBox = uicheckbox(app.BehaviouralstatesTab);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogCheckBoxValueChanged, true);
            app.LogCheckBox.Text = 'Log';
            app.ShowXCorr = uibutton(app.BehaviouralstatesTab, 'push');
            app.ShowXCorr.ButtonPushedFcn = createCallbackFcn(app, @ShowXCorrButtonPushed, true);
            app.ShowXCorr.Text = 'Show XCorr';
            app.MixedStatsTab = uitab(app.TabGroup);
            app.MixedStatsTab.Title = 'Statistics for the People';
            app.speedDistrDay = uiaxes(app.MixedStatsTab);
            title(app.speedDistrDay, 'Speed Distribution (Day)')
            app.speedDistrDay.XTick = [];
            app.speedDistrDay.YTick = [];
            app.speedDistrNight = uiaxes(app.MixedStatsTab);
            title(app.speedDistrNight, 'Speed Distribution (Night)')
            xlabel(app.speedDistrNight, 'Speed (cm/s)')
            app.speedDistrNight.YTick = [];
            app.deltaPxDistrDay = uiaxes(app.MixedStatsTab);
            title(app.deltaPxDistrDay, 'Delta Px Distribution (Day)')
            app.deltaPxDistrDay.XTick = [];
            app.deltaPxDistrDay.YTick = [];
            app.deltaPxDistrNight = uiaxes(app.MixedStatsTab);
            title(app.deltaPxDistrNight, 'Delta Px Distribution (Night)')
            xlabel(app.deltaPxDistrNight, 'Fluctuations of mouse area changes (px)')
            app.deltaPxDistrNight.YTick = [];
            app.deltaPxSlowDistrDay = uiaxes(app.MixedStatsTab);
            title(app.deltaPxSlowDistrDay, 'Delta Px During Rest (Day)')
            app.deltaPxSlowDistrDay.XTick = [];
            app.deltaPxSlowDistrDay.YTick = [];
            app.deltaPxSlowDistrNight = uiaxes(app.MixedStatsTab);
            title(app.deltaPxSlowDistrNight, 'Delta Px During Rest (Night)')
            xlabel(app.deltaPxSlowDistrNight, 'Fluctuation of area during resting (px)')
            app.deltaPxSlowDistrNight.YTick = [];
            app.restingDurationDistrDay = uiaxes(app.MixedStatsTab);
            title(app.restingDurationDistrDay, 'Resting State Duration (Day)')
            app.restingDurationDistrDay.XTick = [];
            app.restingDurationDistrDay.YTick = [];
            app.restingDurationDistrNight = uiaxes(app.MixedStatsTab);
            title(app.restingDurationDistrNight, 'Resting State Duration (Night)')
            xlabel(app.restingDurationDistrNight, 'Resting Duration (s)')
            app.restingDurationDistrNight.YTick = [];
            app.deltaPxDistrTotal = uiaxes(app.MixedStatsTab);
            title(app.deltaPxDistrTotal, 'Delta Px Distribution (Total)')
            xlabel(app.deltaPxDistrTotal, 'Fluctuations of mouse area changes (px)')
            app.deltaPxDistrTotal.YTick = [];
            app.deltaPxSlowDistrTotal = uiaxes(app.MixedStatsTab);
            title(app.deltaPxSlowDistrTotal, 'Delta Px During Rest (Total)')
            xlabel(app.deltaPxSlowDistrTotal, 'Fluctuation of area during resting (px)')
            app.deltaPxSlowDistrTotal.YTick = [];
            app.speedDistrTotal = uiaxes(app.MixedStatsTab);
            title(app.speedDistrTotal, 'Speed Distribution (Total)')
            xlabel(app.speedDistrTotal, 'Speed (cm/s)')
            app.speedDistrTotal.YTick = [];
            app.restingDurationDistrTotal = uiaxes(app.MixedStatsTab);
            title(app.restingDurationDistrTotal, 'Resting State Duration (Total)')
            xlabel(app.restingDurationDistrTotal, 'Resting Duration (s)')
            app.restingDurationDistrTotal.YTick = [];
            app.DisplaycontrolPanel = uipanel(app.MixedStatsTab);
            app.DisplaycontrolPanel.Title = 'Display control';
            app.HouratthebeginningofthenightLabel = uilabel(app.DisplaycontrolPanel);
            app.HouratthebeginningofthenightLabel.Text = 'Hour at the beginning of the night:';
            app.hourNightStart = uieditfield(app.DisplaycontrolPanel, 'numeric');
            app.hourNightStart.Limits = [0 23];
            app.hourNightStart.Value = 22;
            app.HouratthebeginningofthedayLabel = uilabel(app.DisplaycontrolPanel);
            app.HouratthebeginningofthedayLabel.Text = 'Hour at the beginning of the day:';
            app.hourDayStart = uieditfield(app.DisplaycontrolPanel, 'numeric');
            app.hourDayStart.Limits = [0 23];
            app.hourDayStart.Value = 12;
            app.WidthofnightdayperiodshhLabel = uilabel(app.DisplaycontrolPanel);
            app.WidthofnightdayperiodshhLabel.Text = 'Width of night & day periods (hh):';
            app.nightDayWidth = uieditfield(app.DisplaycontrolPanel, 'numeric');
            app.nightDayWidth.Limits = [0 23];
            app.nightDayWidth.Value = 2;
            app.useEntireDayNightPeriodsCB = uicheckbox(app.DisplaycontrolPanel);
            app.useEntireDayNightPeriodsCB.ValueChangedFcn = createCallbackFcn(app, @computeAndPlotDistributions, true);
            app.useEntireDayNightPeriodsCB.Text = 'Use the entire day and night period';
            app.useEntireDayNightPeriodsCB.Value = true;
            app.YlogScaleCB = uicheckbox(app.DisplaycontrolPanel);
            app.YlogScaleCB.ValueChangedFcn = createCallbackFcn(app, @autoLimCBValueChanged, true);
            app.YlogScaleCB.Text = 'Semi-logarithmic coordinates';
            app.autoXCB = uicheckbox(app.DisplaycontrolPanel);
            app.autoXCB.ValueChangedFcn = createCallbackFcn(app, @autoLimCBValueChanged, true);
            app.autoXCB.Text = 'Auto X axis';
            app.ExportHisto = uibutton(app.DisplaycontrolPanel, 'push');
            app.ExportHisto.ButtonPushedFcn = createCallbackFcn(app, @ExportHistoPushed, true);
            app.ExportHisto.Tooltip = {'Sheet1=Speed,S2=Rest; S3=DeltaPX;S4=SlowDeltaPx'};
            app.ExportHisto.Text = 'Export';
            app.plotStatsRS = uibutton(app.DisplaycontrolPanel, 'push');
            app.plotStatsRS.ButtonPushedFcn = createCallbackFcn(app, @computeAndPlotDistributions, true);
            app.plotStatsRS.Tooltip = {'Plot trajectories as a function of the recording mid-time'};
            app.plotStatsRS.Text = 'Histograms';
            app.distributionOfLogCB = uicheckbox(app.DisplaycontrolPanel);
            app.distributionOfLogCB.ValueChangedFcn = createCallbackFcn(app, @autoLimCBValueChanged, true);
            app.distributionOfLogCB.Text = 'Distribution of logarithm';
            app.distributionOfLogCB.Value = true;
            app.autoYCB = uicheckbox(app.DisplaycontrolPanel);
            app.autoYCB.ValueChangedFcn = createCallbackFcn(app, @autoLimCBValueChanged, true);
            app.autoYCB.Text = 'Auto Y axis';
            app.MaxXLabel = uilabel(app.MixedStatsTab);
            app.MaxXLabel.Text = 'Max X';
            app.maxXSpeed = uieditfield(app.MixedStatsTab, 'numeric');
            app.maxXSpeed.Limits = [0 200];
            app.maxXSpeed.ValueDisplayFormat = '%.1f';
            app.maxXSpeed.ValueChangedFcn = createCallbackFcn(app, @redoSpeedHist, true);
            app.maxXSpeed.Value = 80;
            app.MaxYLabel = uilabel(app.MixedStatsTab);
            app.MaxYLabel.Text = 'Max Y';
            app.maxYSpeed = uieditfield(app.MixedStatsTab, 'numeric');
            app.maxYSpeed.Limits = [0 1];
            app.maxYSpeed.ValueDisplayFormat = '%.2f';
            app.maxYSpeed.ValueChangedFcn = createCallbackFcn(app, @refreshSD, true);
            app.maxYSpeed.Value = 0.02;
            app.MaxXLabel_2 = uilabel(app.MixedStatsTab);
            app.MaxXLabel_2.Text = 'Max X';
            app.maxXResting = uieditfield(app.MixedStatsTab, 'numeric');
            app.maxXResting.ValueChangedFcn = createCallbackFcn(app, @redoRestHist, true);
            app.maxXResting.Value = 600;
            app.MaxYLabel_2 = uilabel(app.MixedStatsTab);
            app.MaxYLabel_2.Text = 'Max Y';
            app.maxYResting = uieditfield(app.MixedStatsTab, 'numeric');
            app.maxYResting.Limits = [0 1];
            app.maxYResting.ValueDisplayFormat = '%.2f';
            app.maxYResting.ValueChangedFcn = createCallbackFcn(app, @refreshRH, true);
            app.maxYResting.Value = 0.05;
            app.MaxXLabel_3 = uilabel(app.MixedStatsTab);
            app.MaxXLabel_3.Text = 'Max X';
            app.maxXDeltaPx = uieditfield(app.MixedStatsTab, 'numeric');
            app.maxXDeltaPx.ValueChangedFcn = createCallbackFcn(app, @redoDeltaPxHist, true);
            app.maxXDeltaPx.Value = 6000;
            app.MaxYLabel_3 = uilabel(app.MixedStatsTab);
            app.MaxYLabel_3.Text = 'Max Y';
            app.maxYDeltaPx = uieditfield(app.MixedStatsTab, 'numeric');
            app.maxYDeltaPx.Limits = [0 1];
            app.maxYDeltaPx.ValueDisplayFormat = '%.2f';
            app.maxYDeltaPx.ValueChangedFcn = createCallbackFcn(app, @refreshDelta, true);
            app.maxYDeltaPx.Value = 0.1;
            app.MaxXLabel_4 = uilabel(app.MixedStatsTab);
            app.MaxXLabel_4.Text = 'Max X';
            app.maxXDeltaPxSlow = uieditfield(app.MixedStatsTab, 'numeric');
            app.maxXDeltaPxSlow.ValueChangedFcn = createCallbackFcn(app, @redoDeltaPxSlow, true);
            app.maxXDeltaPxSlow.Value = 6000;
            app.MaxYLabel_4 = uilabel(app.MixedStatsTab);
            app.MaxYLabel_4.Text = 'Max Y';
            app.maxYDeltaPxSlow = uieditfield(app.MixedStatsTab, 'numeric');
            app.maxYDeltaPxSlow.Limits = [0 1];
            app.maxYDeltaPxSlow.ValueDisplayFormat = '%.2f';
            app.maxYDeltaPxSlow.ValueChangedFcn = createCallbackFcn(app, @refreshDeltaSlow, true);
            app.maxYDeltaPxSlow.Value = 0.1;
            app.BinsLabel = uilabel(app.MixedStatsTab);
            app.BinsLabel.Text = 'Bins ';
            app.speedBins = uieditfield(app.MixedStatsTab, 'numeric');
            app.speedBins.Limits = [0 200];
            app.speedBins.ValueDisplayFormat = '%.0f';
            app.speedBins.ValueChangedFcn = createCallbackFcn(app, @redoSpeedHist, true);
            app.speedBins.Value = 60;
            app.BinsLabel_2 = uilabel(app.MixedStatsTab);
            app.BinsLabel_2.Text = 'Bins ';
            app.deltaPxBins = uieditfield(app.MixedStatsTab, 'numeric');
            app.deltaPxBins.ValueChangedFcn = createCallbackFcn(app, @redoDeltaPxHist, true);
            app.deltaPxBins.Value = 60;
            app.BinsLabel_3 = uilabel(app.MixedStatsTab);
            app.BinsLabel_3.Text = 'Bins ';
            app.restingDurationBins = uieditfield(app.MixedStatsTab, 'numeric');
            app.restingDurationBins.ValueChangedFcn = createCallbackFcn(app, @redoRestHist, true);
            app.restingDurationBins.Value = 30;
            app.BinsLabel_4 = uilabel(app.MixedStatsTab);
            app.BinsLabel_4.Text = 'Bins ';
            app.deltaPxSlowBins = uieditfield(app.MixedStatsTab, 'numeric');
            app.deltaPxSlowBins.ValueChangedFcn = createCallbackFcn(app, @redoDeltaPxSlow, true);
            app.deltaPxSlowBins.Value = 60;
            app.FittingOptionsLogDistributionsPanel = uipanel(app.MixedStatsTab);
            app.FittingOptionsLogDistributionsPanel.Title = 'Fitting Options (Log Distributions)';
            app.RefreshPxButton = uibutton(app.FittingOptionsLogDistributionsPanel, 'push');
            app.RefreshPxButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPxButtonPushed, true);
            app.RefreshPxButton.Tooltip = {'Update fit parameter. Click it if you changed smooth option.'};
            app.RefreshPxButton.Text = 'Refresh ΔPx';
            app.ShowFitCB = uicheckbox(app.FittingOptionsLogDistributionsPanel);
            app.ShowFitCB.ValueChangedFcn = createCallbackFcn(app, @ShowFitCBValueChanged, true);
            app.ShowFitCB.Text = 'Show Fit ΔPx During Rest';
            app.ShowFitRestCB = uicheckbox(app.FittingOptionsLogDistributionsPanel);
            app.ShowFitRestCB.ValueChangedFcn = createCallbackFcn(app, @ShowFitRestCBValueChanged, true);
            app.ShowFitRestCB.Tooltip = {'Default data smoothing'};
            app.ShowFitRestCB.Text = 'Show Fit Resting Duration';
            app.AreaSmooth = uicheckbox(app.FittingOptionsLogDistributionsPanel);
            app.AreaSmooth.Tooltip = {'After selecting or deselecting me click on show fit to check which one of the two is better'};
            app.AreaSmooth.Text = 'Area Smooth';
            app.ManualRestFitCB = uicheckbox(app.FittingOptionsLogDistributionsPanel);
            app.ManualRestFitCB.ValueChangedFcn = createCallbackFcn(app, @ManualRestFitCBValueChanged, true);
            app.ManualRestFitCB.Text = 'Manual Rest Fit T';
            app.LogThrEditFieldLabel = uilabel(app.FittingOptionsLogDistributionsPanel);
            app.LogThrEditFieldLabel.Text = 'Log Thr';
            app.LogThr = uieditfield(app.FittingOptionsLogDistributionsPanel, 'numeric');
            app.LogThr.Value = 1.3;
            app.RefreshRestButton = uibutton(app.FittingOptionsLogDistributionsPanel, 'push');
            app.RefreshRestButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshRestButtonPushed, true);
            app.RefreshRestButton.Tooltip = {'Update fit parameter. Click it if you changed smooth option or if you want set Thr manually'};
            app.RefreshRestButton.Text = 'RefreshRest';
            app.ManualRestFitD = uicheckbox(app.FittingOptionsLogDistributionsPanel);
            app.ManualRestFitD.ValueChangedFcn = createCallbackFcn(app, @ManualRestFitDValueChanged, true);
            app.ManualRestFitD.Text = 'Manual Rest Fit D';
            app.LogThrDay = uilabel(app.FittingOptionsLogDistributionsPanel);
            app.LogThrDay.Text = 'Log Thr';
            app.LogThr_D = uieditfield(app.FittingOptionsLogDistributionsPanel, 'numeric');
            app.LogThr_D.Value = 1.3;
            app.ManualRestFitN = uicheckbox(app.FittingOptionsLogDistributionsPanel);
            app.ManualRestFitN.ValueChangedFcn = createCallbackFcn(app, @ManualRestFitNValueChanged, true);
            app.ManualRestFitN.Text = 'Manual Rest Fit N';
            app.LogThrEditFieldLabel_3 = uilabel(app.FittingOptionsLogDistributionsPanel);
            app.LogThrEditFieldLabel_3.Text = 'Log Thr';
            app.LogThr_N = uieditfield(app.FittingOptionsLogDistributionsPanel, 'numeric');
            app.LogThr_N.Value = 1.3;
            app.NightRestingFitLampLabel = uilabel(app.FittingOptionsLogDistributionsPanel);
            app.NightRestingFitLampLabel.Text = 'Night Resting Fit';
            app.NightRestingFitLamp = uilamp(app.FittingOptionsLogDistributionsPanel);
            app.AllDayRestingFitLampLabel = uilabel(app.FittingOptionsLogDistributionsPanel);
            app.AllDayRestingFitLampLabel.Text = 'All Day Resting Fit';
            app.AllDayRestingFitLamp = uilamp(app.FittingOptionsLogDistributionsPanel);
            app.DayRestingFitLampLabel = uilabel(app.FittingOptionsLogDistributionsPanel);
            app.DayRestingFitLampLabel.Text = 'Day Resting Fit';
            app.DayRestingFitLamp = uilamp(app.FittingOptionsLogDistributionsPanel);
            app.FitButtonD = uibutton(app.FittingOptionsLogDistributionsPanel, 'push');
            app.FitButtonD.ButtonPushedFcn = createCallbackFcn(app, @FitButtonDPushed, true);
            app.FitButtonD.Text = 'Fit';
            app.FitButtonN = uibutton(app.FittingOptionsLogDistributionsPanel, 'push');
            app.FitButtonN.ButtonPushedFcn = createCallbackFcn(app, @FitButtonNPushed, true);
            app.FitButtonN.Text = 'Fit';
            app.FitButtonT = uibutton(app.FittingOptionsLogDistributionsPanel, 'push');
            app.FitButtonT.ButtonPushedFcn = createCallbackFcn(app, @FitButtonTPushed, true);
            app.FitButtonT.Text = 'Fit';
            app.DayRestThr = uieditfield(app.FittingOptionsLogDistributionsPanel, 'text');
            app.NightRestThr = uieditfield(app.FittingOptionsLogDistributionsPanel, 'text');
            app.AllDayRestThr = uieditfield(app.FittingOptionsLogDistributionsPanel, 'text');
            app.SpaceDensityTab = uitab(app.TabGroup);
            app.SpaceDensityTab.Title = 'Space Density';
            app.DensityNIGHT = uiaxes(app.SpaceDensityTab);
            title(app.DensityNIGHT, 'Density Night')
            app.DensityNIGHT.XTick = [];
            app.DensityNIGHT.YTick = [];
            app.DensityALLDAY = uiaxes(app.SpaceDensityTab);
            title(app.DensityALLDAY, 'Density All Day')
            app.DensityALLDAY.XTick = [];
            app.DensityALLDAY.YTick = [];
            app.DensityDAY = uiaxes(app.SpaceDensityTab);
            title(app.DensityDAY, 'Density Day')
            app.DensityProfileN = uiaxes(app.SpaceDensityTab);
            title(app.DensityProfileN, 'Density Profile Night')
            app.DensityProfileT = uiaxes(app.SpaceDensityTab);
            title(app.DensityProfileT, 'Density Profile All Day')
            app.DensityProfileD = uiaxes(app.SpaceDensityTab);
            title(app.DensityProfileD, 'Density Profile Day')
            app.TW_Density = uiaxes(app.SpaceDensityTab);
            title(app.TW_Density, 'Time window Density')
            app.TW_Density.XTick = [];
            app.TW_Density.YTick = [];
            app.DensityProfileTW = uiaxes(app.SpaceDensityTab);
            title(app.DensityProfileTW, 'Time Window Density Profile')
            app.DensityParametersPanel = uipanel(app.SpaceDensityTab);
            app.DensityParametersPanel.Title = 'Density Parameters';
            app.GOButton = uibutton(app.DensityParametersPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GODensityButton, true);
            app.GOButton.Tooltip = {'Plot space occupation of the mouse'};
            app.GOButton.Text = 'GO!';
            app.ShowColorbar = uicheckbox(app.DensityParametersPanel);
            app.ShowColorbar.Text = 'Show Colorbar';
            app.ManualClimCheckBox = uicheckbox(app.DensityParametersPanel);
            app.ManualClimCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualClimCheckBoxValueChanged, true);
            app.ManualClimCheckBox.Text = 'Manual C lim';
            app.MinEditFieldLabel = uilabel(app.DensityParametersPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinCLim = uieditfield(app.DensityParametersPanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.DensityParametersPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxCLim = uieditfield(app.DensityParametersPanel, 'numeric');
            app.MaxCLim.Value = 3;
            app.TimeSwitch = uiswitch(app.DensityParametersPanel, 'slider');
            app.TimeSwitch.Items = {'Time', '% Time'};
            app.TimeSwitch.Value = 'Time';
            app.ManualAxisDP = uicheckbox(app.DensityParametersPanel);
            app.ManualAxisDP.ValueChangedFcn = createCallbackFcn(app, @ManualAxisDPValueChanged, true);
            app.ManualAxisDP.Text = 'Density Profile manual axis';
            app.MaxXLabel_5 = uilabel(app.DensityParametersPanel);
            app.MaxXLabel_5.Text = 'Max X';
            app.MaxX_DP = uieditfield(app.DensityParametersPanel, 'numeric');
            app.MaxX_DP.Limits = [0 1000];
            app.MaxX_DP.ValueDisplayFormat = '%.0f';
            app.MaxX_DP.Value = 120;
            app.MaxYLabel_5 = uilabel(app.DensityParametersPanel);
            app.MaxYLabel_5.Text = 'Max Y';
            app.MaxY_DP = uieditfield(app.DensityParametersPanel, 'numeric');
            app.MaxY_DP.Limits = [0 1];
            app.MaxY_DP.ValueDisplayFormat = '%.3f';
            app.MaxY_DP.Value = 0.05;
            app.ButtonGroup = uibuttongroup(app.DensityParametersPanel);
            app.AllData = uiradiobutton(app.ButtonGroup);
            app.AllData.Text = 'All Data';
            app.AllData.Value = true;
            app.RestingData = uiradiobutton(app.ButtonGroup);
            app.RestingData.Text = 'Resting Data';
            app.NotRestingData = uiradiobutton(app.ButtonGroup);
            app.NotRestingData.Text = 'Not Resting Data';
            app.FunHisto = uiswitch(app.DensityParametersPanel, 'slider');
            app.FunHisto.Items = {'Histogram', 'Function'};
            app.FunHisto.Value = 'Histogram';
            app.BinEditFieldLabel = uilabel(app.DensityParametersPanel);
            app.BinEditFieldLabel.Text = 'Bin';
            app.BinDensityProf = uieditfield(app.DensityParametersPanel, 'numeric');
            app.BinDensityProf.Limits = [0 Inf];
            app.BinDensityProf.ValueDisplayFormat = '%.0f';
            app.BinDensityProf.Value = 100;
            app.TimeWindowParametersPanel = uipanel(app.SpaceDensityTab);
            app.TimeWindowParametersPanel.Title = 'Time Window Parameters';
            app.Start = uicheckbox(app.TimeWindowParametersPanel);
            app.Start.Text = 'Begin of Recording';
            app.FromhLabel = uilabel(app.TimeWindowParametersPanel);
            app.FromhLabel.Text = 'From (h)';
            app.FromTW = uieditfield(app.TimeWindowParametersPanel, 'text');
            app.FromTW.Value = '12';
            app.ToTW = uieditfield(app.TimeWindowParametersPanel, 'text');
            app.ToTW.Value = '15';
            app.TohLabel = uilabel(app.TimeWindowParametersPanel);
            app.TohLabel.Text = 'To (h)';
            app.ShowColorbarTW = uicheckbox(app.TimeWindowParametersPanel);
            app.ShowColorbarTW.Text = 'Show Colorbar';
            app.ManualClimTW = uicheckbox(app.TimeWindowParametersPanel);
            app.ManualClimTW.ValueChangedFcn = createCallbackFcn(app, @ManualClimTWValueChanged, true);
            app.ManualClimTW.Text = 'Manual C lim';
            app.MinEditFieldLabel_2 = uilabel(app.TimeWindowParametersPanel);
            app.MinEditFieldLabel_2.Text = 'Min';
            app.MinCLimTW = uieditfield(app.TimeWindowParametersPanel, 'numeric');
            app.MaxEditFieldLabel_2 = uilabel(app.TimeWindowParametersPanel);
            app.MaxEditFieldLabel_2.Text = 'Max';
            app.MaxCLimTW = uieditfield(app.TimeWindowParametersPanel, 'numeric');
            app.MaxCLimTW.Value = 2;
            app.GOButtonTW = uibutton(app.TimeWindowParametersPanel, 'push');
            app.GOButtonTW.ButtonPushedFcn = createCallbackFcn(app, @GOButtonTWPushed, true);
            app.GOButtonTW.Tooltip = {'Plot space occupation of the mouse'};
            app.GOButtonTW.Text = 'GO!';
            app.AddHoursTW = uicheckbox(app.TimeWindowParametersPanel);
            app.AddHoursTW.Text = 'Add';
            app.hoursEditFieldLabel = uilabel(app.TimeWindowParametersPanel);
            app.hoursEditFieldLabel.Text = 'hours';
            app.hoursTW = uieditfield(app.TimeWindowParametersPanel, 'numeric');
            app.hoursTW.Limits = [0 24];
            app.hoursTW.Value = 2;
            app.ManualAxisDP_TW = uicheckbox(app.TimeWindowParametersPanel);
            app.ManualAxisDP_TW.ValueChangedFcn = createCallbackFcn(app, @ManualAxisDP_TWValueChanged, true);
            app.ManualAxisDP_TW.Text = 'Density Profile manual axis';
            app.FunHisto_TW = uiswitch(app.TimeWindowParametersPanel, 'slider');
            app.FunHisto_TW.Items = {'Histogram', 'Function'};
            app.FunHisto_TW.Value = 'Histogram';
            app.MaxXLabel_6 = uilabel(app.TimeWindowParametersPanel);
            app.MaxXLabel_6.Text = 'Max X';
            app.MaxX_DP_TW = uieditfield(app.TimeWindowParametersPanel, 'numeric');
            app.MaxX_DP_TW.Limits = [0 1000];
            app.MaxX_DP_TW.ValueDisplayFormat = '%.0f';
            app.MaxX_DP_TW.Value = 120;
            app.MaxYLabel_6 = uilabel(app.TimeWindowParametersPanel);
            app.MaxYLabel_6.Text = 'Max Y';
            app.MaxY_DP_TW = uieditfield(app.TimeWindowParametersPanel, 'numeric');
            app.MaxY_DP_TW.Limits = [0 1];
            app.MaxY_DP_TW.ValueDisplayFormat = '%.3f';
            app.MaxY_DP_TW.Value = 0.05;
            app.BinEditFieldLabel_2 = uilabel(app.TimeWindowParametersPanel);
            app.BinEditFieldLabel_2.Text = 'Bin';
            app.BinDensityProf_TW = uieditfield(app.TimeWindowParametersPanel, 'numeric');
            app.BinDensityProf_TW.Limits = [0 Inf];
            app.BinDensityProf_TW.ValueDisplayFormat = '%.0f';
            app.BinDensityProf_TW.Value = 100;
            app.notes = uitab(app.TabGroup);
            app.notes.Title = 'Notes and Actograms';
            app.actogramSpeed = uiaxes(app.notes);
            title(app.actogramSpeed, 'Actogram: speed')
            app.actogramArea = uiaxes(app.notes);
            title(app.actogramArea, 'Actogram: area change')
            xlabel(app.actogramArea, 'Time of the day (hh)')
            app.dirFile = uilabel(app.notes);
            app.dirFile.Text = 'Directory and id Exp.';
            app.timeAndDuration = uilabel(app.notes);
            app.timeAndDuration.Text = 'datetime beginning and end';
            app.AcquisitionandimagetreatmentPanel = uipanel(app.notes);
            app.AcquisitionandimagetreatmentPanel.Title = 'Acquisition and image treatment';
            app.spLbl = uilabel(app.AcquisitionandimagetreatmentPanel);
            app.spLbl.Text = 'Sampling period (s):';
            app.framesLbl = uilabel(app.AcquisitionandimagetreatmentPanel);
            app.framesLbl.Text = 'Frames:';
            app.cameraExposureLbl = uilabel(app.AcquisitionandimagetreatmentPanel);
            app.cameraExposureLbl.Text = 'Camera gain:';
            app.thresholdNightAndDayLbl = uilabel(app.AcquisitionandimagetreatmentPanel);
            app.thresholdNightAndDayLbl.Text = 'Threshold (night and day):';
            app.segmentationModeLbl = uilabel(app.AcquisitionandimagetreatmentPanel);
            app.segmentationModeLbl.Text = 'Segmentation mode:';
            app.analysisModeLbl = uilabel(app.AcquisitionandimagetreatmentPanel);
            app.analysisModeLbl.Text = 'Analysis mode:';
            app.LPfilterFlatFieldPxLbl = uilabel(app.AcquisitionandimagetreatmentPanel);
            app.LPfilterFlatFieldPxLbl.Text = 'LP filter flat field (px):';
            app.LPfilterEachFramePxLbl = uilabel(app.AcquisitionandimagetreatmentPanel);
            app.LPfilterEachFramePxLbl.Text = 'LP filter of each frame (px):';
            app.blobAnalyzerFiltersizeLbl = uilabel(app.AcquisitionandimagetreatmentPanel);
            app.blobAnalyzerFiltersizeLbl.Text = 'Blob analyzer. Filter size:';
            app.StrainandothernotesPanel = uipanel(app.notes);
            app.StrainandothernotesPanel.Title = 'Strain and other notes';
            app.birthDateLbl = uilabel(app.StrainandothernotesPanel);
            app.birthDateLbl.Text = 'Birth date:';
            app.ageAtExperimentTimeLbl = uilabel(app.StrainandothernotesPanel);
            app.ageAtExperimentTimeLbl.Text = 'Age at experiment time:';
            app.geneticBackgroundLbl = uilabel(app.StrainandothernotesPanel);
            app.geneticBackgroundLbl.Text = 'Genetic background:';
            app.sexLbl = uilabel(app.StrainandothernotesPanel);
            app.sexLbl.Text = 'Sex:';
            app.lightCycleLbl = uilabel(app.StrainandothernotesPanel);
            app.lightCycleLbl.Text = 'Light cycle:';
            app.notesLbl = uilabel(app.StrainandothernotesPanel);
            app.notesLbl.Text = 'Notes:';
            app.ComputationandReportingPanel = uipanel(app.notes);
            app.ComputationandReportingPanel.Title = 'Computation and Reporting';
            app.wrapActogramCB = uicheckbox(app.ComputationandReportingPanel);
            app.wrapActogramCB.Text = 'Actogram wrap around';
            app.startingfromhLabel = uilabel(app.ComputationandReportingPanel);
            app.startingfromhLabel.Text = 'starting from (h):';
            app.hourActogram = uieditfield(app.ComputationandReportingPanel, 'numeric');
            app.hourActogram.Limits = [0 24];
            app.hourActogram.ValueDisplayFormat = '%.0f';
            app.hourActogram.Value = 12;
            app.computeActogram = uibutton(app.ComputationandReportingPanel, 'push');
            app.computeActogram.ButtonPushedFcn = createCallbackFcn(app, @compActogram, true);
            app.computeActogram.Text = 'Actogram';
            app.DisplayasaheatmapCheckBox = uicheckbox(app.ComputationandReportingPanel);
            app.DisplayasaheatmapCheckBox.Text = 'Display as a heat map';
            app.BinmLabel = uilabel(app.ComputationandReportingPanel);
            app.BinmLabel.Text = 'Bin (m):';
            app.binActogram = uieditfield(app.ComputationandReportingPanel, 'numeric');
            app.binActogram.Limits = [0 24];
            app.binActogram.ValueDisplayFormat = '%.0f';
            app.binActogram.Value = 5;
            app.LogscaleCheckBox = uicheckbox(app.ComputationandReportingPanel);
            app.LogscaleCheckBox.Text = 'Log scale';
            app.wrapAroundGapLbl = uilabel(app.ComputationandReportingPanel);
            app.wrapAroundGapLbl.Text = 'Wrap around gap:';
            app.WORDreportButton = uibutton(app.ComputationandReportingPanel, 'push');
            app.WORDreportButton.ButtonPushedFcn = createCallbackFcn(app, @createWordReport, true);
            app.WORDreportButton.Text = 'WORD report';
            app.reportmatButton = uibutton(app.ComputationandReportingPanel, 'push');
            app.reportmatButton.ButtonPushedFcn = createCallbackFcn(app, @reportmatButtonPushed, true);
            app.reportmatButton.Text = 'report.mat';
            app.FourierButton = uibutton(app.notes, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.FourierButton.Tooltip = {'If you want to see the FFT plots please select Show Plot'};
            app.FourierButton.Text = 'Fourier';
            app.FFTSpeedPlotCB = uicheckbox(app.notes);
            app.FFTSpeedPlotCB.Tooltip = {'Show FFT plot for speed distribution during night and day'};
            app.FFTSpeedPlotCB.Text = 'Show Plot';
            app.FFTAreaPlotCB = uicheckbox(app.notes);
            app.FFTAreaPlotCB.Tooltip = {'Show FFT plot for Area change distribution during night and day'};
            app.FFTAreaPlotCB.Text = 'Show Plot';
            app.SummaryTable = uitable(app.notes);
            app.SummaryTable.ColumnName = {'Displacement (m)'; 'CC Expl-Groom'; 'Lag (min)'; 'Median Speed Distr'; 'Mean Speed Distr'; 'Median Resting Distr'; 'Mean Resting Distr'; 'Median DeltaPx Distr'; 'Mean DeltaPx Distr'; 'Median Slow Delta Px Distr'; 'Mean Slow Delta Px Distr'; 'Resting Grooming Ratio'; 'Resting Thr'; 'Resting Duration Ratio'; 'Mean Sleep Dur'; 'Median Sleep Dur'; 'Max Sleep Dur'; 'N of Sleep Events'; 'Time Spent Sleeping'; 'Speed FFT (times/hour)'; 'Area FFT(times/hour)'};
            app.SummaryTable.RowName = {'Day'; 'Night '; 'All Day'; ''};
            app.WriteTable = uibutton(app.notes, 'state');
            app.WriteTable.ValueChangedFcn = createCallbackFcn(app, @WriteTableValueChanged, true);
            app.WriteTable.Enable = 'off';
            app.WriteTable.Tooltip = {'Push Fourier button first, please.'; 'The table will be saved in the directory'};
            app.WriteTable.Text = 'Write Table';
            app.RefreshButton = uibutton(app.ReportUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.ReportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trajectoriesRep(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReportUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReportUIFigure)
        end
    end
end