classdef morpheus3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        uipanel1                        matlab.ui.container.Panel
        readWClist                      matlab.ui.control.Button
        WClistTxt                       matlab.ui.control.Label
        connectCam                      matlab.ui.control.Button
        getOneImage                     matlab.ui.control.Button
        acquireFlat                     matlab.ui.control.Button
        record                          matlab.ui.control.Button
        autothresholdCK                 matlab.ui.control.CheckBox
        plotTrajectoryIm                matlab.ui.control.CheckBox
        refreshImageCk                  matlab.ui.control.CheckBox
        plotTrajectoryBin               matlab.ui.control.CheckBox
        refreshBinaryImCk               matlab.ui.control.CheckBox
        SamplingperiodsEditFieldLabel   matlab.ui.control.Label
        SPtxt                           matlab.ui.control.NumericEditField
        FramesLabel                     matlab.ui.control.Label
        framesTxt                       matlab.ui.control.NumericEditField
        ArduinoButton                   matlab.ui.control.Button
        RepLabel                        matlab.ui.control.Label
        repetitions                     matlab.ui.control.NumericEditField
        refreshDeltaImageCk             matlab.ui.control.CheckBox
        plotTrajectoryDeltaCk           matlab.ui.control.CheckBox
        plotHistoCB                     matlab.ui.control.CheckBox
        dailyModeCB                     matlab.ui.control.CheckBox
        HowmanydaysLabel                matlab.ui.control.Label
        nDays                           matlab.ui.control.NumericEditField
        loadBk                          matlab.ui.control.Button
        clearIWnd                       matlab.ui.control.Button
        subBack                         matlab.ui.control.CheckBox
        CamexposureLabel                matlab.ui.control.Label
        exposure                        matlab.ui.control.NumericEditField
        whiteMiceCB                     matlab.ui.control.CheckBox
        logHistCB                       matlab.ui.control.CheckBox
        filterFlatCB                    matlab.ui.control.CheckBox
        filterFlatPx                    matlab.ui.control.NumericEditField
        filterFrameCB                   matlab.ui.control.CheckBox
        filterFramePx                   matlab.ui.control.NumericEditField
        connectCam_2                    matlab.ui.control.Button
        eraseEdgesCB                    matlab.ui.control.CheckBox
        uipanel3                        matlab.ui.container.Panel
        uipanel4                        matlab.ui.container.Panel
        override                        matlab.ui.control.CheckBox
        DayLightCB                      matlab.ui.control.CheckBox
        fanCk                           matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        DaylightButton                  matlab.ui.control.RadioButton
        FromtableButton                 matlab.ui.control.RadioButton
        onathLabel                      matlab.ui.control.Label
        lightOn                         matlab.ui.control.NumericEditField
        offatLabel                      matlab.ui.control.Label
        lightOff                        matlab.ui.control.NumericEditField
        LoadtableButton                 matlab.ui.control.Button
        uipanel5                        matlab.ui.container.Panel
        Ignoreblobssmallerthanpx2Label  matlab.ui.control.Label
        lowerLimBlobs                   matlab.ui.control.NumericEditField
        Mouseblobtypicalsizepx2Label    matlab.ui.control.Label
        mouseBlobArea                   matlab.ui.control.NumericEditField
        ThresholdunitsstddayLabel       matlab.ui.control.Label
        thrLevelDay                     matlab.ui.control.NumericEditField
        binaryClosureTxt                matlab.ui.control.NumericEditField
        blobAnalyzerCB                  matlab.ui.control.CheckBox
        closure                         matlab.ui.control.CheckBox
        ThresholdmethodLabel            matlab.ui.control.Label
        methodThr                       matlab.ui.control.DropDown
        SizeadaptivethresholdingoddpxLabel  matlab.ui.control.Label
        sizeAdaptiveThr                 matlab.ui.control.NumericEditField
        nightLabel                      matlab.ui.control.Label
        thrLevelNight                   matlab.ui.control.NumericEditField
        labelDataCB                     matlab.ui.control.CheckBox
        lblExp                          matlab.ui.control.Button
        lblRest                         matlab.ui.control.Button
        lblGrooming                     matlab.ui.control.Button
        lblDrink                        matlab.ui.control.Button
        lblOther                        matlab.ui.control.Button
        uipanel8                        matlab.ui.container.Panel
        currentPositionTxt              matlab.ui.control.Label
        speedTxt                        matlab.ui.control.Label
        cycleDuration                   matlab.ui.control.Label
        sequenceBeginning               matlab.ui.control.Label
        currentImage                    matlab.ui.control.UIAxes
        deltaImage                      matlab.ui.control.UIAxes
        segmentedImage                  matlab.ui.control.UIAxes
        grayDistribution                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        WClist              % list of available web cams
        WCnumber            % number of available cams
        cam                 % web vam object
        hpw                 % handle to preview window
        background
        imObj
        binObj
        deltaObj

        scaleFactor
        height
        width

        currentFrame
        currentFrameBinary
        newFrame
        newFrameBinary
        flatDay             % flat field for the day...
        flatDayBk
        flatNight           % and the night
        flatNightBk
        kernelFlt           % kernel of frame filter        
        deltaFrame
        initialArea

        npoints             % number of points in the stored trajectory
        trajectory          % structure holding the trajectory data

        period
        framesN
        repeatN
        repeat
        dayCnt              % day counter when in daily mode
        currentLabel
        arduinoBoard        % Arduino object
        beginTime           %
        lightTransition

        hBlob               % system object Blob analyzer
        mask                % mask used to clear the smaller blobs from the binary image
        bA                  % structure holding the blob analyzer output
        whereAmI            % string array holding infos on the program  flow
        manualStop          % boolean. If "stop recording" is pressed, it takes 1 until the acquisition timer stops
        lastFrames          % 3d matrix with dimensions 3*y*x, where 3 is the number of last frames saved.
        outdir              % output directory
        dayLightsTable      % .xlsx table that drives light switches
        dayLightsTable_folder % source directory of the .xlsx table
    end
    
    properties (Access = private)
        medianNow
        thr
        timeLapse           % timer object
        timeLapseFlag
    end
    
    methods (Access = private)
        function acquireBackground(app)
            % grab a background image 480x640 uint8

            [frameNow, newFrBin] = shootAndBinarize (app);
            
            % get the background image
            app.background = frameNow;

            [app.height, app.width] = size (app.background);
            
            % create the handles for the output images
            colormap(app.currentImage,'Gray');
            cla(app.currentImage);
            app.imObj = imagesc (app.currentImage, app.background);
            set(app.currentImage,'XTickLabel',[])
            set(app.currentImage,'YTickLabel',[])
            axis (app.currentImage,[0 app.width 0 app.height]);
            
            cla(app.segmentedImage);
            app.binObj = imagesc (app.segmentedImage, newFrBin);
            set(app.segmentedImage,'XTickLabel',[])
            set(app.segmentedImage,'YTickLabel',[])
            axis (app.segmentedImage,[0 app.width 0 app.height]);
            
            cla(app.deltaImage);
            app.deltaObj = imagesc (app.deltaImage, app.background);
            set(app.deltaImage,'XTickLabel',[])
            set(app.deltaImage,'YTickLabel',[])
            axis (app.deltaImage,[0 app.width 0 app.height]);            
        end
        
        function [md, stdim, thr] = computeAndPlotHisto(app, im, thrmtp)
            
            % This function computes the gray level distribution of the input image im
            % computes the current statistics and the threshold
            % the distribution is plotted together with the median and threshold value
            % INPUT ARGUMENTS
            % im: input image
            % thrmtp: mutiplier for the coputation of the luminance threshold
            % OUTPUT ARGUMENTS
            % md: median of luminance distribution
            % stdim: standard deviation
            % thr: threshold for image binarisation (to be normalized to 255 before im2bin)
            
            % compute descriptive statistics
            md = median(median(im));
            stdim = ceil(std(std(double(im))));
            thr = double(md - thrmtp * stdim);
            
            if app.plotHistoCB.Value
                cla(app.grayDistribution);
                [pixelCount, grayLevels] = imhist(im);
                bar (app.grayDistribution,pixelCount);
                set (app.grayDistribution,'XTickLabel',[])
                set (app.grayDistribution,'YTickLabel',[])
                if app.logHistCB.Value
                    set (app.grayDistribution,'YScale','log')
                else
                    set (app.grayDistribution,'YScale','lin')
                end
                xlim (app.grayDistribution,[0 255]);
                grid (app.grayDistribution, 'on');
                
                % Show the threshold as a vertical red bar on the histogram.
                hold (app.grayDistribution,'on');
                maxYValue = app.grayDistribution.YLim;
                line(app.grayDistribution, [thr, thr], maxYValue, 'Color', 'r');
                line(app.grayDistribution, [md, md], maxYValue, 'Color', 'g');
                
                % Place a text label on the bar chart showing the threshold.
                annotationText = sprintf('Threshold at %d', thr);
                % For text(), the x and y need to be of the data class "double" so let's cast both to double.
                text(app.grayDistribution, double(thr - 20), ...
                    double(0.85 * maxYValue(2)), annotationText, 'FontSize', 6, 'Color', [1 0 0]);
                text(app.grayDistribution, double(thr - 40), double(0.94 * maxYValue(2)),...
                    'Mouse', 'FontSize', 6, 'Color', [0 0 .5]);
                text(app.grayDistribution, double(thr + 50), double(0.94 * maxYValue(2)),...
                    'Background', 'FontSize', 6, 'Color', [0 0 .5]);
            end
        end
        
        function computeMousePosition(app, im)
            % This function computes the center of gravity of the mouse, the
            % displacement and the fractional area change.
            xmouse = 0;
            ymouse = 0;
            mouseArea = 0;
            % From here the processing is different depending on the use of the blob analyzer
            if app.blobAnalyzerCB.Value
                if ~isempty(app.bA.centrioid)
                    ymouse = 480 - app.bA.centrioid(2);
                    xmouse = app.bA.centrioid(1);
                    mouseArea = app.bA.area;           % the mouse area is provided by the number of pixels set to 0
                end
            else
                [rw, clm] = find(im<1);
                ymouse = app.height - int16(mean(rw));
                xmouse = int16(mean(clm));
                mouseArea = length(rw);            % the mouse area is provided by the number of pixels set to 0
            end
            app.whereAmI = 'comp muse position delta';
            app.trajectory.x(app.npoints,1) = xmouse;
            app.trajectory.y(app.npoints,1) = ymouse;
            if (app.npoints>1)               % not the first frame!
                deltaX = app.trajectory.x(app.npoints)-app.trajectory.x(app.npoints-1);
                deltaY = app.trajectory.y(app.npoints)-app.trajectory.y(app.npoints-1);
                app.trajectory.d(app.npoints,1) = hypot(deltaX,deltaY);
                app.trajectory.l(app.npoints,1) = app.trajectory.l(app.npoints-1) ...
                    + app.trajectory.d(app.npoints);
            else
                app.trajectory.d(app.npoints,1) = 0;
                app.trajectory.l(app.npoints,1) = 0;
                app.initialArea = mouseArea;
            end
            app.trajectory.deltaA(app.npoints,1) = (mouseArea-app.initialArea)/app.initialArea;
        end
        
              
        function initializeOutputStruct(app)
            
            % preallocate all arrays
            
            app.npoints = 0;
            app.trajectory.x = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.y = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.l = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.d = zeros(app.repeatN * app.framesN, 1);
            
            app.trajectory.deltaA = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.deltaPx = zeros(app.repeatN * app.framesN, 1);
            
            app.trajectory.year = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.month = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.day = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.hour = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.min = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.sec = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.secFromStart = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.environment = zeros(app.repeatN * app.framesN, 1);    
            app.trajectory.label = zeros(app.repeatN * app.framesN, 1);
            
            % Initialize empty description fields
            if ~isfield(app.trajectory,'sex')
                app.trajectory.sex = '';
                app.trajectory.geneticBackground = '';
                app.trajectory.lightCycle = '';
                app.trajectory.notes = '';
                app.trajectory.birthdate = datetime;
            end            
        end
        
        
        function plotTrajectory(app)
            
            % This function plots the trajectory on the selected axes
            % December 2019. The plot is restricted to the latest new
            % segment.
            
            st = app.npoints-1;
            if (st < 1), st=1;
            end
            if app.plotTrajectoryIm.Value
                hold (app.currentImage,'on');
                plot (app.currentImage,app.trajectory.x(st:st+1),app.height - app.trajectory.y(st:st+1),'color','r');
                axis (app.currentImage,[0 app.width 0 app.height]);
                hold (app.currentImage,'off');
            end
            
            if app.plotTrajectoryBin.Value
                hold (app.segmentedImage,'on');
                plot (app.segmentedImage,app.trajectory.x(st:st+1),app.height - app.trajectory.y(st:st+1),'color','r');
                axis (app.segmentedImage,[0 app.width 0 app.height]);
                hold (app.segmentedImage,'off');
            end

            if app.plotTrajectoryDeltaCk.Value
                hold (app.deltaImage,'on');
                plot (app.deltaImage,app.trajectory.x(st:st+1),app.height - app.trajectory.y(st:st+1),'color','r');
                axis (app.deltaImage,[0 app.width 0 app.height]);
                hold (app.deltaImage,'off');
            end
        end
        
        function [newFr, newFrBin] = shootAndBinarize(app)
            % This fuction shoot a new image and binarizes it
            
            app.whereAmI = 'entering SandB';
            frameNow =  get (app.hpw,'CData');
            newFr = rgb2gray(frameNow);
            if app.subBack.Value
                app.whereAmI = 'Flat fielding';
                if app.DayLightCB.Value
                    %newFr = uint8(newFr - app.imD);
                    newFr = double(newFr) ./ app.flatDay;
                else
                    %newFr = uint8(newFr - app.imIR);
                    newFr = double(newFr) ./ app.flatNight;
                end
            end
            if app.filterFrameCB.Value
                % low pass filter of the frame
                newFr = imfilter(newFr,app.kernelFlt);
            end
            newFr = uint8(newFr);
            if app.whiteMiceCB.Value
                % invert the BW image
                newFr = uint8(255) - newFr;
            end
            app.whereAmI = 'acq completed';
            
            % February 6, 2020. Modified by adding some options to the
            % binarization process.
            
            switch app.methodThr.Value
                case '1'      % Threshold
                    % app.medianNow is 0 when the program is started
                    if (app.autothresholdCK.Value || app.medianNow==0)
                        if app.DayLightCB.Value, thrX = app.thrLevelDay.Value;
                        else
                            thrX = app.thrLevelNight.Value;
                        end
                        [md, stdim, thrgray] = computeAndPlotHisto(app, newFr, thrX);
                        app.medianNow = md;
                        app.thr = thrgray/255;
                        if (app.thr < 0.05), app.thr=0.05;
                        end
                    end
                    newFrBin = imbinarize(newFr,app.thr);
                case '2'      % Otsu threshold
                    newFrBin = imbinarize(newFr);
                case '3'      % Otsu threshold adaptive
                    T = adaptthresh(newFr,'NeighborhoodSize',app.sizeAdaptiveThr.Value);
                    newFrBin = imbinarize(newFr,T);
            end
            newFrBin = ~newFrBin;
            app.whereAmI = 'binarization completed';
            if app.eraseEdgesCB.Value
                % Clear the top and bottom stripes of the binary image
                newFrBin (1:65,1:640) = 0;
                newFrBin (405:480,1:640) = 0;
            end
            
            if app.closure.Value
                closureSize = app.binaryClosureTxt.Value;
                if (closureSize>0)
                    % create the structure element
                    SE = strel('disk',closureSize);
                    %newFrBin = imdilate(newFrBin,SE);
                    newFrBin = imerode(newFrBin,SE);
                end
            end
            % gab add dilation
            if true
                dilSize = 15;
                eroSize = 7;
                if (dilSize>0)
                    % create the structure element
                    SE = strel('disk',dilSize);
                    %newFrBin = imdilate(newFrBin,SE);
                    newFrBin = imdilate(newFrBin,SE);
                    
                    SE = strel('disk',eroSize);
                    newFrBin = imerode(newFrBin,SE);
                end
            end
            app.whereAmI = 'shootAndBin completed';
        end
        
        function timeLapseHandlerIn(app)
            % The timer call backs are nested in the main body so they share the
            % workspace with the main function.
            tic
            % Create the object timer for the control of time lapse
            % gab prova 20 june 2022
            timerList = timerfindall;
            delete(timerList(:));

            app.timeLapse = timer;
            app.timeLapse.BusyMode = 'drop';                   % drop missed events
            app.timeLapse.ExecutionMode = 'FixedRate';         % the code is executed immediately at each timer event
            app.timeLapse.TasksToExecute = app.framesN;        % number of frames in each repeat
            app.timeLapse.Period = app.period ;                % period of timer events
            app.timeLapse.StartDelay = app.period;                      % wait 0 sec before starting
            
%             app.timeLapse.TimerFcn = {@timeLapseCallback};     % pointer to the function evoked at each timer event
            app.timeLapse.TimerFcn = {@(x,y)(timeLapseCallback(app))}; % GAB: pointer to the function evoked at each timer event. Since we pass app to it,
                                % we do not have to set app as a global
                                % variable, which is not recommended.
%             app.timeLapse.StopFcn = {@stopTimeLapseCallback}; % pointer to the function evoked at the end of the time lapse seq
            app.timeLapse.StopFcn = {@(x,y)stopTimeLapseCallback(app)};  % GAB: the same as before
                        
            % initialize and acquire a first frame
            app.dayCnt = app.nDays.Value;
            initializeOutputStruct(app)
            [frameNow, newFrBin] = shootAndBinarize (app);
            cla(app.currentImage);
            app.imObj = imagesc (app.currentImage, frameNow);
            cla(app.segmentedImage);
            app.binObj = imagesc (app.segmentedImage,newFrBin);
            
            % start the timer
            start(app.timeLapse);
            app.beginTime = clock;
            
            % functions within timeLapseHandler and within its scope
            
            function beginTimeLapse (~,~)
                % This function initializes the time lapse sequence and the output data set
                % No image is acquired. REMEMBER the image size must be determined at connection time
            end
            
            function stopTimeLapseCallback (app)
%             function stopTimeLapseCallback (~,~)
                %'stop'
                % Time to write down the last bits and save the trajectory structure, I guess.
                app.trajectory.nightThr = app.thrLevelNight.Value;
                app.trajectory.dayThr = app.thrLevelDay.Value;
                app.trajectory.flagFlat = app.subBack.Value;
                app.trajectory.flagFlatLP = app.filterFlatCB.Value;
                app.trajectory.flatLP = app.filterFlatPx.Value;
                app.trajectory.flagFltFr = app.filterFlatCB.Value;
                app.trajectory.filterFrPix = app.filterFramePx.Value;
                app.trajectory.exp = app.exposure.Value;
                app.trajectory.segMode = app.methodThr.Items{str2num(app.methodThr.Value)};
                app.trajectory.flagBlob = app.blobAnalyzerCB.Value;
                app.trajectory.flagIgnore = app.lowerLimBlobs.Value;
                app.trajectory.flagMouse = app.mouseBlobArea.Value;
                           
                newStr = app.trajectory;        % apparently the function save cannot extract the subfield directly from app.trajectory
                
                % Create the file name using the  YY-MM-DD of the beginning of the record
                y = app.trajectory.year(1,1);
                m = app.trajectory.month(1,1);
                d = app.trajectory.day(1,1);
                dtS = datestr(datetime(y,m,d));
                
%                 fileName = sprintf(['morpheus ' dtS ' %04.0f.mat'], app.repeat);
                date_str = datestr(clock);
                fileName = sprintf(['morpheus %s %04.0f.mat'], strrep(date_str,':','-'), app.repeat); % gab: do not overwrite multiple files in a day
                save([app.outdir filesep fileName],'-struct','newStr');
                if app.dailyModeCB.Value
                    if (app.npoints < app.framesN) && ~app.manualStop
                        % unexpected error condition. Restart timer with
                        % proper number of events and output debug infos
                        app.whereAmI
                        app.timeLapse.TasksToExecute = app.framesN-app.npoints;
                        fprintf('Restart due to an unexpected error at %s', strrep(datestr(clock),':','-'))
                        start(app.timeLapse);
                        
                    elseif app.manualStop
                        disp('Manual stop')
                        app.timeLapseFlag = 0;
                        listOfTimers = timerfindall;
                        delete(listOfTimers)
                        disp('Timer stopped manually')
                    else
                        % we are in daily mode, so lets countdown the elapsed days
                        % Save the day
                        app.dayCnt = app.dayCnt - 1;
                        if app.dayCnt <= 0
                            % end of the daily sequence
                            app.record.Text = 'Record';
                            app.timeLapseFlag = 0;
                            listOfTimers = timerfindall;
                            delete(listOfTimers)
                            clear listOfTimers
                            fprintf('End of the recording at %s.\n',datestr(clock))
                        else
                            % reinitialize the whole lot and restart the timer
                            app.repeat = 1;
                            initializeOutputStruct(app)
                            start(app.timeLapse);
                            fprintf('End of the day at %s.\nGoing on recording...\n',datestr(clock))

                        end
                    end
                else
                    app.repeat = app.repeat + 1;
                    if app.repeat > app.repeatN
                        app.record.Text = 'Record';
                        app.timeLapseFlag = 0;
                        delete(app.timeLapse)
                        clear app.timeLapse
                    else
                        start(app.timeLapse);
                    end
                end
            end
            
%             function timeLapseCallback (~,~)
            function timeLapseCallback (app)
                tic
                %'inside time lapse handler: shootAndBinarize'
                [frameNow, newFrBin] = shootAndBinarize (app);
                app.whereAmI = 'starting time apse callback';
                nowIs = clock;      % this is a numeric array containing date and time
                % year month day hour minute seconds
                nowIsDT = datetime(nowIs);
                % toc   ASUS 41 ms
                % drawnow;
                
                % at this stage the image should be further processed to remove small objects,
                % either by erosion/dilation or by blob analysis followed by image cleaning.
                
                
                app.newFrame = frameNow;
                % update lastFrames
                app.lastFrames = circshift(app.lastFrames,-1);
                app.lastFrames(1,:,:) = frameNow;
                % check if last frames are all the same
                areEqual = all(isequal(app.lastFrames(1,:,:),app.lastFrames(2,:,:),app.lastFrames(3,:,:)));
                if areEqual
                    fprintf('The previw stopped at %s, since the data stream from the webcam stopped.\nClosing the camera...\n',datestr(clock))
                    % close the previw and disconnect the camera
                    connectCam_Callback(app, [])
                    fprintf('Camera disconnected successfully...\n')
                    
                    pause(20)
                    
                    fprintf('Connecting the camera...\n')
                    % connect th camera and start the previw again
                    connectCam_Callback(app, [])
                    fprintf('Camera successfully re-connected.\nWaiting 20 s...\n')
                    pause(20)
                    fprintf('Going on with the timer\n')
                    
                end
                
                app.whereAmI = 'starting blob analyzer';
                if app.blobAnalyzerCB.Value
                    % obtain the centrioid, area and boundaries of the mouse blob
                    [app.bA.area,app.bA.centrioid,app.bA.bbox] = app.hBlob(newFrBin);      % process the binary image through the blob analyzer
                    % lets clear the binary image away from the bounding box
                    app.whereAmI = 'mask bin image';
                    newMask = app.mask;
                    if ~isempty(app.bA.bbox)
                        % check that the mask is not larger than the image
                        maskRow = app.bA.bbox(2)+app.bA.bbox(4)-1;
                        if maskRow>480, maskRow=480;
                        end
                        maskC = app.bA.bbox(1)+app.bA.bbox(3)-1;
                        if maskC>640, maskC=640;
                            'here n'
                        end
                        newMask(app.bA.bbox(2):maskRow, app.bA.bbox(1):maskC) = 1;
                        newFrBin = newFrBin .* newMask;
                    end
                end
                app.newFrameBinary = ~newFrBin;
                app.whereAmI = 'compute delta pX';
                % compute the difference with the previous frame
                if (app.npoints == 0)
                    app.deltaFrame = app.newFrameBinary - app.newFrameBinary;
                else
                    app.deltaFrame = abs(app.newFrameBinary - app.currentFrameBinary);
                end
                app.currentFrame = app.newFrame;
                app.currentFrameBinary = app.newFrameBinary;
                
                if get(app.refreshImageCk,'Value'), set(app.imObj,'cData',app.currentFrame);
                end
                
                if get(app.refreshBinaryImCk,'Value'), set(app.binObj,'cData',app.currentFrameBinary);
                end
                
                if get(app.refreshDeltaImageCk,'Value'), set(app.deltaObj,'cData',app.deltaFrame);
                end
                
                % Increment the frame number
                app.npoints = app.npoints + 1;
                
                % the next stm also computes displacement and fractional area change.
                app.whereAmI = 'compute mouse position';
                computeMousePosition (app, app.currentFrameBinary);
                app.whereAmI = 'trajectory fields';
                % 'Image acq, display and analysis'
                % toc       92 ms
                app.trajectory.year(app.npoints,1) = nowIs(1);
                app.trajectory.month(app.npoints,1) = nowIs(2);
                app.trajectory.day(app.npoints,1) = nowIs(3);
                app.trajectory.hour(app.npoints,1) = nowIs(4);
                app.trajectory.min(app.npoints,1) = nowIs(5);
                app.trajectory.sec(app.npoints,1) = nowIs(6);
                app.trajectory.secFromStart(app.npoints,1) = etime(nowIs,app.beginTime);
                
                % Detect light/darkness transitions
                past = find(app.lightTransition.dt<nowIsDT);
                lastTransition = app.lightTransition.onOff(past(end));      % state: 1= light on, 0 off
                
                app.DayLightCB.Value = lastTransition;
                if ~app.override.Value
                    if ~isempty(app.arduinoBoard)   % Arduino is connected
                        writeDigitalPin(app.arduinoBoard,'D2',lastTransition);
                    end
                    app.trajectory.environment(app.npoints,1) = double(app.DayLightCB.Value);
                end
                app.trajectory.stateLabel(app.npoints,1) = app.currentLabel;    % write the state label
                % on line analysis and output
                plotTrajectory (app)
                app.whereAmI = 'out status';
                txtOut = sprintf('Frame: %d Rep: %d Day: %d X: %d Y: %d', app.npoints, ...
                    app.repeat, app.dayCnt, round(app.trajectory.x(app.npoints)), round(app.trajectory.x(app.npoints)));
                app.currentPositionTxt.Text = txtOut;
                app.trajectory.deltaPx(app.npoints) = sum(sum(app.deltaFrame));

                speed = app.scaleFactor * ...
                    app.trajectory.d(app.npoints) / app.period;  % Instantaneous speed
                txtOut = sprintf('Instantaneous speed: %.2f (cm/s)', speed);
                app.speedTxt.Text = txtOut;

                txtOut = sprintf('delta Pixel: %d', app.trajectory.deltaPx(app.npoints));
                app.speedTxt.Text = txtOut;                
                cd = toc;       % 152 ms
                app.cycleDuration.Text = sprintf ('Cycle duration (s): %0.3f',cd);
                
%                 if app.manualStop
%                     stop(app.timeLapse);
%                 end
                
                drawnow;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function morpheus_OpeningFcn(app)

            app.timeLapseFlag = 0;
            app.trajectory.version = 'Morpheus 3.0';

            app.scaleFactor = 1/12.5;     % image scale = 1 / n pixels x 1 cm
            
            % initialize axes properties
            set(app.currentImage,'XTickLabel',[])
            set(app.currentImage,'YTickLabel',[])
            set(app.deltaImage,'XTickLabel',[])
            set(app.deltaImage,'YTickLabel',[])
            set(app.segmentedImage,'XTickLabel',[])
            set(app.segmentedImage,'YTickLabel',[])
            set (app.grayDistribution,'XTickLabel',[])
            set (app.grayDistribution,'YTickLabel',[])
            
            
        end

        % Button pushed function: connectCam
        function connectCam_Callback(app, event)
            
            if ~isempty(app.cam)
                closePreview(app.cam);
                delete (app.cam);
                app.connectCam.Text = 'Connect';
                app.cam=[];
            else
                if isempty(app.WCnumber)
                    % if the web cam list does not exist create it!
                    app.WClist = webcamlist;
                    app.WCnumber = length(app.WClist);
                    app.WClistTxt.Text = app.WClist;
                end
                app.cam = webcam(1); %app.WCnumber
                app.cam.Resolution = '640x480';
                
                % initialize lastFrames property
                app.lastFrames = nan(3,480,640);
                
                app.connectCam.Text = 'Disconnect';
                
                % now start the preview mode and create the relative handle
                app.hpw = preview(app.cam);
                
                % get one image to identify frame size
                acquireBackground(app)
            end
        end

        % Value changed function: fanCk
        function fanCk_Callback(app, event)
            % hObject    handle to fanCk (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of fanCk
        end

        % Button pushed function: getOneImage
        function getOneImage_Callback(app, event)
            
            % Get the first frame of a manually acquired sequence.
            
            [frameNow, newFrBin] = shootAndBinarize (app);
            
            app.mask = 0 & newFrBin;    % here we define app.mask as a logical array of the same size of the frame and containing 0 only
            
            if app.blobAnalyzerCB.Value
                % obtain the centrioid, area and boundaries of the mouse blob
                [app.bA.area,app.bA.centrioid,app.bA.bbox] = app.hBlob(newFrBin);      % process the binary image through the blob analyzer
                % lets clear the binary image away from the bounding box
                newMask = app.mask;
                if ~isempty(app.bA.bbox)
                    newMask(app.bA.bbox(2):app.bA.bbox(2)+app.bA.bbox(4), ...
                        app.bA.bbox(1):app.bA.bbox(1)+app.bA.bbox(3)) = 1;
                    newFrBin = newFrBin .* newMask;
                end
            end
            app.currentFrame = frameNow;
            app.currentFrameBinary = ~newFrBin;
            
            set(app.imObj, 'CData', app.currentFrame);
            set(app.binObj, 'CData', app.currentFrameBinary);
            %axis (app.currentImage,[0 app.width 0 app.height]);
            
            % let's compute center of gravity of the mouse
            % this is the beginning of the trajectory. Reset the trajectory
            app.npoints = 1;
            app.trajectory.x = [];
            app.trajectory.y = [];
            app.trajectory.l = [];
            app.trajectory.d = [];
            app.trajectory.deltaA = [];
            
            computeMousePosition (app, app.currentFrameBinary);
            
            nowIs = clock;      % this is a numeric array containing date and time
            % year month day hour minute seconds
            app.trajectory.day(app.npoints) = nowIs(3);
            app.trajectory.hour(app.npoints) = nowIs(4);
            app.trajectory.min(app.npoints) = nowIs(5);
            app.trajectory.sec(app.npoints) = nowIs(6);
            
            app.trajectory.deltaA(app.npoints) = (app.initialArea-app.initialArea)/app.initialArea;
        end

        % Button pushed function: acquireFlat
        function acquireFlat_Callback(app, event)
            % Acquire flat field in both night and day configurations
            
            app.acquireFlat.BackgroundColor = [0,1,0];
            if ~isempty(app.arduinoBoard)   % Arduino is connected
                writeDigitalPin(app.arduinoBoard,'D2',1);
            end
            
            pause(2)
            frameNow =  get (app.hpw,'CData');
            newFr = double(rgb2gray(frameNow));
            for i=1:1999
                frameNow =  get (app.hpw,'CData');
                newFr = double(rgb2gray(frameNow)) + newFr;
            end
            newFr = newFr/2000;
            %newFr = median(newFr);
            set(app.imObj, 'CData', newFr);
            m = mean(mean(newFr));
            app.flatDay = newFr / m;
            app.acquireFlat.BackgroundColor = [0,1,1];
            if ~isempty(app.arduinoBoard)   % night
                writeDigitalPin(app.arduinoBoard,'D2',0);
            end
            pause (2)
            frameNow =  get (app.hpw,'CData');
            newFr = double(rgb2gray(frameNow));
            for i=1:1999
                frameNow =  get (app.hpw,'CData');
                newFr = double(rgb2gray(frameNow)) + newFr;
            end
            newFr = newFr/2000;
            set(app.imObj, 'CData', newFr);
            m = mean(mean(newFr));
            app.flatNight = newFr / m;

            % save the flat fields with their default name in the current directory
            FN = app.flatNight;
            save('flatNight.mat', 'FN');
            FD = app.flatDay;
            save('flatDay.mat', 'FD');
            app.acquireFlat.BackgroundColor = [0.96,0.96,0.96];
            app.flatDayBk = app.flatDay;
            app.flatNightBk = app.flatNight;
        end

        % Value changed function: plotTrajectoryBin
        function plotTrajectoryBin_Callback(app, event)
            
            % if(app.plotTrajectoryBin.Value),
            %    app.trjBin = gobjects;
            % else
            %    if ishandles(app.trjBin), delete(app.trjBin);
            %    end
            % end
            % guidata(hObject,handles)
        end

        % Button pushed function: readWClist
        function readWClist_Callback(app, event)
            app.WClist = webcamlist;
            app.WCnumber = length(app.WClist);
            app.WClistTxt.Text = app.WClist;
        end

        % Button pushed function: record
        function record_Callback(app, event)
            % First: check if the timer is already running
            if (app.timeLapseFlag)
                % it is running. Stop and kill the timer
                app.manualStop=1;
                pause(5)
                % gab prova 20 june 2022
                listOfTimers = timerfindall;            % just to make sure
                stop(listOfTimers(:));                  % stop them all
                app.record.Text = 'Record';
                delete (listOfTimers(:));
                app.timeLapseFlag = 0;
                
            else
                % Prepare the time lapse mode.
                newdir = uigetdir(app.outdir,'Select output directory');
                if isa(newdir,'double')
                    return
                else
                    app.outdir = newdir;
                end
                app.manualStop=0; % not stopped manually (yet)
                app.record.Text = 'STOP';
                app.timeLapseFlag = 1;
                app.period = app.SPtxt.Value;  % this is the frame period in sec.
                app.repeat = 1;
                
                % now prepare the repetion modes and the light on/off array
                
                % Compute total duration of acquisition based on the value of daily mode
                nowT = datetime;            % datetime at the beginning
                app.sequenceBeginning.Text = ['Started on:' datestr(nowT)];
                nowT = nowT - days(1);      % let's go back one day in time. This ensure that all possible transitions are accounted for
                runningDay = nowT;
                cnt = 0;
                
                selectedButton = app.ButtonGroup.SelectedObject;
                switch selectedButton
                    
                    case app.DaylightButton % standard mode: light/dark transitions at the same time every day
                        minNow = 0;        % Light on off always at the hour sharp!
                        secNow = 0;
                        if app.dailyModeCB.Value
                            % in this modality each acquisition set lasts
                            % 24 hrs and it is repeated for nDays. Each
                            % episode lasts 1 day
                            
                            % on-the-fly check of timing
                            app.repeatN = 1;
                            %app.nDays.Value = 1;
                            app.repetitions.Value = 1;
                            app.framesN = 3600/app.period * 24;  % this is the frame number for one day.
                            app.framesTxt.Value = app.framesN;
                            %app.totalDuration =
                            % create light on light off daytime arrays
                            for i=1:app.nDays.Value+2   % + 2 to add one day at the beginning and 1 day at the end of the recording
                                % compute the transitions for each day of the sequence. In this way the code will work also on Dec 31
                                yearNow = year(runningDay);
                                monthNow = month(runningDay);
                                dayNow = day(runningDay);
                                cnt = cnt + 1;
                                if app.lightOn.Value < app.lightOff.Value    % transitions are ordered as increasing datetimes
                                    app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOn.Value, minNow, secNow);
                                    app.lightTransition.onOff(cnt) = 1;     % light on
                                    cnt = cnt + 1;
                                    app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOff.Value, minNow, secNow);
                                    app.lightTransition.onOff(cnt) = 0;     % light off
                                else
                                    app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOff.Value, minNow, secNow);
                                    app.lightTransition.onOff(cnt) = 0;     % light off
                                    cnt = cnt + 1;
                                    app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOn.Value, minNow, secNow);
                                    app.lightTransition.onOff(cnt) = 1;     % light on
                                end
                                runningDay = runningDay + days(1);  % add 1 day to the runtime
                            end
                        else
                            % compute the duration of the recording in ceil(days)
                            %                             app.framesN = app.framesTxt.Value;  % this is the frame number.
                            app.framesN = 3600/app.period * 24 * app.nDays.Value; % gab: fixed it.
                            app.repeatN = app.repetitions.Value;
                            nd = ceil(app.framesN * app.period/86400);
                            for i=1:nd+2           % nd + 2 will cover any reasonable period in non-daily mode.
                                % compute the transitions for each day of the sequence
                                yearNow = year(runningDay);
                                monthNow = month(runningDay);
                                dayNow = day(runningDay);
                                cnt = cnt + 1;
                                app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOn.Value, minNow, secNow);
                                app.lightTransition.onOff(cnt) = 1;     % light on
                                cnt = cnt + 1;
                                app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOff.Value, minNow, secNow);
                                app.lightTransition.onOff(cnt) = 0;     % light off
                                runningDay = runningDay + days(1);  % add 1 day to the runtime
                            end
                        end
                        
                    case app.FromtableButton % light/dark transition times come from a table
                        % a file with all the transitions is already
                        % stored in app.dayLightsTable. Just check the
                        % Compute the duration from the first to the
                        % first and the last transition:
                        
                        % start with the last transition. Compute the
                        % amount of time from now to the
                        % last transition. The recording should last
                        % for an integer number of days!
                        dur = app.lightTransition.dt(end)-nowT;
                        [h,min,~]=hms(dur); % convert duration to doubles
                        n_hours = ceil((min+h*24)/24);
                        n_days = ceil(n_hours/24); % plan the recording for an integer number of days
                        app.nDays.Value = n_days;
                        endtime = datetime+duration(n_days*24,0,0);
                        if endtime>app.lightTransition.dt(end)
                            % add a "fake" transition after the end of
                            % the recording
                            app.lightTransition.dt = [app.lightTransition.dt,endtime+duration(1,0,0)];
                            app.lightTransition.onOff = [app.lightTransition.onOff,double(~app.lightTransition.onOff(end))];
                        end
                        
                        %  now check the first transition
                        if nowT<app.lightTransition.dt(1)
                            % add a date in the past as first
                            % transition:
                            app.lightTransition.dt = [nowT-duration(1,0,0), app.lightTransition.dt];
                            app.lightTransition.onOff = [double(~app.lightTransition.onOff(1)), app.lightTransition.onOff];
                        end
                        
                        if app.dailyModeCB.Value
                            % in this modality each acquisition set lasts
                            % 24 hrs and it is repeated for nDays. Each
                            % episode lasts 1 day
                            
                            % on-the-fly check of timing
                            app.repeatN = 1;
                            app.repetitions.Value = 1;
                            app.framesN = 3600/app.period * 24;  % this is the frame number for one day.
                            app.framesTxt.Value = app.framesN;
                            
                        else
                            % compute the duration of the recording in ceil(days)
                            %                             app.framesN = app.framesTxt.Value;  % this is the frame number.
                            app.framesN = 3600/app.period * 24 * app.nDays.Value; % gab: fixed it.
                            app.framesTxt.Value = app.framesN;
                        end
                end
                app.currentLabel = 0;       % default value for state labelling
                % Start the time lapse acquisition and real time processing
                timeLapseHandlerIn (app)
            end
        end

        % Button pushed function: ArduinoButton
        function connectArduino(app, event)
            % December 12, 2019
            % Connection to a single Arduino board for the control of the
            % light cycle.
            if isempty(app.arduinoBoard)
                app.arduinoBoard = arduino;
                % initialize digital pin 1
                writeDigitalPin(app.arduinoBoard,'D2',0);
                app.ArduinoButton.Text ='Disconnect A';
                disp('Arduino connected.')
            else
                fclose(serial(app.arduinoBoard.Port));
                clear app.arduinoBoard;
                app.arduinoBoard = [];
                app.ArduinoButton.Text ='Arduino';
                disp('Arduino disconnected.')
            end
        end

        % Value changed function: DayLightCB
        function toggleDayLight(app, event)
            value = app.DayLightCB.Value;
            if ~isempty(app.arduinoBoard)   % Arduino is connected
                if value
                    writeDigitalPin(app.arduinoBoard,'D2',1);
                else
                    writeDigitalPin(app.arduinoBoard,'D2',0);
                end
            end
        end

        % Value changed function: subBack
        function loadBack(app, event)
            %             value = app.subBack.Value;
            %             % load the IR and day backgroun images
            %             backIR = open('flatIR.mat');
            %             backD = open('flatDay.mat');
            %             app.imIR = double(backIR.newFr);
            %             app.imD = double(backD.newFr);
            %             % normalize the background images for divisive correction
            %             m = mean(mean(app.imIR));
            %             app.imIR = app.imIR / m;
            %             m = mean(mean(app.imD));
            %             app.imD = app.imD / m;
            
        end

        % Value changed function: exposure
        function camExposure(app, event)
            value = app.exposure.Value;
            % override the cam exposure
            app.cam.Exposure = value;
        end

        % Value changed function: blobAnalyzerCB
        function openVBobject(app, event)
            value = app.blobAnalyzerCB.Value;
            % Creates or clear the Vision BlobAnalyzer with the current parameters
            if value
                % create hBlob
                app.hBlob = vision.BlobAnalysis( ...
                    'AreaOutputPort', true, ...
                    'PerimeterOutputPort', false, ...
                    'BoundingBoxOutputPort', true, ...
                    'OutputDataType', 'single', ...
                    'MinimumBlobArea', app.lowerLimBlobs.Value, ...
                    'MaximumBlobArea', 3*app.mouseBlobArea.Value, ...
                    'MaximumCount', 1);
            else
                delete(app.hBlob);
            end
        end

        % Button pushed function: loadBk
        function loadBackFiles(app, event)
            % This callback loads the latestnormalized flat fields acquired
            % by Morpheus. Caution must be used when using old flat fields.
            
            % load the IR and day background images
            fin = open('flatNight.mat');
            app.flatNight = fin.FN;
            fin = open('flatDay.mat');
            app.flatDay = fin.FD;
            app.flatNight = double(app.flatNight);
            app.flatDay = double(app.flatDay);
            app.flatNightBk = app.flatNight;
            app.flatDayBk = app.flatDay;
        end

        % Button pushed function: clearIWnd
        function clearIWndWindows(app, event)
            % March 6, 2020
            % This call back clear the content of all image windows including the trajectories.
            app.imObj.HandleVisibility = 'off';
            app.binObj.HandleVisibility = 'off';
            app.deltaObj.HandleVisibility = 'off';
            cla(app.currentImage);
            cla(app.deltaImage);
            cla(app.segmentedImage);
            app.imObj.HandleVisibility = 'on';
            app.binObj.HandleVisibility = 'on';
            app.deltaObj.HandleVisibility = 'on';
        end

        % Value changed function: filterFlatCB
        function filterFlat(app, event)
            % March 8, 2020
            if app.filterFlatCB.Value
                % compute the low pass filtered flat fields
                app.flatDay = imgaussfilt(app.flatDayBk,app.filterFlatPx.Value);
                app.flatNight = imgaussfilt(app.flatNightBk,app.filterFlatPx.Value);
            else
                app.flatDay = app.flatDayBk;
                app.flatNight = app.flatNightBk;                
            end
        end

        % Value changed function: filterFrameCB
        function prepareFilter(app, event)
            if app.filterFrameCB.Value
                app.kernelFlt = ones(app.filterFramePx.Value,app.filterFramePx.Value)/app.filterFramePx.Value^2;
            end            
        end

        % Value changed function: labelDataCB
        function labels(app, event)
            if app.labelDataCB.Value
                app.lblExp.Enable = 1;
                app.lblRest.Enable = 1;
                app.lblGrooming.Enable = 1;
                app.lblDrink.Enable = 1;
                app.lblOther.Enable = 1;
            else
                app.currentLabel = 0;           % from now on the state returns to be indetermined
                app.lblExp.Enable = 0;
                app.lblRest.Enable = 0;
                app.lblGrooming.Enable = 0;
                app.lblDrink.Enable = 0;
                app.lblOther.Enable = 0;
            end
            
        end

        % Button pushed function: lblExp
        function labelExp(app, event)
            app.currentLabel = 1;
            app.lblExp.BackgroundColor = [1,0,0];
            app.lblOther.BackgroundColor = [0.96,0.96,0.96];
            app.lblRest.BackgroundColor = [0.96,0.96,0.96];
            app.lblGrooming.BackgroundColor = [0.96,0.96,0.96];
            app.lblDrink.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: lblRest
        function labelRest(app, event)
            app.currentLabel = 3;
            app.lblExp.BackgroundColor = [0.96,0.96,0.96];
            app.lblOther.BackgroundColor = [0.96,0.96,0.96];
            app.lblRest.BackgroundColor = [1,0,0];
            app.lblGrooming.BackgroundColor = [0.96,0.96,0.96];
            app.lblDrink.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: lblGrooming
        function labelGrooming(app, event)
            app.currentLabel = 2;            
            app.lblExp.BackgroundColor = [0.96,0.96,0.96];
            app.lblOther.BackgroundColor = [0.96,0.96,0.96];
            app.lblRest.BackgroundColor = [0.96,0.96,0.96];
            app.lblGrooming.BackgroundColor = [1,0,0];
            app.lblDrink.BackgroundColor = [0.96,0.96,0.96];            
        end

        % Button pushed function: lblDrink
        function labelDrink(app, event)
            app.currentLabel = 5;
            app.lblExp.BackgroundColor = [0.96,0.96,0.96];
            app.lblOther.BackgroundColor = [0.96,0.96,0.96];
            app.lblRest.BackgroundColor = [0.96,0.96,0.96];
            app.lblGrooming.BackgroundColor = [0.96,0.96,0.96];
            app.lblDrink.BackgroundColor = [1,0,0];
        end

        % Button pushed function: lblOther
        function labelOther(app, event)
            app.currentLabel = 6;            
            app.lblExp.BackgroundColor = [0.96,0.96,0.96];
            app.lblOther.BackgroundColor = [1,0,0];
            app.lblRest.BackgroundColor = [0.96,0.96,0.96];
            app.lblGrooming.BackgroundColor = [0.96,0.96,0.96];
            app.lblDrink.BackgroundColor = [0.96,0.96,0.96];
        end

        % Button pushed function: connectCam_2
        function openNotes(app, event)
            % March 9, 2020
            % Open the notes app
            notes(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton
                case app.DaylightButton
                    app.LoadtableButton.Enable = 'off';
                    app.lightOn.Enable = 'on';
                    app.lightOff.Enable = 'on';
                    app.nDays.Enable = 'on';
                    
                case app.FromtableButton
                    app.LoadtableButton.Enable = 'on';
                    app.lightOn.Enable = 'off';
                    app.lightOff.Enable = 'off';
                    app.nDays.Enable = 'off';
            end
        end

        % Button pushed function: LoadtableButton
        function LoadtableButtonPushed(app, event)
            [tabFile,newFolder] = uigetfile([app.dayLightsTable_folder '*xlsx'],'Select talbe');
            if isa(newFolder,'double')
                return               
            end
            % store the source folder
            app.dayLightsTable_folder=newFolder;
            % load the table
            t=readtable([newFolder tabFile],'ReadVariableNames',true,...
                'DatetimeType','datetime',...
                'range','a4',...
                'VariableNamingRule','preserve');
            % trim the table
            stop=find(isnat([t{:,1}]),1);
            t=t(1:stop-1,:);
            % compute transitions:
            
            % number of transitions
            ntrans = size(t,1);
            
            % date and time of transitions to "lights ON" and to "lights
            % OFF"
            trans_on = [t{:,1}]+duration([t{:,2}],[t{:,3}],zeros(ntrans,1));
            trans_off = [t{:,1}]+duration([t{:,4}],[t{:,5}],zeros(ntrans,1));
            
            % concatenate transitions
            trans = [trans_on,trans_off]';
            trans = trans(:)';
            lights = [ones(size(trans_on)),zeros(size(trans_off))]';
            lights = lights(:)';
            
            % store transitions in the app property
            app.lightTransition = struct('dt',trans,'onOff',lights);
            
            disp('Table loaded')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Morpheus 3.0';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Parameters and Actions';
            app.readWClist = uibutton(app.uipanel1, 'push');
            app.readWClist.ButtonPushedFcn = createCallbackFcn(app, @readWClist_Callback, true);
            app.readWClist.Tooltip = {'Display the ist of available web cams'};
            app.readWClist.Text = 'Cam List';
            app.WClistTxt = uilabel(app.uipanel1);
            app.WClistTxt.Tooltip = {'List of available web cams'};
            app.WClistTxt.Text = 'WEb Cam List';
            app.connectCam = uibutton(app.uipanel1, 'push');
            app.connectCam.ButtonPushedFcn = createCallbackFcn(app, @connectCam_Callback, true);
            app.connectCam.Tooltip = {'Connect to a specific web cam'};
            app.connectCam.Text = 'Connect';
            app.getOneImage = uibutton(app.uipanel1, 'push');
            app.getOneImage.ButtonPushedFcn = createCallbackFcn(app, @getOneImage_Callback, true);
            app.getOneImage.Tooltip = {'Initialization of the sequence. This callback initializes the crop limits and must be launched in order to change crop limits. '};
            app.getOneImage.Text = 'First frame';
            app.acquireFlat = uibutton(app.uipanel1, 'push');
            app.acquireFlat.ButtonPushedFcn = createCallbackFcn(app, @acquireFlat_Callback, true);
            app.acquireFlat.Tooltip = 'Get one image and process ';
            app.acquireFlat.Text = 'Flat field';
            app.record = uibutton(app.uipanel1, 'push');
            app.record.ButtonPushedFcn = createCallbackFcn(app, @record_Callback, true);
            app.record.Tooltip = {'Record activity'};
            app.record.Text = 'Record';
            app.autothresholdCK = uicheckbox(app.uipanel1);
            app.autothresholdCK.Text = 'Auto-threshold ';
            app.autothresholdCK.Value = true;
            app.plotTrajectoryIm = uicheckbox(app.uipanel1);
            app.plotTrajectoryIm.Tooltip = 'Plot trajectory on current image window';
            app.plotTrajectoryIm.Text = 'Plot trajectory';
            app.refreshImageCk = uicheckbox(app.uipanel1);
            app.refreshImageCk.Tooltip = 'Plot trajectory on current image window';
            app.refreshImageCk.Text = 'Current Image';
            app.plotTrajectoryBin = uicheckbox(app.uipanel1);
            app.plotTrajectoryBin.ValueChangedFcn = createCallbackFcn(app, @plotTrajectoryBin_Callback, true);
            app.plotTrajectoryBin.Tooltip = 'Plot trajectory on current image window';
            app.plotTrajectoryBin.Text = 'Plot trajectory ';
            app.refreshBinaryImCk = uicheckbox(app.uipanel1);
            app.refreshBinaryImCk.Tooltip = 'Plot trajectory on current image window';
            app.refreshBinaryImCk.Text = 'Binary Image';
            app.SamplingperiodsEditFieldLabel = uilabel(app.uipanel1);
            app.SamplingperiodsEditFieldLabel.Text = 'Sampling period (s):';
            app.SPtxt = uieditfield(app.uipanel1, 'numeric');
            app.SPtxt.Limits = [0.1 Inf];
            app.SPtxt.Value = 0.25;
            app.FramesLabel = uilabel(app.uipanel1);
            app.FramesLabel.Text = 'Frames:';
            app.framesTxt = uieditfield(app.uipanel1, 'numeric');
            app.framesTxt.Limits = [1 Inf];
            app.framesTxt.ValueDisplayFormat = '%.0f';
            app.framesTxt.Tooltip = {'Frames in a single repeat. It cannot be changed on the fly while recording'};
            app.framesTxt.Value = 7200;
            app.ArduinoButton = uibutton(app.uipanel1, 'push');
            app.ArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @connectArduino, true);
            app.ArduinoButton.Tooltip = {'Connect Arduino board'};
            app.ArduinoButton.Text = 'Arduino';
            app.RepLabel = uilabel(app.uipanel1);
            app.RepLabel.Text = 'Rep:';
            app.repetitions = uieditfield(app.uipanel1, 'numeric');
            app.repetitions.Limits = [1 Inf];
            app.repetitions.ValueDisplayFormat = '%.0f';
            app.repetitions.Tooltip = {'Number of repetitions. It cannot be changed on the fly while running'};
            app.repetitions.Value = 26;
            app.refreshDeltaImageCk = uicheckbox(app.uipanel1);
            app.refreshDeltaImageCk.Tooltip = 'Plot trajectory on current image window';
            app.refreshDeltaImageCk.Text = 'Delta Image';
            app.plotTrajectoryDeltaCk = uicheckbox(app.uipanel1);
            app.plotTrajectoryDeltaCk.Tooltip = 'Plot trajectory on current image window';
            app.plotTrajectoryDeltaCk.Text = 'Plot trajectory ';
            app.plotHistoCB = uicheckbox(app.uipanel1);
            app.plotHistoCB.Tooltip = 'Plot trajectory on current image window';
            app.plotHistoCB.Text = {'Display histo'; ''};
            app.dailyModeCB = uicheckbox(app.uipanel1);
            app.dailyModeCB.Tooltip = {'In daily mode data are saved each our and the acquisition lasts 1 day. If multple days are defined in the nDay field that is taken care automatically.'};
            app.dailyModeCB.Text = 'Daily mode';
            app.dailyModeCB.Value = true;
            app.HowmanydaysLabel = uilabel(app.uipanel1);
            app.HowmanydaysLabel.Text = 'How many days:';
            app.nDays = uieditfield(app.uipanel1, 'numeric');
            app.nDays.Limits = [1 Inf];
            app.nDays.ValueDisplayFormat = '%.0f';
            app.nDays.Tooltip = {'Number of repetitions. It cannot be changed on the fly while running'};
            app.nDays.Value = 3;
            app.loadBk = uibutton(app.uipanel1, 'push');
            app.loadBk.ButtonPushedFcn = createCallbackFcn(app, @loadBackFiles, true);
            app.loadBk.Tooltip = 'Get one image and process ';
            app.loadBk.Text = 'Load Flat';
            app.clearIWnd = uibutton(app.uipanel1, 'push');
            app.clearIWnd.ButtonPushedFcn = createCallbackFcn(app, @clearIWndWindows, true);
            app.clearIWnd.Tooltip = {'Connect to a specific web cam'};
            app.clearIWnd.Text = 'Clear Trajectories';
            app.subBack = uicheckbox(app.uipanel1);
            app.subBack.ValueChangedFcn = createCallbackFcn(app, @loadBack, true);
            app.subBack.Text = 'Divide by flat field';
            app.CamexposureLabel = uilabel(app.uipanel1);
            app.CamexposureLabel.Text = 'Cam exposure:';
            app.exposure = uieditfield(app.uipanel1, 'numeric');
            app.exposure.Limits = [-10 10];
            app.exposure.ValueDisplayFormat = '%.0f';
            app.exposure.ValueChangedFcn = createCallbackFcn(app, @camExposure, true);
            app.exposure.Tooltip = {'Window size for running average of plots'};
            app.exposure.Value = -4;
            app.whiteMiceCB = uicheckbox(app.uipanel1);
            app.whiteMiceCB.Tooltip = {'Check here for white mice on dark background'};
            app.whiteMiceCB.Text = 'White mice';
            app.logHistCB = uicheckbox(app.uipanel1);
            app.logHistCB.Tooltip = {'questo è un help online'};
            app.logHistCB.Text = 'Histogram in log scale';
            app.logHistCB.Value = true;
            app.filterFlatCB = uicheckbox(app.uipanel1);
            app.filterFlatCB.ValueChangedFcn = createCallbackFcn(app, @filterFlat, true);
            app.filterFlatCB.Text = 'Flat Gauss low pass filter (px):';
            app.filterFlatPx = uieditfield(app.uipanel1, 'numeric');
            app.filterFlatPx.Limits = [1 Inf];
            app.filterFlatPx.ValueDisplayFormat = '%.0f';
            app.filterFlatPx.Value = 20;
            app.filterFrameCB = uicheckbox(app.uipanel1);
            app.filterFrameCB.ValueChangedFcn = createCallbackFcn(app, @prepareFilter, true);
            app.filterFrameCB.Text = 'Median filter each frame (px):';
            app.filterFramePx = uieditfield(app.uipanel1, 'numeric');
            app.filterFramePx.Limits = [1 Inf];
            app.filterFramePx.ValueDisplayFormat = '%.0f';
            app.filterFramePx.Value = 3;
            app.connectCam_2 = uibutton(app.uipanel1, 'push');
            app.connectCam_2.ButtonPushedFcn = createCallbackFcn(app, @openNotes, true);
            app.connectCam_2.Tooltip = {'Connect to a specific web cam'};
            app.connectCam_2.Text = 'Notes';
            app.eraseEdgesCB = uicheckbox(app.uipanel1);
            app.eraseEdgesCB.Text = 'Erase edges';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel4 = uipanel(app.uipanel3);
            app.uipanel4.Title = 'Environment Control';
            app.override = uicheckbox(app.uipanel4);
            app.override.Tooltip = 'Toggle IR illumination';
            app.override.Text = 'Override Light ';
            app.DayLightCB = uicheckbox(app.uipanel4);
            app.DayLightCB.ValueChangedFcn = createCallbackFcn(app, @toggleDayLight, true);
            app.DayLightCB.Tooltip = 'Toggle white illumination';
            app.DayLightCB.Text = 'Day light';
            app.fanCk = uicheckbox(app.uipanel4);
            app.fanCk.ValueChangedFcn = createCallbackFcn(app, @fanCk_Callback, true);
            app.fanCk.Tooltip = 'Toggle white illumination';
            app.fanCk.Text = 'Fan';
            app.ButtonGroup = uibuttongroup(app.uipanel4);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DaylightButton = uiradiobutton(app.ButtonGroup);
            app.DaylightButton.Text = 'Day light';
            app.DaylightButton.Value = true;
            app.FromtableButton = uiradiobutton(app.ButtonGroup);
            app.FromtableButton.Text = 'From table';
            app.onathLabel = uilabel(app.ButtonGroup);
            app.onathLabel.Text = 'on at (h):';
            app.lightOn = uieditfield(app.ButtonGroup, 'numeric');
            app.lightOn.Limits = [0 23];
            app.lightOn.Tooltip = {'Time of the day (hr only'; ' 0-23 format)'};
            app.lightOn.Value = 7;
            app.offatLabel = uilabel(app.ButtonGroup);
            app.offatLabel.Text = 'off at:';
            app.lightOff = uieditfield(app.ButtonGroup, 'numeric');
            app.lightOff.Limits = [0 24];
            app.lightOff.Tooltip = {'Time of the day (hr only'; ' 0-23 format)'};
            app.lightOff.Value = 19;
            app.LoadtableButton = uibutton(app.ButtonGroup, 'push');
            app.LoadtableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtableButtonPushed, true);
            app.LoadtableButton.Enable = 'off';
            app.LoadtableButton.Text = 'Load table';
            app.uipanel5 = uipanel(app.uipanel3);
            app.uipanel5.Title = 'Image Segmentation and Labelling';
            app.Ignoreblobssmallerthanpx2Label = uilabel(app.uipanel5);
            app.Ignoreblobssmallerthanpx2Label.Text = 'Ignore blobs smaller than (px^2)';
            app.lowerLimBlobs = uieditfield(app.uipanel5, 'numeric');
            app.lowerLimBlobs.Limits = [0 Inf];
            app.lowerLimBlobs.Value = 800;
            app.Mouseblobtypicalsizepx2Label = uilabel(app.uipanel5);
            app.Mouseblobtypicalsizepx2Label.Text = 'Mouse blob typical size (px^2)';
            app.mouseBlobArea = uieditfield(app.uipanel5, 'numeric');
            app.mouseBlobArea.Limits = [0 Inf];
            app.mouseBlobArea.Value = 1500;
            app.ThresholdunitsstddayLabel = uilabel(app.uipanel5);
            app.ThresholdunitsstddayLabel.Text = 'Threshold  (units std) day:';
            app.thrLevelDay = uieditfield(app.uipanel5, 'numeric');
            app.thrLevelDay.Limits = [0 Inf];
            app.thrLevelDay.Value = 12;
            app.binaryClosureTxt = uieditfield(app.uipanel5, 'numeric');
            app.binaryClosureTxt.Limits = [0 Inf];
            app.binaryClosureTxt.Value = 4;
            app.blobAnalyzerCB = uicheckbox(app.uipanel5);
            app.blobAnalyzerCB.ValueChangedFcn = createCallbackFcn(app, @openVBobject, true);
            app.blobAnalyzerCB.Tooltip = {'Requires the Machine Vision toolbox'};
            app.blobAnalyzerCB.Text = 'Use Vision Blob Analyzer';
            app.closure = uicheckbox(app.uipanel5);
            app.closure.Text = 'Apply morphological erosion (px):';
            app.ThresholdmethodLabel = uilabel(app.uipanel5);
            app.ThresholdmethodLabel.Text = 'Threshold method';
            app.methodThr = uidropdown(app.uipanel5);
            app.methodThr.Items = {'Fixed Threshold', 'Otsu Method', 'Otsu Adaptive'};
            app.methodThr.ItemsData = {'1', '2', '3'};
            app.methodThr.Value = '1';
            app.SizeadaptivethresholdingoddpxLabel = uilabel(app.uipanel5);
            app.SizeadaptivethresholdingoddpxLabel.Text = 'Size adaptive thresholding (odd px):';
            app.sizeAdaptiveThr = uieditfield(app.uipanel5, 'numeric');
            app.sizeAdaptiveThr.Limits = [0 Inf];
            app.sizeAdaptiveThr.RoundFractionalValues = 'on';
            app.sizeAdaptiveThr.ValueDisplayFormat = '%.0f';
            app.sizeAdaptiveThr.Value = 127;
            app.nightLabel = uilabel(app.uipanel5);
            app.nightLabel.Text = 'night:';
            app.thrLevelNight = uieditfield(app.uipanel5, 'numeric');
            app.thrLevelNight.Limits = [0 Inf];
            app.thrLevelNight.Value = 12;
            app.labelDataCB = uicheckbox(app.uipanel5);
            app.labelDataCB.ValueChangedFcn = createCallbackFcn(app, @labels, true);
            app.labelDataCB.Tooltip = {'When selected'; ' states can be manually labelled using the buttons.'};
            app.labelDataCB.Text = 'Manual labelling of states';
            app.lblExp = uibutton(app.uipanel5, 'push');
            app.lblExp.ButtonPushedFcn = createCallbackFcn(app, @labelExp, true);
            app.lblExp.Enable = 'off';
            app.lblExp.Text = 'Exp';
            app.lblRest = uibutton(app.uipanel5, 'push');
            app.lblRest.ButtonPushedFcn = createCallbackFcn(app, @labelRest, true);
            app.lblRest.Enable = 'off';
            app.lblRest.Text = 'Rest';
            app.lblGrooming = uibutton(app.uipanel5, 'push');
            app.lblGrooming.ButtonPushedFcn = createCallbackFcn(app, @labelGrooming, true);
            app.lblGrooming.Enable = 'off';
            app.lblGrooming.Text = 'Gru';
            app.lblDrink = uibutton(app.uipanel5, 'push');
            app.lblDrink.ButtonPushedFcn = createCallbackFcn(app, @labelDrink, true);
            app.lblDrink.Enable = 'off';
            app.lblDrink.Text = 'Drnk';
            app.lblOther = uibutton(app.uipanel5, 'push');
            app.lblOther.ButtonPushedFcn = createCallbackFcn(app, @labelOther, true);
            app.lblOther.Enable = 'off';
            app.lblOther.Text = 'Othr';
            app.uipanel8 = uipanel(app.uipanel3);
            app.uipanel8.Title = 'Status';
            app.currentPositionTxt = uilabel(app.uipanel8);
            app.currentPositionTxt.Text = '-';
            app.speedTxt = uilabel(app.uipanel8);
            app.speedTxt.Text = 'Istantaneous speed (cm/s):';
            app.cycleDuration = uilabel(app.uipanel8);
            app.cycleDuration.Text = 'Cycle duration (s):';
            app.sequenceBeginning = uilabel(app.uipanel8);
            app.sequenceBeginning.Text = 'Started on:';
            app.currentImage = uiaxes(app.figure1);
            app.currentImage.XTick = [];
            app.currentImage.YTick = [];
            app.deltaImage = uiaxes(app.figure1);
            app.deltaImage.YLim = [0 1];
            app.deltaImage.XTick = [];
            app.deltaImage.XTickLabel = '';
            app.deltaImage.YTick = [];
            app.deltaImage.YTickLabel = '';
            app.segmentedImage = uiaxes(app.figure1);
            app.segmentedImage.XTick = [];
            app.segmentedImage.YTick = [];
            app.grayDistribution = uiaxes(app.figure1);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = morpheus3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @morpheus_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end