classdef morpheus_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        multiPlot                       matlab.ui.control.UIAxes
        currentImage                    matlab.ui.control.UIAxes
        deltaImage                      matlab.ui.control.UIAxes
        segmentedImage                  matlab.ui.control.UIAxes
        grayDistribution                matlab.ui.control.UIAxes
        uipanel1                        matlab.ui.container.Panel
        readWClist                      matlab.ui.control.Button
        WClistTxt                       matlab.ui.control.Label
        connectCam                      matlab.ui.control.Button
        getOneImage                     matlab.ui.control.Button
        manualAcquisition               matlab.ui.control.Button
        record                          matlab.ui.control.Button
        autothresholdCK                 matlab.ui.control.CheckBox
        plotTrajectoryIm                matlab.ui.control.CheckBox
        refreshImageCk                  matlab.ui.control.CheckBox
        plotTrajectoryBin               matlab.ui.control.CheckBox
        refreshBinaryImCk               matlab.ui.control.CheckBox
        ThresholdlevelunitsofstdEditFieldLabel  matlab.ui.control.Label
        thrLevelEdit                    matlab.ui.control.NumericEditField
        SamplingperiodsEditFieldLabel   matlab.ui.control.Label
        SPtxt                           matlab.ui.control.NumericEditField
        FramesLabel                     matlab.ui.control.Label
        framesTxt                       matlab.ui.control.NumericEditField
        SizeofnoisetoremovepxLabel      matlab.ui.control.Label
        binaryClosureTxt                matlab.ui.control.NumericEditField
        TrajectorylengthpointsLabel     matlab.ui.control.Label
        plotTrajectoryLenTxt            matlab.ui.control.NumericEditField
        ArduinoButton                   matlab.ui.control.Button
        RepetitionsLabel                matlab.ui.control.Label
        repetitions                     matlab.ui.control.NumericEditField
        cycleDuration                   matlab.ui.control.Label
        refreshDeltaImageCk             matlab.ui.control.CheckBox
        plotTrajectoryDeltaCk           matlab.ui.control.CheckBox
        plotGraphCB                     matlab.ui.control.CheckBox
        RunningAvEditFieldLabel         matlab.ui.control.Label
        RunningAv                       matlab.ui.control.NumericEditField
        plotHistoCB                     matlab.ui.control.CheckBox
        dailyModeCB                     matlab.ui.control.CheckBox
        HowmanydaysLabel                matlab.ui.control.Label
        nDays                           matlab.ui.control.NumericEditField
        whiteMiceCB                     matlab.ui.control.CheckBox
        subBack                         matlab.ui.control.CheckBox
        ExposureLabel                   matlab.ui.control.Label
        exposure                        matlab.ui.control.NumericEditField
        uipanel2                        matlab.ui.container.Panel
        imageDelta                      matlab.ui.control.UIAxes
        deltaArea                       matlab.ui.control.UIAxes
        travelledDisplacement           matlab.ui.control.UIAxes
        uipanel3                        matlab.ui.container.Panel
        uipanel4                        matlab.ui.container.Panel
        override                        matlab.ui.control.CheckBox
        DayLightCB                      matlab.ui.control.CheckBox
        fanCk                           matlab.ui.control.CheckBox
        SwitchdaylightonathourEditFieldLabel  matlab.ui.control.Label
        lightOn                         matlab.ui.control.NumericEditField
        SwitchdaylightoffathourLabel    matlab.ui.control.Label
        lightOff                        matlab.ui.control.NumericEditField
        uipanel5                        matlab.ui.container.Panel
        LeftwidthpixLabel               matlab.ui.control.Label
        leftCropTxt                     matlab.ui.control.NumericEditField
        RightwidthLabel                 matlab.ui.control.Label
        rightCropTxt                    matlab.ui.control.NumericEditField
        TopwidthpixLabel                matlab.ui.control.Label
        topCropTxt                      matlab.ui.control.NumericEditField
        BottomwidthLabel                matlab.ui.control.Label
        bottomCropTxt                   matlab.ui.control.NumericEditField
        uipanel7                        matlab.ui.container.Panel
        ThresholdspeedcmsLabel          matlab.ui.control.Label
        thrSpeedTxt                     matlab.ui.control.NumericEditField
        ThresholddApxsLabel             matlab.ui.control.Label
        areaThrTxt                      matlab.ui.control.NumericEditField
        MindurationofsleepeventssLabel  matlab.ui.control.Label
        minSleepTxt                     matlab.ui.control.NumericEditField
        uipanel8                        matlab.ui.container.Panel
        currentPositionTxt              matlab.ui.control.Label
        speedTxt                        matlab.ui.control.Label
        stateTxt                        matlab.ui.control.Label
        immobileTxt                     matlab.ui.control.Label
        sleepTxt                        matlab.ui.control.Label
        exploratoryTxt                  matlab.ui.control.Label
        localExpTxt                     matlab.ui.control.Label
        openTxt                         matlab.ui.control.Label
        edgesTxt                        matlab.ui.control.Label
        uibuttongroup1                  matlab.ui.container.ButtonGroup
        speedRB                         matlab.ui.control.RadioButton
        deltaAreaRB                     matlab.ui.control.RadioButton
        stateRB                         matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        WClist              % list of available web cams
        WCnumber            % number of available cams
        cam                 % web vam object
        hpw                 % handle to preview window
        background
        imObj
        binObj
        deltaObj
        previewMode
        cropLeft
        cropRight
        scaleFactor
        height
        width
        npoints             % number of points in the stored trajectory
        trajectory          % structure holding the trajectory data
        currentFrame
        currentFrameBinary
        newFrame
        newFrameBinary
        deltaFrame
        initialArea
        dRav                % running average displacement
        dARav               % running average delta area
        dPixRav             % running average delta pix
        RavWin              % width of the rolling window filter
        period
        framesN
        repeatN
        repeat
        edgeCumulative
        spThr
        areaThr
        minSleep
        leftB
        rightB
        topB
        bottomB
        stateNow
        previousState
        currentStateN
        arduinoBoard        % Arduino object
        beginTime           % 
        lightTransition
        imIR 
        imD 
        cntDay
    end
    
    properties (Access = private)
        overrideCropFlag
        medianNow
        thr
        timeLapse           % timer object
        timeLapseFlag
    end
    
    methods (Access = private)
        function acquireBackground(app)
            % grab a background image 480x640 uint8
            if (app.previewMode==1)
                % exit from preview mode
                app.previewMode = 0;
                app.preview.Text = 'Preview';
                closePreview(app.cam);
            end
            % frameNow = snapAndCrop (hObject, handles);
            [frameNow, newFrBin] = shootAndBinarize (app);
            
            % get the background image
            app.background = frameNow;
            
            % create the handles for the output images
            colormap(app.currentImage,'Gray');
            cla(app.currentImage);
            app.imObj = imagesc (app.currentImage, app.background);
            set(app.currentImage,'XTickLabel',[])
            set(app.currentImage,'YTickLabel',[])
            
            cla(app.segmentedImage);
            app.binObj = imagesc (app.segmentedImage, newFrBin);
            set(app.segmentedImage,'XTickLabel',[])
            set(app.segmentedImage,'YTickLabel',[])
            
            cla(app.deltaImage);
            app.deltaObj = imagesc (app.deltaImage, app.background);
            set(app.deltaImage,'XTickLabel',[])
            set(app.deltaImage,'YTickLabel',[])
            
            [app.height, app.width] = size (app.background);
        end
        
        function [md, stdim, thr] = computeAndPlotHisto(app, im, thrmtp)
            
            % This function computes the gray level distribution of the input image im
            % computes the current statistics and the threshold
            % the distribution is plotted together with the median and threshold value
            % INPUT ARGUMENTS
            % im: input image
            % thrmtp: mutiplier for the coputation of the luminance threshold
            % OUTPUT ARGUMENTS
            % md: median of luminance distribution
            % stdim: standard deviation
            % thr: threshold for image binarisation (to be normalized to 255 before im2bin)
            
            % compute descriptive statistics
            md = median(median(im));
            stdim = ceil(std(std(double(im))));
            thr = double(md - thrmtp * stdim);
            
            if app.plotHistoCB.Value
                cla(app.grayDistribution);
                [pixelCount, grayLevels] = imhist(im);
                bar (app.grayDistribution,pixelCount);
                set (app.grayDistribution,'XTickLabel',[])
                set (app.grayDistribution,'YTickLabel',[])
    
                xlim (app.grayDistribution,[0 255]);
                grid (app.grayDistribution, 'on');
                
                % Show the threshold as a vertical red bar on the histogram.
                hold (app.grayDistribution,'on');
                maxYValue = app.grayDistribution.YLim;
                line(app.grayDistribution, [thr, thr], maxYValue, 'Color', 'r');
                line(app.grayDistribution, [md, md], maxYValue, 'Color', 'g');
                
                % Place a text label on the bar chart showing the threshold.
                annotationText = sprintf('Threshold at %d', thr);
                % For text(), the x and y need to be of the data class "double" so let's cast both to double.
                text(app.grayDistribution, double(thr - 20), ...
                    double(0.85 * maxYValue(2)), annotationText, 'FontSize', 6, 'Color', [1 0 0]);
                text(app.grayDistribution, double(thr - 40), double(0.94 * maxYValue(2)),...
                    'Mouse', 'FontSize', 6, 'Color', [0 0 .5]);
                text(app.grayDistribution, double(thr + 50), double(0.94 * maxYValue(2)),...
                    'Background', 'FontSize', 6, 'Color', [0 0 .5]);
            end
        end
        
        function computeMousePosition(app, im)
            % This function computes the center of gravity of the mouse, the
            % displacement, the fractional area change and the number of pixels that
            % change status.
            
            [rw, clm] = find(im<1);
            ymouse = app.height - int16(mean(rw));
            xmouse = int16(mean(clm));
            
            app.trajectory.x(app.npoints,1) = xmouse;
            app.trajectory.y(app.npoints,1) = ymouse;
            if (app.npoints>1)               % not the first frame!
                deltaX = app.trajectory.x(app.npoints)-app.trajectory.x(app.npoints-1);
                deltaY = app.trajectory.y(app.npoints)-app.trajectory.y(app.npoints-1);
                app.trajectory.d(app.npoints,1) = hypot(deltaX,deltaY);
                app.trajectory.l(app.npoints,1) = app.trajectory.l(app.npoints-1) ...
                    + app.trajectory.d(app.npoints);
            else
                app.trajectory.d(app.npoints,1) = 0;
                app.trajectory.l(app.npoints,1) = 0;
                app.initialArea = length(rw);
            end
            mouseArea = length(rw);           % the mouse area is provided by the number of pixels set to 0
            app.trajectory.deltaA(app.npoints,1) = (mouseArea-app.initialArea)/app.initialArea;            
        end
        
        function plotGraphs(app)
            w = app.plotTrajectoryLenTxt.Value;         % why???
            if app.npoints>2
                x = [app.npoints-1 app.npoints];
                % All plots are aligned on the inner positition of the upper plot
                hold (app.travelledDisplacement, 'on')
                oldIP = app.travelledDisplacement.InnerPosition;
                %plot(app.travelledDisplacement, app.npoints,app.dRav(app.npoints));
                line(app.travelledDisplacement, x, [app.dRav(app.npoints-1) app.dRav(app.npoints)]);
%                axis (app.travelledDisplacement, 'auto y');
    
                delta = oldIP - app.travelledDisplacement.InnerPosition;
                app.travelledDisplacement.Position = app.travelledDisplacement.Position + delta;
                %text((XValues(2)-XValues(1))/2, double(0.85 * YValues(2)), 'Module of displacement', 'FontSize', 8, 'Color', [1 0 0]);
                
                hold (app.deltaArea, 'on')
                oldIP = app.deltaArea.InnerPosition;
                %plot(app.deltaArea,  app.npoints,app.dARav(app.npoints))
                line(app.deltaArea, x, [app.dARav(app.npoints-1) app.dARav(app.npoints)])
                axis (app.deltaArea, 'auto y');
    
                delta = oldIP - app.deltaArea.InnerPosition;
                app.deltaArea.Position = app.deltaArea.Position + delta;
                %text((XValues(2)-XValues(1))/2, double(0.7 * YValues(2)), 'Fractional area change', 'FontSize', 8, 'Color', [1 0 0]);
                
                hold (app.imageDelta, 'on')
                oldIP = app.imageDelta.InnerPosition;
                %plot(app.imageDelta, app.npoints,app.dPixRav(app.npoints))
                line(app.imageDelta, x, [app.dPixRav(app.npoints-1) app.dPixRav(app.npoints)])
                axis (app.imageDelta, 'auto y');
                delta = oldIP - app.imageDelta.InnerPosition;
                app.imageDelta.Position = app.imageDelta.Position + delta;
            end            
            % Place a text label on the bar chart showing the threshold.
            %annotationText = sprintf('Threshold at %d', thr);
            % For text(), the x and y need to be of the data class "double" so let's cast both to double.
        
        end
        
        function computeRollingAverages (app)
            % compute rolling averages of arrays
            % app.trajectory.d; app.trajectory.deltaA; app.trajectory.deltaPx
            beginI = app.npoints - app.RavWin;
            if beginI <1 beginI = 1;
            end
            endI = app.npoints + app.RavWin;
            if endI > app.repeatN * app.framesN, endI = app.repeatN * app.framesN;
            end
            app.dRav (app.npoints) = mean(app.trajectory.d(beginI:endI));
            app.dARav (app.npoints) = mean(app.trajectory.deltaA(beginI:endI));
            app.dPixRav (app.npoints) = mean(app.trajectory.deltaPx(beginI:endI));
        end

        function initializeOutputStruct(app)
        
            % preallocate all arrays
            
            app.npoints = 0;
            app.trajectory.x = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.y = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.l = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.d = zeros(app.repeatN * app.framesN, 1);
            app.dRav = zeros(app.repeatN * app.framesN, 1);
            app.dARav = zeros(app.repeatN * app.framesN, 1);
            app.dPixRav = zeros(app.repeatN * app.framesN, 1);

            app.trajectory.deltaA = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.deltaPx = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.instDeltaPx = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.instSpeed = zeros(app.repeatN * app.framesN, 1);
            % app.trajectory.location = zeros(app.repeatN * app.framesN, 1);       % EDGE = 1 or OPEN = -1
            
            app.trajectory.year = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.month = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.day = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.hour = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.min = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.sec = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.secFromStart = zeros(app.repeatN * app.framesN, 1);
            app.trajectory.environment = zeros(app.repeatN * app.framesN, 1);
            
            % app.trajectory.state = zeros(app.repeatN * app.framesN, 1);
            app.edgeCumulative = 0;
                        
            app.spThr = app.thrSpeedTxt.Value;
            app.areaThr = app.areaThrTxt.Value;
            app.minSleep = app.minSleepTxt.Value/app.period;   % min sleep in frames
            app.leftB = app.leftCropTxt.Value;
            app.rightB = app.width - app.rightCropTxt.Value;
            app.topB = app.topCropTxt.Value;
            app.bottomB = app.height - app.bottomCropTxt.Value;
            
            app.stateNow = 0;
            app.previousState = 0;
            app.currentStateN = 0;      % this counts the duration of the current state
        end
        
        function plotStateDistribution(app)
            % this function plots the distribution selected by the radio buttons in the
            % multiPlot panel
            
            if get(app.speedRB,'Value')
                %create and plot the instantaneous speed distribution
                histogram(app.multiPlot,app.trajectory.instSpeed);
            end
            if get(app.deltaAreaRB,'Value')
                %create and plot the instantaneous speed distribution
                histogram(app.multiPlot,app.trajectory.instDeltaPx);
            end
            if get(app.stateRB,'Value')
                %create and plot the distribution of the duration of the states
                histogram(app.multiPlot,app.trajectory.instDeltaPx);
            end
            set(app.multiPlot,'fontsize',7)
        end
        
        function plotTrajectory(app)
            
            % This function plots the trajectory on the selected axes
            % December 2019. The plot is restricted to the latest new
            % segment.
            
            st = app.plotTrajectoryLenTxt.Value;
            st = app.npoints-1;
            bgn = app.npoints;
            st = bgn - st;
            if (st < 1), st=1;
            end
            if get(app.plotTrajectoryIm,'Value')
                hold (app.currentImage,'on');
                plot (app.currentImage,app.trajectory.x(st:bgn),app.height - app.trajectory.y(st:bgn),'color','r');
                axis (app.currentImage,[0 app.width 0 app.height]);
                hold (app.currentImage,'off');
            end
            
            if get(app.plotTrajectoryBin,'Value')
                hold (app.segmentedImage,'on');
                plot (app.segmentedImage,app.trajectory.x(st:bgn),app.height - app.trajectory.y(st:bgn),'color','r');
                axis (app.segmentedImage,[0 app.width 0 app.height]);
                hold (app.segmentedImage,'off');
            end
        end
        
        function [newFr, newFrBin] = shootAndBinarize(app)
            % This fuction shoot a new image and binarizes it
            
            frameNow =  get (app.hpw,'CData');
            newFr = rgb2gray(frameNow);
            if app.subBack.Value
                if app.DayLightCB.Value
                    %newFr = uint8(newFr - app.imD);
                    newFr = double(newFr) ./ app.imD;
                else
                    %newFr = uint8(newFr - app.imIR);
                    newFr = double(newFr) ./ app.imIR;
                end
            end
            newFr = uint8(newFr);
            if app.whiteMiceCB.Value
                % invert the BW image
                newFr = uint8(255) - newFr;
            end
            % app.medianNow is 0 when the program is started
            if (app.autothresholdCK.Value || app.medianNow==0)
                thrX = app.thrLevelEdit.Value;
                [md, stdim, thrgray] = computeAndPlotHisto(app, newFr, thrX);
                app.medianNow = md;
                app.thr = thrgray/255;
                if (app.thr < 0.05), app.thr=0.05;
                end
            end
            newFrBin = im2bw(newFr,app.thr);
            closureSize = app.binaryClosureTxt.Value;
            if (closureSize>0)
                % create the structure element
                SE = strel('disk',closureSize);
                newFrBin = imdilate(newFrBin,SE);
                newFrBin = imerode(newFrBin,SE);
            end
        end
        
        function im = snapAndCrop(app)
            % not really a crop but simply a blanking of everything that is outside of
            % the cropping limits.
            im = snapshot(app.cam);
            if app.overrideCropFlag
                % do nothing, I believe!
            else
                if (app.cropLeft>0), im(:,1:app.cropLeft) = 255;
                end
                if (app.cropRight>0), im(:,app.cropRight:app.width) = 255;
                end
                if (app.cropTop>0), im(1:app.cropTop,:) = 255;
                end
                if (app.cropBottom>0), im(app.cropBottom:app.height,:) = 255;
                end
            end
        end
        
        function timeLapseHandlerIn(app)
            
            % The timer call backs are nested in the main body so they share the
            % workspace with the main function. 
            tic
            % Create the object timer for the control of time lapse
            app.timeLapse = timer;
            app.timeLapse.ExecutionMode = 'FixedRate';         % the code is executed immediately at each timer event
            app.timeLapse.TasksToExecute = app.framesN;        % number of repeat
            app.timeLapse.Period = app.period ;                % period of timer events
            app.timeLapse.StartDelay = app.period;                      % wait 0 sec before starting
            
            app.timeLapse.TimerFcn = {@timeLapseCallback};     % pointer to the function evoked at each timer event
            app.timeLapse.StopFcn = {@stopTimeLapseCallback};  % pointer to the function evoked at the end of the time lapse seq
            
            %app.myTimer.TimerFcn = @(~,~) app.myFunction;
            % initialize
            
            initializeOutputStruct (app)
            
            % Clear the graph windows.
            cla(app.travelledDisplacement);
            cla(app.deltaArea);
            cla(app.imageDelta);
            
            [frameNow, newFrBin] = shootAndBinarize (app);
            cla(app.currentImage);
            app.imObj = imagesc (app.currentImage, frameNow);
            cla(app.segmentedImage);
            app.binObj = imagesc (app.segmentedImage,newFrBin);
            
            start(app.timeLapse);
            app.beginTime = clock;
            
            % functions within timeLapseHandler and within its scope
            
            function beginTimeLapse (~,~)
                % This function initializes the time lapse sequence and the output data set
                % No image is acquired. REMEMBER the image size must be determined at connection time    
            end
                    
            function stopTimeLapseCallback (~,~)
                %'stop'
                % Time to save the trajectory structure, I guess.
                newStr = app.trajectory;        % apparently the function save cannot extract the subfield directly from app.trajectory
                fileName = ['morpheus ' date ' ' num2str(app.repeat) '.mat']; 
                save(fileName,'-struct','newStr');
                app.repeat = app.repeat + 1;
                if app.dailyModeCB.Value & app.repeat>24
                    % we are in daily mode, and 24 repetions elapsed
                    % so lets countdown the elapsed days
                    app.dayCnt = app.dayCnt - 1;
                    if app.dayCnt < 0
                        % end of the daily sequence
                        app.record.Text = 'Record';
                        app.timeLapseFlag = 0;
                    else
                        app.repeat = 1;
                        start(app.timeLapse);
                    end
                else
                    if app.repeat > app.repeatN
                        app.record.Text = 'Record';
                        app.timeLapseFlag = 0;
                    else
                        start(app.timeLapse);
                    end    
                end
            end
            
            function timeLapseCallback (~,~)
                    tic
                    %'inside time lapse handler: shootAndBinarize'
                    [frameNow, newFrBin] = shootAndBinarize (app);
                    nowIs = clock;      % this is a numeric array containing date and time
                                        % year month day hour minute seconds   
                    nowIsDT = datetime(nowIs);                    
                    % toc   ASUS 41 ms                    
                    drawnow;
                    app.newFrame = frameNow;
                    app.newFrameBinary = newFrBin;
            
                    if (app.npoints == 0)
                        app.deltaFrame = app.newFrameBinary - app.newFrameBinary;
                    else
                        app.deltaFrame = abs(app.newFrameBinary - app.currentFrameBinary);
                    end
                    app.currentFrame = app.newFrame;
                    app.currentFrameBinary = app.newFrameBinary;
            
                    if get(app.refreshImageCk,'Value'), set(app.imObj,'cData',app.currentFrame);
                    end
            
                    if get(app.refreshBinaryImCk,'Value'), set(app.binObj,'cData',app.currentFrameBinary);
                    end
                    
                    if get(app.refreshDeltaImageCk,'Value'), set(app.deltaObj,'cData',app.deltaFrame);
                    end
                    
                    % Increment the frame number
                    app.npoints = app.npoints + 1;
            
                    % the next stm also computes displacement, fractional area change and delta
                    % area.
                    computeMousePosition (app, app.currentFrameBinary);
                    % 'Image acq, display and analysis'
                    % toc       92 ms
                    app.trajectory.year(app.npoints,1) = nowIs(1);
                    app.trajectory.month(app.npoints,1) = nowIs(2);
                    app.trajectory.day(app.npoints,1) = nowIs(3);
                    app.trajectory.hour(app.npoints,1) = nowIs(4);
                    app.trajectory.min(app.npoints,1) = nowIs(5);
                    app.trajectory.sec(app.npoints,1) = nowIs(6);
                    app.trajectory.secFromStart(app.npoints,1) = etime(nowIs,app.beginTime);

                    % Detect light/darkness transitions
                    past = find(app.lightTransition.dt<nowIsDT);
                    lastTransition = app.lightTransition.onOff(past(end));      % state: 1= light on, 0 off
                    
                    app.DayLightCB.Value = lastTransition;
                    if ~app.override.Value
                        if ~isempty(app.arduinoBoard)   % Arduino is connected
                            writeDigitalPin(app.arduinoBoard,'D2',lastTransition);
                        end
                        app.trajectory.environment(app.npoints,1) = double(app.DayLightCB.Value);
                    end
                    % on line analysis and output
                    plotTrajectory (app)

                    txtOut = sprintf('Frame: %d Rep: %d X: %d Y: %d', app.npoints, ...
                        app.repeat, app.trajectory.x(app.npoints), app.trajectory.x(app.npoints));
                    app.currentPositionTxt.Text = txtOut;

%                     speed = app.scaleFactor * ...
%                         app.trajectory.d(app.npoints) / app.period;  % Instantaneous speed
%                     app.trajectory.d(app.npoints) = speed;
%                     txtOut = sprintf('Instantaneous speed: %.2f (cm/s)', speed);
%                     app.speedTxt.Text = txtOut;
%                     app.trajectory.instSpeed(app.npoints) = speed;
                    app.trajectory.deltaPx(app.npoints) = sum(sum(app.deltaFrame));
%                    speed = app.trajectory.deltaPx(app.npoints)/app.period;
%                    app.trajectory.instDeltaPx(app.npoints) = speed;
            
                    txtOut = sprintf('delta Pixel: %d', app.trajectory.deltaPx(app.npoints));
                    app.speedTxt.Text = txtOut;

                    %'compute mouse location'

                    %                     xnow = app.trajectory.x(app.npoints);
%                     ynow = app.trajectory.y(app.npoints);
%                     if (xnow<=app.leftB || xnow>=app.rightB || ynow<=app.topB || ynow>=app.bottomB)
%                         % Mouse near the edge of the arena
%                         app.trajectory.location(app.npoints) = 1;
%                         app.edgeCumulative = app.edgeCumulative + 1;
%                         app.stateTxt.Text = [app.stateTxt.Text ' BORDER'];
%                     else
%                         app.trajectory.location(app.npoints) = -1;
%                         app.stateTxt.Text = [app.stateTxt.Text ' OPEN'];
%                     end
%                     edgesX100 = app.edgeCumulative / app.npoints * 100;
%                     app.edgesTxt.Text = sprintf('Border %%: %.1f',edgesX100);
%                     app.openTxt.Text = sprintf('Open Field %%: %.1f',100-edgesX100);
                    %'state and location analysis'
                    % toc       99 ms

                    % compute rolling averages of arrays
                    % app.trajectory.d; app.trajectory.deltaA; app.trajectory.deltaPx
                    % computeRollingAverages (app)
                    if app.plotGraphCB.Value, plotGraphs (app)                        
                    end
%                    plotStateDistribution (app);
                    %'plot graphs'
                    cd = toc;       % 152 ms
                    app.cycleDuration.Text = sprintf ('Cycle duration (s): %0.3f',cd);
                    drawnow;
                end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function morpheus_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to morpheus (see VARARGIN)
%             
%             % Choose default command line output for morpheus
%             app.output = hObject;
             app.previewMode=0;
             app.overrideCropFlag = 1;
             app.medianNow = 0;
             app.timeLapseFlag = 0;
             
             app.scaleFactor = 1/12.5;     % image scale = 1 / n pixels x 1 cm
             
             % initialize axes properties
             set(app.currentImage,'XTickLabel',[])
             set(app.currentImage,'YTickLabel',[])
             set(app.deltaImage,'XTickLabel',[])
             set(app.deltaImage,'YTickLabel',[])
             set(app.segmentedImage,'XTickLabel',[])
             set(app.segmentedImage,'YTickLabel',[])
             set(app.travelledDisplacement,'XTickLabel',[])
             set(app.deltaArea,'XTickLabel',[])
             set (app.grayDistribution,'XTickLabel',[])
             set (app.grayDistribution,'YTickLabel',[])

        end

        % Button pushed function: connectCam
        function connectCam_Callback(app, event)
            
            if ~isempty(app.cam)
                closepreview(app.cam);
                delete (app.cam);
                app.connectCam.Text = 'Connect';
            else
                if isempty(app.WCnumber)
                    % if the web cam list does not exist create it!
                    app.WClist = webcamlist;
                    app.WCnumber = length(app.WClist);
                    app.WClistTxt.Text = app.WClist;
                end
                app.cam = webcam(1); %app.WCnumber
                app.cam.Resolution = '640x480';
                app.connectCam.Text = 'Disconnect';
                app.overrideCropFlag = 1;       % upon connection no crop is admitted
            
                % now start the preview mode and create the relative handle
                app.hpw = preview(app.cam);
            
                % get one image to identify frame size
                acquireBackground(app)
                app.overrideCropFlag = 0;            
            end
        end

        % Value changed function: fanCk
        function fanCk_Callback(app, event)
            % hObject    handle to fanCk (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of fanCk
        end

        % Button pushed function: getOneImage
        function getOneImage_Callback(app, event)
            
            % Get the first frame of a manually acquired sequence.
            % This function must be called before of a record session to pass by the
            % cropping limits. Cropping cannot be modified on the fly and must be alway
            % followed by a call to this function in order to implement them.
            
            app.cropLeft = app.leftCropTxt.Value;
            app.cropRight = app.rightCropTxt.Value;
            
            [frameNow, newFrBin] = shootAndBinarize (app);
            app.currentFrame = frameNow;
            app.currentFrameBinary = newFrBin;
            
            set(app.imObj, 'CData', app.currentFrame);
            set(app.binObj, 'CData', app.currentFrameBinary);
            
            % let's compute center of gravity of the mouse
            % this is the beginning of the trajectory. Reset the trajectory
            app.npoints = 1;
            app.trajectory.x = [];
            app.trajectory.y = [];
            app.trajectory.l = [];
            app.trajectory.d = [];
            app.trajectory.deltaA = [];
            % Clear the graph windows.
            cla (app.travelledDisplacement);
            cla (app.deltaArea);
            
            computeMousePosition (app, app.currentFrameBinary);
            
            nowIs = clock;      % this is a numeric array containing date and time
                                % year month day hour minute seconds
            app.trajectory.day(app.npoints) = nowIs(3);
            app.trajectory.hour(app.npoints) = nowIs(4);
            app.trajectory.min(app.npoints) = nowIs(5);
            app.trajectory.sec(app.npoints) = nowIs(6);
            
            app.trajectory.deltaA(app.npoints) = (app.initialArea-app.initialArea)/app.initialArea;
        end

        % Button pushed function: manualAcquisition
        function manualAcquisition_Callback(app, event)
            
            tic
            % tic
            [frameNow, newFrBin] = shootAndBinarize (app);
            % inboard webcam toc  0.03 sec after implementation of image grabbing from live stream.
            
            nowIs = clock;      % this is a numeric array containing date and time
                                % year month day hour minute seconds
            
            app.newFrame = frameNow;
            app.newFrameBinary = newFrBin;
            
            app.deltaFrame = abs(app.newFrameBinary - app.currentFrameBinary);
            app.currentFrame = app.newFrame;
            app.currentFrameBinary = app.newFrameBinary;
            
            % tic
            set(app.imObj,'cData',app.currentFrame);
            set(app.binObj,'cData',app.currentFrameBinary);
            % timing of this section: imshow = 0.08 s  Set cData = 0.0003 s
            % toc
            set(app.deltaObj,'cData',app.deltaFrame);
            
            % Increment the frame number
            app.npoints = app.npoints + 1;
            computeMousePosition (app, app.currentFrameBinary);
            txtOut = sprintf('Frame: %d Position X: %d Y: %d', app.npoints, ...
                app.trajectory.x(app.npoints), app.trajectory.x(app.npoints));
            set(app.currentPositionTxt, 'String', txtOut);
            
            app.trajectory.deltaPx(app.npoints) = sum(sum(app.deltaFrame));
            app.trajectory.day(app.npoints) = nowIs(3);
            app.trajectory.hour(app.npoints) = nowIs(4);
            app.trajectory.min(app.npoints) = nowIs(5);
            app.trajectory.sec(app.npoints) = nowIs(6);
            
            % on line analysis and output
            
            % compute mouse location
            app.leftB = str2double(get(app.leftCropTxt,'String'));
            app.rightB = app.width - str2double(get(app.rightCropTxt,'String'));
            app.topB = str2double(get(app.topCropTxt,'String'));
            app.bottomB = app.height - str2double(get(app.bottomCropTxt,'String'));
            xnow = app.trajectory.x(app.npoints);
            ynow = app.trajectory.y(app.npoints);
            if (xnow<=app.leftB || xnow>=app.rightB || ynow<=app.topB || ynow>=app.bottomB)
                % Mouse near the edge of the arena
                app.trajectory.location(app.npoints) = 1;
                app.stateTxt.String = 'BORDER';
            else
                app.trajectory.location(app.npoints) = -1;
                app.stateTxt.String = 'OPEN FIELD';
            end
            
            plotTrajectory (app)
            plotGraphs (app)
            
            toc
        end

        % Value changed function: plotTrajectoryBin
        function plotTrajectoryBin_Callback(app, event)
            
            % if(app.plotTrajectoryBin.Value),
            %    app.trjBin = gobjects;
            % else
            %    if ishandles(app.trjBin), delete(app.trjBin);
            %    end
            % end
            % guidata(hObject,handles)
        end

        % Button pushed function: readWClist
        function readWClist_Callback(app, event)
            app.WClist = webcamlist;
            app.WCnumber = length(app.WClist);
            app.WClistTxt.Text = app.WClist;
        end

        % Button pushed function: record
        function record_Callback(app, event)
            % First: check if the timer is already running
            if (app.timeLapseFlag)
                % it is running. Stop and kill the timer
                stop(app.timeLapse);
                app.record.Text = 'Record';
                delete(app.timeLapse);
                clear('app.timeLapse');
                app.timeLapseFlag = 0;
                drawnow;
                % save data or whatever
            else
                % Prepare the time lapse mode.
                app.record.Text = 'STOP';
                app.timeLapseFlag = 1;
                app.period = app.SPtxt.Value;  % this is the frame period in sec.
                app.repeat = 1;
                app.framesN = app.framesTxt.Value;  % this is the frame number.
                app.RavWin = app.RunningAv.Value;
                app.repeatN = app.repetitions.Value;
                
                % now prepare the repetion modes and the light on/off array
                
                % Compute total duration of acquisition based on the value of daily mode
                nowT = datetime;            % datetime at the beginning
                nowT = nowT - days(1);      % let's go back one day in time. This ensure that all possible transitions are accounted for
                runningDay = nowT;
                cnt = 0;
                minNow = 0;        % Light on off always at the hour sharp!
                secNow = 0;
                if app.dailyModeCB.Value
                    % in this modality each acquisition set lasts 24 hrs and it is repeated for nDays. Each episode lasts 1 hr
                    app.framesTxt.Value = 3600/app.period + 1;      % st runtime this is going to be overridden by actual 
                                                                    % on-the-fly check of timing 
                    app.framesN = app.framesTxt.Value;              % this is the frame number.
                    app.repeatN = 24;
                    app.repetitions.Value = 24;
                    app.cntDay = app.nDays.Value;
                    %app.totalDuration =
                    % create light on light off daytime arrays
                    for i=1:app.nDays.Value+2   % + 2 to add one day at the beginning and 1 day at the end of the recording
                        % compute the transitions for each day of the sequence. In this way the code will work also on Dec 31
                        yearNow = year(runningDay);
                        monthNow = month(runningDay);
                        dayNow = day(runningDay);
                        cnt = cnt + 1;
                        if app.lightOn.Value < app.lightOff.Value    % transitions are ordered as increasing datetimes
                            app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOn.Value, minNow, secNow);
                            app.lightTransition.onOff(cnt) = 1;     % light on
                            cnt = cnt + 1;
                            app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOff.Value, minNow, secNow);
                            app.lightTransition.onOff(cnt) = 0;     % light off
                        else
                            app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOff.Value, minNow, secNow);
                            app.lightTransition.onOff(cnt) = 0;     % light off
                            cnt = cnt + 1;
                            app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOn.Value, minNow, secNow);
                            app.lightTransition.onOff(cnt) = 1;     % light on
                        end
                        runningDay = runningDay + days(1);  % add 1 day to the runtime                        
                    end
                else
                    % compute the duration of the recording in ceil(days)
                    nd = ceil(app.repeatN * app.framesN * app.period/86400);
                    for i=1:nd+2           % nd + 2 will cover any reasonable period in non-daily mode.
                        % compute the transitions for each day of the sequence
                        yearNow = year(runningDay);
                        monthNow = month(runningDay);
                        dayNow = day(runningDay);
                        cnt = cnt + 1;
                        app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOn.Value, minNow, secNow);
                        app.lightTransition.onOff(cnt) = 1;     % light on
                        cnt = cnt + 1;
                        app.lightTransition.dt(cnt) = datetime(yearNow,monthNow,dayNow,app.lightOff.Value, minNow, secNow);
                        app.lightTransition.onOff(cnt) = 0;     % light off
                        runningDay = runningDay + days(1);  % add 1 day to the runtime                        
                    end                    
                end
                
                % dimensioning the output arrays
                app.travelledDisplacement.XLim = [0,app.framesN*app.repeatN];
                app.travelledDisplacement.XLimMode = 'manual';
                app.travelledDisplacement.YLim = [0,10];
                app.travelledDisplacement.YLimMode = 'manual';
                grid(app.travelledDisplacement,"on");
                set(app.travelledDisplacement,'XTickLabel',[],'fontsize',8)
                ylabel(app.travelledDisplacement, 'Displacement');

                app.deltaArea.XLim = [0,app.framesN*app.repeatN];
                app.deltaArea.XLimMode = 'manual';
                ylabel(app.deltaArea, 'Area Change');
                grid (app.deltaArea,'on');
                set(app.deltaArea,'XTickLabel',[],'fontsize',8)

                app.imageDelta.XLim = [0,app.framesN*app.repeatN];
                app.imageDelta.XLimMode = 'manual';
                ylabel(app.imageDelta, 'Image Change');
                grid (app.imageDelta, 'on');
                set(app.imageDelta,'fontsize',8)

                % Start the time lapse acquisition and real time processing
                timeLapseHandlerIn (app)
            end
        end

        % Button pushed function: ArduinoButton
        function connectArduino(app, event)
            % December 12, 2019
            % Connection to a single Arduino board for the control of the
            % light cycle.
            if isempty(app.arduinoBoard)
                app.arduinoBoard = arduino;
                % initialize digital pin 1
                writeDigitalPin(app.arduinoBoard,'D2',0);
                app.ArduinoButton.Text ='Disconnect A';
            else
                fclose(serial(app.arduinoBoard.Port));
                clear app.arduinoBoard;
                app.arduinoBoard = [];
                app.ArduinoButton.Text ='Arduino';
            end
        end

        % Value changed function: DayLightCB
        function toggleDayLight(app, event)
            value = app.DayLightCB.Value;
            if ~isempty(app.arduinoBoard)   % Arduino is connected
                if value 
                    writeDigitalPin(app.arduinoBoard,'D2',1);
                else
                    writeDigitalPin(app.arduinoBoard,'D2',0);
                end
            end
        end

        % Value changed function: subBack
        function loadBack(app, event)
            value = app.subBack.Value;
            % load the IR and day backgroun images
            backIR = open('flatIR.mat');
            backD = open('flatDay.mat');
            app.imIR = double(backIR.newFr);
            app.imD = double(backD.newFr);
            % normalize the background images for divisive correction
            m = mean(mean(app.imIR));
            app.imIR = app.imIR / m;
            m = mean(mean(app.imD));
            app.imD = app.imD / m;
            
        end

        % Value changed function: exposure
        function camExposure(app, event)
            value = app.exposure.Value;
            % override the cam exposure
            app.cam.Exposure = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'morpheus';
            app.multiPlot = uiaxes(app.figure1);
            app.currentImage = uiaxes(app.figure1);
            app.currentImage.XTick = [];
            app.currentImage.YTick = [];
            app.deltaImage = uiaxes(app.figure1);
            app.deltaImage.YLim = [0 1];
            app.deltaImage.XTick = [];
            app.deltaImage.XTickLabel = '';
            app.deltaImage.YTick = [];
            app.deltaImage.YTickLabel = '';
            app.segmentedImage = uiaxes(app.figure1);
            app.segmentedImage.XTick = [];
            app.segmentedImage.YTick = [];
            app.grayDistribution = uiaxes(app.figure1);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Parameters and Actions';
            app.readWClist = uibutton(app.uipanel1, 'push');
            app.readWClist.ButtonPushedFcn = createCallbackFcn(app, @readWClist_Callback, true);
            app.readWClist.Tooltip = {'Display the ist of available web cams'};
            app.readWClist.Text = 'Cam List';
            app.WClistTxt = uilabel(app.uipanel1);
            app.WClistTxt.Tooltip = {'List of available web cams'};
            app.WClistTxt.Text = 'WEb Cam List';
            app.connectCam = uibutton(app.uipanel1, 'push');
            app.connectCam.ButtonPushedFcn = createCallbackFcn(app, @connectCam_Callback, true);
            app.connectCam.Tooltip = {'Connect to a specific web cam'};
            app.connectCam.Text = 'Connect';
            app.getOneImage = uibutton(app.uipanel1, 'push');
            app.getOneImage.ButtonPushedFcn = createCallbackFcn(app, @getOneImage_Callback, true);
            app.getOneImage.Tooltip = {'Initialization of the sequence. This callback initializes the crop limits and must be launched in order to change crop limits. '};
            app.getOneImage.Text = 'First frame';
            app.manualAcquisition = uibutton(app.uipanel1, 'push');
            app.manualAcquisition.ButtonPushedFcn = createCallbackFcn(app, @manualAcquisition_Callback, true);
            app.manualAcquisition.Tooltip = 'Get one image and process ';
            app.manualAcquisition.Text = 'Manual';
            app.record = uibutton(app.uipanel1, 'push');
            app.record.ButtonPushedFcn = createCallbackFcn(app, @record_Callback, true);
            app.record.Tooltip = {'Record activity'};
            app.record.Text = 'Record';
            app.autothresholdCK = uicheckbox(app.uipanel1);
            app.autothresholdCK.Text = 'Auto-threshold ';
            app.autothresholdCK.Value = true;
            app.plotTrajectoryIm = uicheckbox(app.uipanel1);
            app.plotTrajectoryIm.Tooltip = 'Plot trajectory on current image window';
            app.plotTrajectoryIm.Text = 'Plot trajectory';
            app.refreshImageCk = uicheckbox(app.uipanel1);
            app.refreshImageCk.Tooltip = 'Plot trajectory on current image window';
            app.refreshImageCk.Text = 'Current Image';
            app.plotTrajectoryBin = uicheckbox(app.uipanel1);
            app.plotTrajectoryBin.ValueChangedFcn = createCallbackFcn(app, @plotTrajectoryBin_Callback, true);
            app.plotTrajectoryBin.Tooltip = 'Plot trajectory on current image window';
            app.plotTrajectoryBin.Text = 'Plot trajectory ';
            app.refreshBinaryImCk = uicheckbox(app.uipanel1);
            app.refreshBinaryImCk.Tooltip = 'Plot trajectory on current image window';
            app.refreshBinaryImCk.Text = 'Binary Image';
            app.ThresholdlevelunitsofstdEditFieldLabel = uilabel(app.uipanel1);
            app.ThresholdlevelunitsofstdEditFieldLabel.Text = 'Threshold level (units of std):';
            app.thrLevelEdit = uieditfield(app.uipanel1, 'numeric');
            app.thrLevelEdit.Limits = [0 Inf];
            app.thrLevelEdit.Value = 9;
            app.SamplingperiodsEditFieldLabel = uilabel(app.uipanel1);
            app.SamplingperiodsEditFieldLabel.Text = 'Sampling period (s):';
            app.SPtxt = uieditfield(app.uipanel1, 'numeric');
            app.SPtxt.Limits = [0.1 Inf];
            app.SPtxt.Value = 0.5;
            app.FramesLabel = uilabel(app.uipanel1);
            app.FramesLabel.Text = 'Frames:';
            app.framesTxt = uieditfield(app.uipanel1, 'numeric');
            app.framesTxt.Limits = [0 Inf];
            app.framesTxt.Tooltip = {'Frames in a single repeat. It cannot be changed on the fly while recording'};
            app.framesTxt.Value = 7200;
            app.SizeofnoisetoremovepxLabel = uilabel(app.uipanel1);
            app.SizeofnoisetoremovepxLabel.Text = 'Size of noise to remove (px):';
            app.binaryClosureTxt = uieditfield(app.uipanel1, 'numeric');
            app.binaryClosureTxt.Limits = [0 Inf];
            app.binaryClosureTxt.Value = 4;
            app.TrajectorylengthpointsLabel = uilabel(app.uipanel1);
            app.TrajectorylengthpointsLabel.Text = 'Trajectory length (points):';
            app.plotTrajectoryLenTxt = uieditfield(app.uipanel1, 'numeric');
            app.plotTrajectoryLenTxt.Limits = [0 Inf];
            app.plotTrajectoryLenTxt.Value = 60;
            app.ArduinoButton = uibutton(app.uipanel1, 'push');
            app.ArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @connectArduino, true);
            app.ArduinoButton.Tooltip = {'Connect Arduino board'};
            app.ArduinoButton.Text = 'Arduino';
            app.RepetitionsLabel = uilabel(app.uipanel1);
            app.RepetitionsLabel.Text = 'Repetitions:';
            app.repetitions = uieditfield(app.uipanel1, 'numeric');
            app.repetitions.Limits = [1 Inf];
            app.repetitions.ValueDisplayFormat = '%.0f';
            app.repetitions.Tooltip = {'Number of repetitions. It cannot be changed on the fly while running'};
            app.repetitions.Value = 26;
            app.cycleDuration = uilabel(app.uipanel1);
            app.cycleDuration.Text = 'Cycle duration (s):';
            app.refreshDeltaImageCk = uicheckbox(app.uipanel1);
            app.refreshDeltaImageCk.Tooltip = 'Plot trajectory on current image window';
            app.refreshDeltaImageCk.Text = 'Delta Image';
            app.plotTrajectoryDeltaCk = uicheckbox(app.uipanel1);
            app.plotTrajectoryDeltaCk.Tooltip = 'Plot trajectory on current image window';
            app.plotTrajectoryDeltaCk.Text = 'Plot trajectory ';
            app.plotGraphCB = uicheckbox(app.uipanel1);
            app.plotGraphCB.Tooltip = 'Plot trajectory on current image window';
            app.plotGraphCB.Text = 'Show Plots';
            app.RunningAvEditFieldLabel = uilabel(app.uipanel1);
            app.RunningAvEditFieldLabel.Text = 'Running Av';
            app.RunningAv = uieditfield(app.uipanel1, 'numeric');
            app.RunningAv.Limits = [1 Inf];
            app.RunningAv.ValueDisplayFormat = '%.0f';
            app.RunningAv.Tooltip = {'Window size for running average of plots'};
            app.RunningAv.Value = 10;
            app.plotHistoCB = uicheckbox(app.uipanel1);
            app.plotHistoCB.Tooltip = 'Plot trajectory on current image window';
            app.plotHistoCB.Text = {'Display histo'; ''};
            app.plotHistoCB.Value = true;
            app.dailyModeCB = uicheckbox(app.uipanel1);
            app.dailyModeCB.Tooltip = {'In daily mode data are saved each our and the acquisition lasts 1 day. If multple days are defined in the nDay field that is taken care automatically.'};
            app.dailyModeCB.Text = 'Daily mode';
            app.HowmanydaysLabel = uilabel(app.uipanel1);
            app.HowmanydaysLabel.Text = 'How many days:';
            app.nDays = uieditfield(app.uipanel1, 'numeric');
            app.nDays.Limits = [1 Inf];
            app.nDays.ValueDisplayFormat = '%.0f';
            app.nDays.Tooltip = {'Number of repetitions. It cannot be changed on the fly while running'};
            app.nDays.Value = 3;
            app.whiteMiceCB = uicheckbox(app.uipanel1);
            app.whiteMiceCB.Tooltip = {'Check here for white mice on dark background'};
            app.whiteMiceCB.Text = 'White mice';
            app.subBack = uicheckbox(app.uipanel1);
            app.subBack.ValueChangedFcn = createCallbackFcn(app, @loadBack, true);
            app.subBack.Text = 'Sub background';
            app.ExposureLabel = uilabel(app.uipanel1);
            app.ExposureLabel.Text = 'Exposure';
            app.exposure = uieditfield(app.uipanel1, 'numeric');
            app.exposure.Limits = [-10 10];
            app.exposure.ValueDisplayFormat = '%.0f';
            app.exposure.ValueChangedFcn = createCallbackFcn(app, @camExposure, true);
            app.exposure.Tooltip = {'Window size for running average of plots'};
            app.exposure.Value = -4;
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Plot vs Time';
            app.imageDelta = uiaxes(app.uipanel2);
            app.imageDelta.XLim = [0 1];
            app.deltaArea = uiaxes(app.uipanel2);
            app.deltaArea.XLim = [0 1];
            app.travelledDisplacement = uiaxes(app.uipanel2);
            app.travelledDisplacement.XLim = [0 1];
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Status and Analysis';
            app.uipanel4 = uipanel(app.uipanel3);
            app.uipanel4.Title = 'Environment Control';
            app.override = uicheckbox(app.uipanel4);
            app.override.Tooltip = 'Toggle IR illumination';
            app.override.Text = 'Override Light ';
            app.DayLightCB = uicheckbox(app.uipanel4);
            app.DayLightCB.ValueChangedFcn = createCallbackFcn(app, @toggleDayLight, true);
            app.DayLightCB.Tooltip = 'Toggle white illumination';
            app.DayLightCB.Text = 'Day light';
            app.fanCk = uicheckbox(app.uipanel4);
            app.fanCk.ValueChangedFcn = createCallbackFcn(app, @fanCk_Callback, true);
            app.fanCk.Tooltip = 'Toggle white illumination';
            app.fanCk.Text = 'Fan';
            app.SwitchdaylightonathourEditFieldLabel = uilabel(app.uipanel4);
            app.SwitchdaylightonathourEditFieldLabel.Text = 'Switch day light on at (hour):';
            app.lightOn = uieditfield(app.uipanel4, 'numeric');
            app.lightOn.Limits = [0 23];
            app.lightOn.Tooltip = {'Time of the day (hr only'; ' 0-23 format)'};
            app.lightOn.Value = 7;
            app.SwitchdaylightoffathourLabel = uilabel(app.uipanel4);
            app.SwitchdaylightoffathourLabel.Text = 'Switch day light off at (hour):';
            app.lightOff = uieditfield(app.uipanel4, 'numeric');
            app.lightOff.Limits = [0 24];
            app.lightOff.Tooltip = {'Time of the day (hr only'; ' 0-23 format)'};
            app.lightOff.Value = 19;
            app.uipanel5 = uipanel(app.uipanel3);
            app.uipanel5.Title = 'Edges of the Arena';
            app.LeftwidthpixLabel = uilabel(app.uipanel5);
            app.LeftwidthpixLabel.Text = 'Left width (pix):';
            app.leftCropTxt = uieditfield(app.uipanel5, 'numeric');
            app.leftCropTxt.Limits = [0 Inf];
            app.leftCropTxt.Value = 50;
            app.RightwidthLabel = uilabel(app.uipanel5);
            app.RightwidthLabel.Text = 'Right width:';
            app.rightCropTxt = uieditfield(app.uipanel5, 'numeric');
            app.rightCropTxt.Limits = [0 Inf];
            app.rightCropTxt.Value = 50;
            app.TopwidthpixLabel = uilabel(app.uipanel5);
            app.TopwidthpixLabel.Text = 'Top width (pix):';
            app.topCropTxt = uieditfield(app.uipanel5, 'numeric');
            app.topCropTxt.Limits = [0 Inf];
            app.topCropTxt.Value = 50;
            app.BottomwidthLabel = uilabel(app.uipanel5);
            app.BottomwidthLabel.Text = 'Bottom width:';
            app.bottomCropTxt = uieditfield(app.uipanel5, 'numeric');
            app.bottomCropTxt.Limits = [0 Inf];
            app.bottomCropTxt.Value = 50;
            app.uipanel7 = uipanel(app.uipanel3);
            app.uipanel7.Title = 'State Analysis';
            app.ThresholdspeedcmsLabel = uilabel(app.uipanel7);
            app.ThresholdspeedcmsLabel.Text = 'Threshold speed (cm/s):';
            app.thrSpeedTxt = uieditfield(app.uipanel7, 'numeric');
            app.thrSpeedTxt.Limits = [0 Inf];
            app.thrSpeedTxt.Value = 0.2;
            app.ThresholddApxsLabel = uilabel(app.uipanel7);
            app.ThresholddApxsLabel.Text = 'Threshold dA (px/s):';
            app.areaThrTxt = uieditfield(app.uipanel7, 'numeric');
            app.areaThrTxt.Limits = [0 Inf];
            app.areaThrTxt.Value = 10;
            app.MindurationofsleepeventssLabel = uilabel(app.uipanel7);
            app.MindurationofsleepeventssLabel.Text = 'Min duration of sleep events (s):';
            app.minSleepTxt = uieditfield(app.uipanel7, 'numeric');
            app.minSleepTxt.Limits = [0 Inf];
            app.minSleepTxt.Value = 20;
            app.uipanel8 = uipanel(app.uipanel3);
            app.uipanel8.Title = 'Output Metrics';
            app.currentPositionTxt = uilabel(app.uipanel8);
            app.currentPositionTxt.Text = '-';
            app.speedTxt = uilabel(app.uipanel8);
            app.speedTxt.Text = 'Istantaneous speed (cm/s):';
            app.stateTxt = uilabel(app.uipanel8);
            app.stateTxt.Tooltip = 'EXPLORATORY, LOCAL EXPLORATORY, IMMOBILE, SLEEP. Exploratory state has two additional tags: OPEN FIELD and EDGES according to location';
            app.stateTxt.Text = 'State:';
            app.immobileTxt = uilabel(app.uipanel8);
            app.immobileTxt.Tooltip = 'Location: edges, open field, water';
            app.immobileTxt.Text = 'Immobile %:';
            app.sleepTxt = uilabel(app.uipanel8);
            app.sleepTxt.Tooltip = 'Location: edges, open field, water';
            app.sleepTxt.Text = 'Sleep %:';
            app.exploratoryTxt = uilabel(app.uipanel8);
            app.exploratoryTxt.Tooltip = 'Location: edges, open field, water';
            app.exploratoryTxt.Text = 'Exploratory %:';
            app.localExpTxt = uilabel(app.uipanel8);
            app.localExpTxt.Tooltip = 'Location: edges, open field, water';
            app.localExpTxt.Text = 'Local Exp %:';
            app.openTxt = uilabel(app.uipanel8);
            app.openTxt.Tooltip = 'Location: edges, open field, water';
            app.openTxt.Text = 'Open field %:';
            app.edgesTxt = uilabel(app.uipanel8);
            app.edgesTxt.Tooltip = 'Location: edges, open field, water';
            app.edgesTxt.Text = 'Border %:';
            app.uibuttongroup1 = uibuttongroup(app.figure1);
            app.speedRB = uiradiobutton(app.uibuttongroup1);
            app.speedRB.Tooltip = 'Plot of the delta area distribution';
            app.speedRB.Text = 'Speed';
            app.speedRB.Value = true;
            app.deltaAreaRB = uiradiobutton(app.uibuttongroup1);
            app.deltaAreaRB.Tooltip = 'Plot of the delta area distribution';
            app.deltaAreaRB.Text = 'Delta Area';
            app.stateRB = uiradiobutton(app.uibuttongroup1);
            app.stateRB.Tooltip = 'Plot of the delta area distribution';
            app.stateRB.Text = 'State';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = morpheus_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @morpheus_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end