classdef notes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NotesforMorpheusUIFigure       matlab.ui.Figure
        MousedescriptionandnotesPanel  matlab.ui.container.Panel
        BirthdateLabel                 matlab.ui.control.Label
        birthdate                      matlab.ui.control.DatePicker
        SexButtonGroup                 matlab.ui.container.ButtonGroup
        femaleRB                       matlab.ui.control.RadioButton
        maleRG                         matlab.ui.control.RadioButton
        GeneticbackgroundEditFieldLabel  matlab.ui.control.Label
        geneticBackground              matlab.ui.control.EditField
        LightcyclehhonhhoffLabel       matlab.ui.control.Label
        lightCycle                     matlab.ui.control.EditField
        NotesLabel                     matlab.ui.control.Label
        notesTxt                       matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        morphApp        % handle to Morpheus app structure
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, source)
            app.morphApp = source;                  % establish the link with Morpheus
            % Default values
            app.morphApp.trajectory.sex = 'F';        
            app.morphApp.trajectory.geneticBackground = app.geneticBackground.Value;
            app.morphApp.trajectory.lightCycle = app.lightCycle.Value;
            app.morphApp.trajectory.notes = app.notesTxt.Value;
            app.morphApp.trajectory.birthdate = app.birthdate.Value;
        end

        % Value changed function: birthdate
        function changeDate(app, event)
            app.morphApp.trajectory.birthdate = app.birthdate.Value;        
        end

        % Value changed function: geneticBackground
        function changeGB(app, event)
            app.morphApp.trajectory.geneticBackground = app.geneticBackground.Value;   
        end

        % Value changed function: lightCycle
        function changeLC(app, event)
            app.morphApp.trajectory.lightCycle = app.lightCycle.Value;
        end

        % Selection changed function: SexButtonGroup
        function changeSex(app, event)
            if app.SexButtonGroup.SelectedObject == app.femaleRB
                app.morphApp.trajectory.sex = 'F';
            else
                app.morphApp.trajectory.sex = 'M';
            end
        end

        % Value changed function: notesTxt
        function writeNotes(app, event)
            app.morphApp.trajectory.notes = app.notesTxt.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NotesforMorpheusUIFigure = uifigure('Visible', 'off');
            app.NotesforMorpheusUIFigure.Name = 'Notes for Morpheus';
            app.MousedescriptionandnotesPanel = uipanel(app.NotesforMorpheusUIFigure);
            app.MousedescriptionandnotesPanel.Title = 'Mouse description and notes';
            app.BirthdateLabel = uilabel(app.MousedescriptionandnotesPanel);
            app.BirthdateLabel.Text = 'Birth date:';
            app.birthdate = uidatepicker(app.MousedescriptionandnotesPanel);
            app.birthdate.ValueChangedFcn = createCallbackFcn(app, @changeDate, true);
            app.SexButtonGroup = uibuttongroup(app.MousedescriptionandnotesPanel);
            app.SexButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @changeSex, true);
            app.SexButtonGroup.Title = 'Sex';
            app.femaleRB = uiradiobutton(app.SexButtonGroup);
            app.femaleRB.Text = 'Female';
            app.femaleRB.Value = true;
            app.maleRG = uiradiobutton(app.SexButtonGroup);
            app.maleRG.Text = 'Male';
            app.GeneticbackgroundEditFieldLabel = uilabel(app.MousedescriptionandnotesPanel);
            app.GeneticbackgroundEditFieldLabel.Text = 'Genetic background:';
            app.geneticBackground = uieditfield(app.MousedescriptionandnotesPanel, 'text');
            app.geneticBackground.ValueChangedFcn = createCallbackFcn(app, @changeGB, true);
            app.geneticBackground.Value = 'C57';
            app.LightcyclehhonhhoffLabel = uilabel(app.MousedescriptionandnotesPanel);
            app.LightcyclehhonhhoffLabel.Text = 'Light cycle (hh on-hh off):';
            app.lightCycle = uieditfield(app.MousedescriptionandnotesPanel, 'text');
            app.lightCycle.ValueChangedFcn = createCallbackFcn(app, @changeLC, true);
            app.lightCycle.Value = '7-19';
            app.NotesLabel = uilabel(app.MousedescriptionandnotesPanel);
            app.NotesLabel.Text = 'Notes:';
            app.notesTxt = uitextarea(app.MousedescriptionandnotesPanel);
            app.notesTxt.ValueChangedFcn = createCallbackFcn(app, @writeNotes, true);
            % Show the figure after all components are created
            app.NotesforMorpheusUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = notes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NotesforMorpheusUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NotesforMorpheusUIFigure)
        end
    end
end